classdef AOGUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        AOIUIFigure                    matlab.ui.Figure
        FileMenu                       matlab.ui.container.Menu
        LoadCondifurationsMenu         matlab.ui.container.Menu
        SaveConfigurationsMenu         matlab.ui.container.Menu
        ToolsMenu                      matlab.ui.container.Menu
        DebugMenu                      matlab.ui.container.Menu
        DeactivatePeripheralsMenu      matlab.ui.container.Menu
        ExtraFiguresMenu               matlab.ui.container.Menu
        PMTSaturationInspector         matlab.ui.container.Menu
        ScanAssistantMenu              matlab.ui.container.Menu
        FindMyinstrumentPortMenu       matlab.ui.container.Menu
        InstrumentsMenu                matlab.ui.container.Menu
        StagesMenu                     matlab.ui.container.Menu
        ScanningAssistantMenu          matlab.ui.container.Menu
        AFGMenu                        matlab.ui.container.Menu
        OscilloscopeMenu               matlab.ui.container.Menu
        ResetInstrumentsCommunicationMenu  matlab.ui.container.Menu
        HelpMenu                       matlab.ui.container.Menu
        AboutMenu                      matlab.ui.container.Menu
        acoustoOpticsPlotsTab          matlab.ui.container.TabGroup
        TimeTable                      matlab.ui.container.Tab
        UITable2_3                     matlab.ui.control.Table
        UITable2_2                     matlab.ui.control.Table
        UITable2                       matlab.ui.control.Table
        AOVarsTab                      matlab.ui.container.Tab
        TabGroup2                      matlab.ui.container.TabGroup
        SystemClkTab                   matlab.ui.container.Tab
        UITable                        matlab.ui.control.Table
        USSignalTab                    matlab.ui.container.Tab
        UITable_2                      matlab.ui.control.Table
        SamplesTab                     matlab.ui.container.Tab
        UITable_3                      matlab.ui.control.Table
        TimingTab                      matlab.ui.container.Tab
        UITable_4                      matlab.ui.control.Table
        DigitizerTab                   matlab.ui.container.Tab
        UITable_5                      matlab.ui.control.Table
        LengthTab                      matlab.ui.container.Tab
        UITable_6                      matlab.ui.control.Table
        FreqTab                        matlab.ui.container.Tab
        UITable_7                      matlab.ui.control.Table
        extClkTab                      matlab.ui.container.Tab
        extClkAx                       matlab.ui.control.UIAxes
        usSignalTab                    matlab.ui.container.Tab
        usSignalAx                     matlab.ui.control.UIAxes
        croppedTab                     matlab.ui.container.Tab
        croppedAx                      matlab.ui.control.UIAxes
        measTab                        matlab.ui.container.Tab
        measAx                         matlab.ui.control.UIAxes
        signalTab                      matlab.ui.container.Tab
        signalAx                       matlab.ui.control.UIAxes
        deMulTab                       matlab.ui.container.Tab
        deMulAx                        matlab.ui.control.UIAxes
        reshapedTab                    matlab.ui.container.Tab
        reshapedAx                     matlab.ui.control.UIAxes
        PhiTabGroup                    matlab.ui.container.TabGroup
        phiTab                         matlab.ui.container.Tab
        phiAx                          matlab.ui.control.UIAxes
        rawPhiTab                      matlab.ui.container.Tab
        rawPhiAx                       matlab.ui.control.UIAxes
        phiLogTab                      matlab.ui.container.Tab
        phiLogAx                       matlab.ui.control.UIAxes
        FFTTabGroup                    matlab.ui.container.TabGroup
        rawFFTTab                      matlab.ui.container.Tab
        rawFFTAx                       matlab.ui.control.UIAxes
        calibrationTab                 matlab.ui.container.Tab
        calibrationAx                  matlab.ui.control.UIAxes
        fittingModelTab                matlab.ui.container.Tab
        fittingModelAx                 matlab.ui.control.UIAxes
        fittedPowerFFTTab              matlab.ui.container.Tab
        fittedPowerFFTAx               matlab.ui.control.UIAxes
        finalFFTTab                    matlab.ui.container.Tab
        finalFFTAx                     matlab.ui.control.UIAxes
        ScanPlotTab                    matlab.ui.container.TabGroup
        scanPlaneTab                   matlab.ui.container.Tab
        axScanPlane                    matlab.ui.control.UIAxes
        scanPlaneLogTab                matlab.ui.container.Tab
        axScanPlaneLog                 matlab.ui.control.UIAxes
        navLineTab                     matlab.ui.container.Tab
        axNavLine                      matlab.ui.control.UIAxes
        navLineLogTab                  matlab.ui.container.Tab
        axNavLineLog                   matlab.ui.control.UIAxes
        navPlaneTab                    matlab.ui.container.Tab
        axNavPlane                     matlab.ui.control.UIAxes
        navPlaneLogTab                 matlab.ui.container.Tab
        axNavPlaneLog                  matlab.ui.control.UIAxes
        BPTab                          matlab.ui.container.Tab
        axOABP                         matlab.ui.control.UIAxes
        SinogramTab                    matlab.ui.container.Tab
        axOASinogram                   matlab.ui.control.UIAxes
        OANavSinogramTab               matlab.ui.container.Tab
        axOANavSinogram                matlab.ui.control.UIAxes
        OANavBPTab                     matlab.ui.container.Tab
        axOANavBP                      matlab.ui.control.UIAxes
        PEUSRawTab                     matlab.ui.container.Tab
        axPEUSRaw                      matlab.ui.control.UIAxes
        PEUSCorrectedTab               matlab.ui.container.Tab
        axPEUSCorrected                matlab.ui.control.UIAxes
        CorrectedOATab                 matlab.ui.container.Tab
        axCorrectedOA                  matlab.ui.control.UIAxes
        InvisiblePlotTabs              matlab.ui.container.TabGroup
        Tab                            matlab.ui.container.Tab
        navAxHolderTab                 matlab.ui.container.Tab
        GlobalParameters               matlab.ui.container.TabGroup
        SignalExcitationTab            matlab.ui.container.Tab
        ImportVarsPanel                matlab.ui.container.Panel
        ExportVarsButton               matlab.ui.control.Button
        ImportVarsButton               matlab.ui.control.Button
        analysisVarsPathButton         matlab.ui.control.Button
        VarsEditField                  matlab.ui.control.EditField
        VarsEditFieldLabel             matlab.ui.control.Label
        ReCalcDataButton               matlab.ui.control.Button
        VirtualDataPanel               matlab.ui.container.Panel
        MuEffEditField                 matlab.ui.control.NumericEditField
        MuEffEditFieldLabel            matlab.ui.control.Label
        NoiesStdEditField              matlab.ui.control.NumericEditField
        NoiesStdEditFieldLabel         matlab.ui.control.Label
        UseVirtualDataCheckBox         matlab.ui.control.CheckBox
        SNRNoisePanel                  matlab.ui.container.Panel
        ExternalSNRDefinitionCheckBox  matlab.ui.control.CheckBox
        BkgIdxsEditField               matlab.ui.control.EditField
        BkgIdxsEditFieldLabel          matlab.ui.control.Label
        PeakIdxEditField               matlab.ui.control.NumericEditField
        PeakIdxEditFieldLabel          matlab.ui.control.Label
        MuEffCalculationPanel          matlab.ui.container.Panel
        CalcMuEffCheckBox              matlab.ui.control.CheckBox
        GeometryDropDown_2             matlab.ui.control.DropDown
        GeometryDropDown_2Label        matlab.ui.control.Label
        muEffIndexesEditField          matlab.ui.control.EditField
        IndexesEditField_2Label        matlab.ui.control.Label
        ArtifactsPanel                 matlab.ui.container.Panel
        IndexesEditField               matlab.ui.control.EditField
        IndexesEditFieldLabel          matlab.ui.control.Label
        CutArtifactsCheckBox           matlab.ui.control.CheckBox
        QuickPlotControlPanel          matlab.ui.container.Panel
        DepthLenLabel                  matlab.ui.control.Label
        AODepthIdxSpinner              matlab.ui.control.Spinner
        DepthCoorLabel                 matlab.ui.control.Label
        PlotPosmmLabel                 matlab.ui.control.Label
        SeparateChannelSpinner         matlab.ui.control.Spinner
        SeparateChannelSpinnerLabel    matlab.ui.control.Label
        FrameAOSpinner                 matlab.ui.control.Spinner
        FrameLabel                     matlab.ui.control.Label
        SignalChannelSpinner           matlab.ui.control.Spinner
        SignalChannelSpinnerLabel      matlab.ui.control.Label
        FittingEnvironmentsPanel       matlab.ui.container.Panel
        KHzLabel_7                     matlab.ui.control.Label
        KHzLabel_6                     matlab.ui.control.Label
        envUS                          matlab.ui.control.NumericEditField
        USEditFieldLabel               matlab.ui.control.Label
        envDC                          matlab.ui.control.NumericEditField
        DCEditFieldLabel               matlab.ui.control.Label
        GeneralOperationPanel          matlab.ui.container.Panel
        ExternalIOCtrlCheckBox         matlab.ui.control.CheckBox
        KeepSplitsCheckBox             matlab.ui.control.CheckBox
        UseCalibrationCheckBox         matlab.ui.control.CheckBox
        DispEveryEditField             matlab.ui.control.NumericEditField
        DispEveryEditFieldLabel        matlab.ui.control.Label
        DisplayBuildUpCheckBox         matlab.ui.control.CheckBox
        ACCouplingCheckBox             matlab.ui.control.CheckBox
        UseGPUCheckBox                 matlab.ui.control.CheckBox
        SeparateChCheckBox             matlab.ui.control.CheckBox
        HadamardCodeCheckBox           matlab.ui.control.CheckBox
        UseTimeFrameCheckBox           matlab.ui.control.CheckBox
        GeometryPanel                  matlab.ui.container.Panel
        cmLabel_3                      matlab.ui.control.Label
        SpeedofSoundEditField          matlab.ui.control.NumericEditField
        SpeedofSoundEditFieldLabel     matlab.ui.control.Label
        msLabel                        matlab.ui.control.Label
        TransDistEditField             matlab.ui.control.NumericEditField
        TransducerdistanceLabel        matlab.ui.control.Label
        UltrasoundSignalPropertiesPanel  matlab.ui.container.Panel
        USPowerEditField               matlab.ui.control.NumericEditField
        USPowerEditFieldLabel          matlab.ui.control.Label
        Label_19                       matlab.ui.control.Label
        CyclesPerPulseEditField        matlab.ui.control.NumericEditField
        CyclesPerPulseEditFieldLabel   matlab.ui.control.Label
        SquenceFreqEditField           matlab.ui.control.NumericEditField
        SquenceFreqEditFieldLabel      matlab.ui.control.Label
        SineFreqEditField              matlab.ui.control.NumericEditField
        SineFreqEditFieldLabel         matlab.ui.control.Label
        KHzLabel_3                     matlab.ui.control.Label
        Label_13                       matlab.ui.control.Label
        MHzLabel                       matlab.ui.control.Label
        SamplingPanel                  matlab.ui.container.Panel
        NoOfChannelsEditField          matlab.ui.control.NumericEditField
        NoOfChannelsEditFieldLabel     matlab.ui.control.Label
        AOChNumLabel                   matlab.ui.control.Label
        MeasLimitEditField             matlab.ui.control.NumericEditField
        MeasLimitEditFieldLabel        matlab.ui.control.Label
        AOMeasLimitLabel               matlab.ui.control.Label
        FrameTimeEditField             matlab.ui.control.NumericEditField
        FrameTimeEditFieldLabel        matlab.ui.control.Label
        AOFrameTimeLabel               matlab.ui.control.Label
        TimetosampleEditField          matlab.ui.control.NumericEditField
        TimetosampleEditFieldLabel     matlab.ui.control.Label
        AOTimeToSampleLabel            matlab.ui.control.Label
        FrequencyEditField             matlab.ui.control.NumericEditField
        FrequencyEditFieldLabel        matlab.ui.control.Label
        AOSamplingRateLabel            matlab.ui.control.Label
        PeripheralsTab                 matlab.ui.container.Tab
        IOControlPanel                 matlab.ui.container.Panel
        OpenIOPortButton               matlab.ui.control.Button
        CloseIOPortButton              matlab.ui.control.Button
        StagesPanel                    matlab.ui.container.Panel
        OpenStagesGUIButton            matlab.ui.control.Button
        CalibrationPanel               matlab.ui.container.Panel
        CalibrateButton                matlab.ui.control.Button
        secLabel_4                     matlab.ui.control.Label
        DurationEditField              matlab.ui.control.NumericEditField
        DurationEditFieldLabel         matlab.ui.control.Label
        DigitizerPanel                 matlab.ui.container.Panel
        ResetDigitizerButton           matlab.ui.control.Button
        UseusersetcropsamplesCheckBox  matlab.ui.control.CheckBox
        MonitorButton                  matlab.ui.control.Button
        CroppedSamplsEditField         matlab.ui.control.NumericEditField
        CroppedSamplsEditFieldLabel    matlab.ui.control.Label
        BackProjectionPanel            matlab.ui.container.Panel
        BPModeDropDown                 matlab.ui.control.DropDown
        BPModeDropDownLabel            matlab.ui.control.Label
        ImageWidthEditField            matlab.ui.control.NumericEditField
        ImageWidthEditFieldLabel       matlab.ui.control.Label
        GeometryDropDown               matlab.ui.control.DropDown
        GeometryDropDownLabel          matlab.ui.control.Label
        OABPmmLabel                    matlab.ui.control.Label
        OsciloscopePanel               matlab.ui.container.Panel
        HzLabel                        matlab.ui.control.Label
        PEUSIntValEditField            matlab.ui.control.NumericEditField
        IntValEditFieldLabel           matlab.ui.control.Label
        PEUSRepetitionRateEditField    matlab.ui.control.NumericEditField
        RepetitionRateEditFieldLabel   matlab.ui.control.Label
        PEUSNumofAvgEditField          matlab.ui.control.NumericEditField
        NumofAvgEditFieldLabel         matlab.ui.control.Label
        PEUSTrigChannelEditField       matlab.ui.control.NumericEditField
        TrigChannelEditFieldLabel      matlab.ui.control.Label
        PEUSChannelNoEditField         matlab.ui.control.NumericEditField
        ChannelNoEditFieldLabel        matlab.ui.control.Label
        DAQPanel                       matlab.ui.container.Panel
        ResetDaqButton                 matlab.ui.control.Button
        DelayEditField                 matlab.ui.control.NumericEditField
        DelayEditFieldLabel            matlab.ui.control.Label
        FramesEditField                matlab.ui.control.NumericEditField
        FramesEditFieldLabel           matlab.ui.control.Label
        AveragesEditField              matlab.ui.control.NumericEditField
        AveragesEditFieldLabel         matlab.ui.control.Label
        SamplesEditField               matlab.ui.control.NumericEditField
        SamplesEditFieldLabel          matlab.ui.control.Label
        HzLabel_2                      matlab.ui.control.Label
        sLabel_10                      matlab.ui.control.Label
        Label_16                       matlab.ui.control.Label
        Label_15                       matlab.ui.control.Label
        Label_14                       matlab.ui.control.Label
        SamplingRateEditField          matlab.ui.control.NumericEditField
        SamplingRateEditFieldLabel     matlab.ui.control.Label
        FunctionGeneratorPanel         matlab.ui.container.Panel
        TurnAFGOnButton                matlab.ui.control.Button
        TurnAFGOffButton               matlab.ui.control.Button
        DutyCycleEditField             matlab.ui.control.NumericEditField
        DutyCycleEditFieldLabel        matlab.ui.control.Label
        Label_17                       matlab.ui.control.Label
        MSsLabel                       matlab.ui.control.Label
        SamplesClkFreqEditField        matlab.ui.control.NumericEditField
        SamplesClkFreqEditFieldLabel   matlab.ui.control.Label
        PlotsDataTab                   matlab.ui.container.Tab
        TabGroup3                      matlab.ui.container.TabGroup
        AOTab                          matlab.ui.container.Tab
        AxisPropertiesPanel            matlab.ui.container.Panel
        AODepthAxTypeDropDown          matlab.ui.control.DropDown
        DepthDropDownLabel             matlab.ui.control.Label
        NotificationsPanel             matlab.ui.container.Panel
        SendEveryEditField             matlab.ui.control.NumericEditField
        SendEveryEditFieldLabel        matlab.ui.control.Label
        ChatIDEditField                matlab.ui.control.EditField
        ChatIDEditFieldLabel           matlab.ui.control.Label
        UpdatedviaTelegramCheckBox     matlab.ui.control.CheckBox
        AcoustoOpticsPanel_4           matlab.ui.container.Panel
        KHzLabel_8                     matlab.ui.control.Label
        FreqEnvEditField               matlab.ui.control.NumericEditField
        FreqEnvEditFieldLabel          matlab.ui.control.Label
        dispFullFFTCheckBox            matlab.ui.control.CheckBox
        calibrationDropDown            matlab.ui.control.DropDown
        CalibrationLabel               matlab.ui.control.Label
        extClkDropDown                 matlab.ui.control.DropDown
        usSignalDropDown               matlab.ui.control.DropDown
        croppedDropDown                matlab.ui.control.DropDown
        measDropDown                   matlab.ui.control.DropDown
        signalDropDown                 matlab.ui.control.DropDown
        deMulDropDown                  matlab.ui.control.DropDown
        reshapedDropDown               matlab.ui.control.DropDown
        rawFFTDropDown                 matlab.ui.control.DropDown
        fittingModelDropDown           matlab.ui.control.DropDown
        FinalFFTDropDownLabel          matlab.ui.control.Label
        fittedPowerFFTDropDown         matlab.ui.control.DropDown
        FittedPFFTLabel                matlab.ui.control.Label
        FittingModelDropDownLabel      matlab.ui.control.Label
        RawFFTDropDownLabel            matlab.ui.control.Label
        finalFFTDropDown               matlab.ui.control.DropDown
        phiDropDown                    matlab.ui.control.DropDown
        rawPhiDropDown                 matlab.ui.control.DropDown
        phiLogDropDown                 matlab.ui.control.DropDown
        PhiLogDropDownLabel            matlab.ui.control.Label
        PhiRawDropDownLabel            matlab.ui.control.Label
        PhiDropDownLabel               matlab.ui.control.Label
        ReshapedDropDownLabel          matlab.ui.control.Label
        DeMulLabel                     matlab.ui.control.Label
        SignalDropDownLabel            matlab.ui.control.Label
        MeasuredDropDownLabel          matlab.ui.control.Label
        CroppedDropDownLabel           matlab.ui.control.Label
        USSignalDropDownLabel          matlab.ui.control.Label
        ExternalClkDropDownLabel       matlab.ui.control.Label
        SpatialReconLabel_4            matlab.ui.control.Label
        FrequencyDomainLabel_4         matlab.ui.control.Label
        TimeDomainSignalsLabel_4       matlab.ui.control.Label
        ExcitationSignalsLabel_4       matlab.ui.control.Label
        ExportDataPanel                matlab.ui.container.Panel
        ComplexCompUSCheckBox          matlab.ui.control.CheckBox
        FFTCheckBox                    matlab.ui.control.CheckBox
        ReshapedExportCheckBox         matlab.ui.control.CheckBox
        DeMultiplexedCheckBox          matlab.ui.control.CheckBox
        SignalCheckBox                 matlab.ui.control.CheckBox
        MeasuredCheckBox               matlab.ui.control.CheckBox
        TimeTablePanel                 matlab.ui.container.Panel
        DispTimeTableCheckBox          matlab.ui.control.CheckBox
        AOSTab                         matlab.ui.container.Tab
        FiguresArrangementPanel        matlab.ui.container.Panel
        navLineLogDropDown             matlab.ui.control.DropDown
        LineNavLogDropDownLabel        matlab.ui.control.Label
        navLineDropDown                matlab.ui.control.DropDown
        LineNavDropDownLabel           matlab.ui.control.Label
        navPlaneLogDropDown            matlab.ui.control.DropDown
        PlaneNavLogDropDownLabel       matlab.ui.control.Label
        navPlaneDropDown               matlab.ui.control.DropDown
        PlaneNavDropDownLabel          matlab.ui.control.Label
        scanPlaneLogDropDown           matlab.ui.control.DropDown
        MainPlaneLogDropDownLabel      matlab.ui.control.Label
        scanPlaneDropDown              matlab.ui.control.DropDown
        MainPlaneDropDownLabel         matlab.ui.control.Label
        AxespropertiesPanel            matlab.ui.container.Panel
        AOScan3AxTypeDropDown          matlab.ui.control.DropDown
        Scan2Label_2                   matlab.ui.control.Label
        AOScan2AxTypeDropDown          matlab.ui.control.DropDown
        Scan2Label                     matlab.ui.control.Label
        AOScan1AxTypeDropDown          matlab.ui.control.DropDown
        Scan1Label                     matlab.ui.control.Label
        NavigatorPropertiesPanel       matlab.ui.container.Panel
        LineNavigatorLabel             matlab.ui.control.Label
        LineNavAx1DropDown             matlab.ui.control.DropDown
        LineNavAx2Label                matlab.ui.control.Label
        LineNavAx3Label                matlab.ui.control.Label
        LineNavAx4Label                matlab.ui.control.Label
        LineNavAx2Spinner              matlab.ui.control.Spinner
        LineNavAx3Spinner              matlab.ui.control.Spinner
        LineNavAx4Spinner              matlab.ui.control.Spinner
        LineNavAx2IdxLabel             matlab.ui.control.Label
        LineNavAx3IdxLabel             matlab.ui.control.Label
        LineNavAx4IdxLabel             matlab.ui.control.Label
        PlaneNavigatorLabel            matlab.ui.control.Label
        PlaneNavAx1DropDown            matlab.ui.control.DropDown
        PlaneNavAx2DropDown            matlab.ui.control.DropDown
        PlaneNavAx3Label               matlab.ui.control.Label
        PlaneNavAx4Label               matlab.ui.control.Label
        PlaneNavAx3IdxSpinner          matlab.ui.control.Spinner
        PlaneNavAx4IdxSpinner          matlab.ui.control.Spinner
        PlaneNavAx3IdxLabel            matlab.ui.control.Label
        PlaneNavAx4IdxLabel            matlab.ui.control.Label
        NormalizePlaneColorsCheckBox   matlab.ui.control.CheckBox
        OthersTab                      matlab.ui.container.Tab
        RefreshFiguresPanel            matlab.ui.container.Panel
        RefreshButton                  matlab.ui.control.Button
        DropDown                       matlab.ui.control.DropDown
        PopoutFiguresPanel             matlab.ui.container.Panel
        PopTab4Button                  matlab.ui.control.Button
        PopTab3Button                  matlab.ui.control.Button
        PopTab2Button                  matlab.ui.control.Button
        PopTab1Button                  matlab.ui.control.Button
        ReOpenFiguresCheckBox          matlab.ui.control.CheckBox
        ExternalFiguresCheckBox        matlab.ui.control.CheckBox
        FiguresToPopDropDown           matlab.ui.control.DropDown
        PopButton                      matlab.ui.control.Button
        PEUSAxespropertiesPanel        matlab.ui.container.Panel
        PEUSSecondScanAxTypeDropDown   matlab.ui.control.DropDown
        DepthReconAxLabel              matlab.ui.control.Label
        PEUSFirstScanAxTypeDropDown    matlab.ui.control.DropDown
        ScanReconAxLabel               matlab.ui.control.Label
        OAScanNavigatorPanel           matlab.ui.container.Panel
        OANormalizeColorstoDisplayedPlaneCheckBox  matlab.ui.control.CheckBox
        OANavmmLabel                   matlab.ui.control.Label
        OANavIdxSpinner                matlab.ui.control.Spinner
        NavIdxSpinnerLabel_5           matlab.ui.control.Label
        OANavPlaneDropDown             matlab.ui.control.DropDown
        NavPlaneLabel_2                matlab.ui.control.Label
        OAAxespropertiesPanel          matlab.ui.container.Panel
        OATimeAxTypeDropDown           matlab.ui.control.DropDown
        TimeAxLabel                    matlab.ui.control.Label
        OAScanAxTypeDropDown           matlab.ui.control.DropDown
        ScanAxLabel                    matlab.ui.control.Label
        OASecondScanAxTypeDropDown     matlab.ui.control.DropDown
        SecondReconAxLabel             matlab.ui.control.Label
        OAFirstScanAxTypeDropDown      matlab.ui.control.DropDown
        FirstReconAxLabel              matlab.ui.control.Label
        MeasMethod                     matlab.ui.container.TabGroup
        SingleMeasurementTab           matlab.ui.container.Tab
        PauseButton                    matlab.ui.control.Button
        ResetRunButton                 matlab.ui.control.Button
        TopUpButton                    matlab.ui.control.Button
        ImportResultsPanel             matlab.ui.container.Panel
        LoadRawDataCheckBox            matlab.ui.control.CheckBox
        ImportButton                   matlab.ui.control.Button
        analysisDataPathButton         matlab.ui.control.Button
        PathEditField_2                matlab.ui.control.EditField
        PathEditField_2Label           matlab.ui.control.Label
        LiveAcoustoOpticsPanel         matlab.ui.container.Panel
        AOLiveRunButton                matlab.ui.control.Button
        NEditField                     matlab.ui.control.NumericEditField
        NEditFieldLabel                matlab.ui.control.Label
        LimitLiveAOCheckBox            matlab.ui.control.CheckBox
        AOPositionPanel                matlab.ui.container.Panel
        AOMeasHereCheckBox             matlab.ui.control.CheckBox
        AOYpositionEditField           matlab.ui.control.NumericEditField
        YpositionEditField_2Label      matlab.ui.control.Label
        AOXpositionEditField           matlab.ui.control.NumericEditField
        XpositionEditField_2Label      matlab.ui.control.Label
        mmLabel_18                     matlab.ui.control.Label
        mmLabel_17                     matlab.ui.control.Label
        AORunButton                    matlab.ui.control.Button
        AOScanTab                      matlab.ui.container.Tab
        StopButton                     matlab.ui.control.Button
        AOScanImportLimitsfromfileButton  matlab.ui.control.Button
        AOScanImportScanVarsButton     matlab.ui.control.Button
        AOScanExportScanVarsButton     matlab.ui.control.Button
        AOScanImportResultsButton      matlab.ui.control.Button
        AOScanRunButton                matlab.ui.control.Button
        AOScanPanel                    matlab.ui.container.Panel
        AOSAxisScan3Label              matlab.ui.control.Label
        AOScan3EndEditField            matlab.ui.control.NumericEditField
        EndEditField_2Label_3          matlab.ui.control.Label
        AOScanAx3EndmmLabel            matlab.ui.control.Label
        AOScan3StartEditField          matlab.ui.control.NumericEditField
        StartEditField_2Label_3        matlab.ui.control.Label
        AOScanAx3StartmmLabel          matlab.ui.control.Label
        AOScan3StrideEditField         matlab.ui.control.NumericEditField
        StrideEditField_2Label_3       matlab.ui.control.Label
        AOScanAx3StridemmLabel         matlab.ui.control.Label
        KeppUSONCheckBox               matlab.ui.control.CheckBox
        ScanTypeDropDown               matlab.ui.control.DropDown
        ScanTypeDropDownLabel          matlab.ui.control.Label
        DontMoveNonScanninStages       matlab.ui.control.CheckBox
        AOScanAx2StartmmLabel          matlab.ui.control.Label
        AOScan3AxisLabel               matlab.ui.control.Label
        AOScan2AxisDropDown            matlab.ui.control.DropDown
        AOScan2AxisLabel               matlab.ui.control.Label
        AOScan1AxisDropDown            matlab.ui.control.DropDown
        AOScan1AxisLabel               matlab.ui.control.Label
        AOScan2StrideEditField         matlab.ui.control.NumericEditField
        StrideEditField_2Label         matlab.ui.control.Label
        AOScan1StrideEditField         matlab.ui.control.NumericEditField
        StrideEditFieldLabel           matlab.ui.control.Label
        AOScan2EndEditField            matlab.ui.control.NumericEditField
        EndEditField_2Label            matlab.ui.control.Label
        AOScan2StartEditField          matlab.ui.control.NumericEditField
        StartEditField_2Label          matlab.ui.control.Label
        AOScan1EndEditField            matlab.ui.control.NumericEditField
        EndEditFieldLabel              matlab.ui.control.Label
        AOScan1StartEditField          matlab.ui.control.NumericEditField
        StartEditFieldLabel            matlab.ui.control.Label
        AOScanAx2StridemmLabel         matlab.ui.control.Label
        AOScanAx2EndmmLabel            matlab.ui.control.Label
        AOScanAx1EndmmLabel            matlab.ui.control.Label
        AOScanAx1StridemmLabel         matlab.ui.control.Label
        AOScanAx1StartmmLabel          matlab.ui.control.Label
        OptoAcousticsTab               matlab.ui.container.Tab
        OAScanEndmmLabel               matlab.ui.control.Label
        OAScanStridemmLabel            matlab.ui.control.Label
        OAScanStartmmLabel             matlab.ui.control.Label
        OARunLiveButton                matlab.ui.control.Button
        ScanAxisEditField              matlab.ui.control.EditField
        ScanAxisEditFieldLabel         matlab.ui.control.Label
        SecondAxisEditField            matlab.ui.control.EditField
        SecondAxisEditFieldLabel       matlab.ui.control.Label
        FirstAxisEditField             matlab.ui.control.EditField
        FirstAxisEditFieldLabel        matlab.ui.control.Label
        EndEditField                   matlab.ui.control.NumericEditField
        EndEditFieldLabel_5            matlab.ui.control.Label
        StrideEditField                matlab.ui.control.NumericEditField
        StrideEditFieldLabel_5         matlab.ui.control.Label
        StartSinglePosEditField        matlab.ui.control.NumericEditField
        StartSinglePosEditFieldLabel   matlab.ui.control.Label
        OARunSingleButton              matlab.ui.control.Button
        OARunScanButton                matlab.ui.control.Button
        PulseEchoUSTab                 matlab.ui.container.Tab
        PEUSDontmovesecondscanaxisCheckBox  matlab.ui.control.CheckBox
        PEUSRunScanButton              matlab.ui.control.Button
        mmLabel_45                     matlab.ui.control.Label
        mmLabel_44                     matlab.ui.control.Label
        mmLabel_43                     matlab.ui.control.Label
        PEUSThirdsAxisEditField        matlab.ui.control.EditField
        ThirdsAxisEditFieldLabel       matlab.ui.control.Label
        PEUSDepthAxisEditField         matlab.ui.control.EditField
        DepthAxisEditFieldLabel        matlab.ui.control.Label
        PEUSScanAxisEditField          matlab.ui.control.EditField
        ScanAxisEditField_3Label       matlab.ui.control.Label
        PEUSEndEditField               matlab.ui.control.NumericEditField
        EndEditField_2Label_2          matlab.ui.control.Label
        PEUSStrideEditField            matlab.ui.control.NumericEditField
        StrideEditField_2Label_2       matlab.ui.control.Label
        PEUSStartEditField             matlab.ui.control.NumericEditField
        StartEditField_2Label_2        matlab.ui.control.Label
        mmLabel_50                     matlab.ui.control.Label
        cmLabel_4                      matlab.ui.control.Label
        PEUSImageWidthEditField        matlab.ui.control.NumericEditField
        ImageWidthEditField_2Label     matlab.ui.control.Label
        PEUSDistancefromImageCenterEditField  matlab.ui.control.NumericEditField
        DistancefromImageCenterLabel   matlab.ui.control.Label
        IntSystemTab                   matlab.ui.container.Tab
        RunIntImagingButton            matlab.ui.control.Button
        cmLabel_7                      matlab.ui.control.Label
        cmLabel_6                      matlab.ui.control.Label
        IntSysDistanceFromImageCenterEditField  matlab.ui.control.NumericEditField
        DistanceFromImageCenterLabel   matlab.ui.control.Label
        IntSysImageWidthEditField      matlab.ui.control.NumericEditField
        ImageWidthEditField_2Label_2   matlab.ui.control.Label
        OAScanEndEditField             matlab.ui.control.NumericEditField
        OAScanEndEditFieldLabel        matlab.ui.control.Label
        OAScanStrideEditField          matlab.ui.control.NumericEditField
        OAScanStrideEditFieldLabel     matlab.ui.control.Label
        OAScanStartEditField           matlab.ui.control.NumericEditField
        OAScanStartEditFieldLabel      matlab.ui.control.Label
        AOScanEndEditField             matlab.ui.control.NumericEditField
        AOScanEndEditFieldLabel        matlab.ui.control.Label
        AOScanStrideEditField          matlab.ui.control.NumericEditField
        AOScanStrideEditFieldLabel     matlab.ui.control.Label
        AOScanStartEditField           matlab.ui.control.NumericEditField
        AOScanStartEditFieldLabel      matlab.ui.control.Label
        OAScanAxisEditField            matlab.ui.control.EditField
        OAScanAxisEditFieldLabel       matlab.ui.control.Label
        AODepthAxisEditField           matlab.ui.control.EditField
        AODepthAxisEditFieldLabel      matlab.ui.control.Label
        AOScanAxisEditField            matlab.ui.control.EditField
        AOScanAxisEditFieldLabel       matlab.ui.control.Label
        SimulationSavePanel            matlab.ui.container.Panel
        SaveAOCheckBox                 matlab.ui.control.CheckBox
        SaveCurrentMeasButton          matlab.ui.control.Button
        dirBrowseButton                matlab.ui.control.Button
        saveUSCmplxCheckBox            matlab.ui.control.CheckBox
        SavedDataSpecificationsLabel   matlab.ui.control.Label
        saveFFTCheckBox                matlab.ui.control.CheckBox
        saveReshapedCheckBox           matlab.ui.control.CheckBox
        saveDeMultiplexedCheckBox      matlab.ui.control.CheckBox
        saveNetSignalCheckBox          matlab.ui.control.CheckBox
        saveRawDataCheckBox            matlab.ui.control.CheckBox
        saveFigsCheckBox               matlab.ui.control.CheckBox
        saveResultsandVarsCheckBox     matlab.ui.control.CheckBox
        PathEditField                  matlab.ui.control.EditField
        PathLabel                      matlab.ui.control.Label
        MeasNameEditField              matlab.ui.control.EditField
        MeasNameLabel                  matlab.ui.control.Label
        AcoustoOpticSystemLabel        matlab.ui.control.Label
    end


    properties (Access = public)
        %parameters
        importedLimits % Description

        %--------------------------
        % New Variables
        %--------------------------
        
        %objects
        algo;
        ao;
        oa;
        aos;
        peus;
        intSys;
        
        %peripherals
        aoStages;
        scope
                
        %Graphics Objects Handles
        aoGraphics;
        AOSGraphics;
        hConsGraph;
        oaGraphics;
        peusGraphics;
        intSysGraphics;
        
        % AO Measurement Vars
        navVars

        % Data vars
        algoData;
        aoData;
        AOSData;
        stagesData;
        oaData;
        peusData;
        intSysData;
        
        %GUI control Variables
        GUICtrl;
        stgGUI;
    end
    
    events
        updateFrame
        updateSet
%         updateFrame
        
        updatePhiRepEvent
        updatePhiEvent
        
        timeTable
    end
    
    methods (Access = public)
        
        function displayAOTimeTable(app, ~, ~)

            timeTable = app.ao.getTimeTable();
            dataAlgo  = app.extractStructDataToTable(timeTable.algo);
            dataDigi  = app.extractStructDataToTable(timeTable.digitizer);
            dataAO(1,1:2) = {'Overall',sum([timeTable.acq timeTable.reconstruct timeTable.moveData])}; 
            dataAO(2,1:2) = {'Overall Sampling',timeTable.acq}; 
            dataAO(3,1:2) = {'Overall Analysis',timeTable.reconstruct}; 
            dataAO(4,1:2) = {'Gather Raw Data', timeTable.moveData};
    
            app.UITable2.Data = dataAlgo(:,1:2);
            app.UITable2_2.Data = dataDigi(:,1:2);
            app.UITable2_3.Data = dataAO;  

        end
    end

    methods (Access = private)

        function applyImportedLimits(app)
             app.AOScan1StartEditField.Value   = app.importedLimits.startPosX; 
             app.AOScan1EndEditField.Value     = app.importedLimits.endPosX; 
             app.AOScan2StartEditField.Value  = app.importedLimits.startPosY; 
             app.AOScan2EndEditField.Value    = app.importedLimits.endPosY; 
             app.AOScan1StrideEditField.Value  = app.importedLimits.Xstride; 
             app.AOScan2StrideEditField.Value = app.importedLimits.Ystride; 
        end
    
        function data = extractStructDataToTable(~, curStruct)
            names= fieldnames(curStruct);
            j=1;
            num = numel(names);
            data = cell(num,3);
            for i=1:num
                %this function requires mapping toolbox
                fieldVal  = extractfield(curStruct, names{i});
                if length(fieldVal)> 1 || (~isa(fieldVal, 'double') && ~isa(fieldVal, 'logical')) 
                    continue;
                end
                data{j,1} = names{i};
                data{j,2} = fieldVal;
                data{j,3} = 'unit';
                j=j+1;
            end
        end

        %---------------- Algo Functions ---------------%
        function initAlgoVars(app)
           app.algoData.uVars = Algo.createUserVars(); 
           app.collectAlgoVars();

           app.algoData.uVars.bufferSizeBytes   = 8*2^20;
           app.algoData.uVars.bytesPerSample    = 2;
           app.algoData.uVars.preTriggerSamples = 14;
        end
        
        function collectAlgoVars(app) 
            % US Signal
            app.algoData.uVars.cycPerPulse     = app.CyclesPerPulseEditField.Value;
            app.algoData.uVars.fSin            = app.SineFreqEditField.Value*1e6;
            app.algoData.uVars.fSqnc           = app.SquenceFreqEditField.Value*1e3;
            app.algoData.uVars.frameTime       = app.FrameTimeEditField.Value;
            
            % Sampling Clk
            app.algoData.uVars.fs              = app.FrequencyEditField.Value*1e6; %fs
            app.algoData.uVars.sClkDcyc        = app.DutyCycleEditField.Value;
            app.algoData.uVars.fgClk           = app.SamplesClkFreqEditField.Value*1e6;
            
            % Geometry
            app.algoData.uVars.c               = app.SpeedofSoundEditField.Value; 
            app.algoData.uVars.distFromPhantom = app.TransDistEditField.Value*1e-2;              
            
            % Digitizer 
            app.algoData.uVars.timeToSample    = app.TimetosampleEditField.Value;
            app.algoData.uVars.channels        = app.NoOfChannelsEditField.Value;
            
            % Frequency
            app.algoData.uVars.envDC           = app.envDC.Value*1e3;
            app.algoData.uVars.envUS           = app.envUS.Value*1e3;
            
            % General Operation
            app.algoData.uVars.useFrame            = app.UseTimeFrameCheckBox.Value;
            app.algoData.uVars.useGPU              = false;
            app.algoData.uVars.useHadamard         = app.HadamardCodeCheckBox.Value;
            app.algoData.uVars.analyzeSingleCh     = app.SeparateChCheckBox.Value;
            
            app.algoData.uVars.measTimeLimit       = app.MeasLimitEditField.Value;
            
            %Artifacts
            app.algoData.uVars.cutArtfct    = app.CutArtifactsCheckBox.Value;
            app.algoData.uVars.artfctIdxVec = str2num(app.IndexesEditField.Value);
            
            % SNR:
            app.algoData.uVars.extSNRDef    = app.UseVirtualDataCheckBox.Value;
            app.algoData.uVars.extNoiseIdx  = str2num(app.BkgIdxsEditField.Value);
            app.algoData.uVars.extPeakIdx   = app.PeakIdxEditField.Value;

            % Export data
            app.algoData.uVars.exportData.meas        = false;
            app.algoData.uVars.exportData.signal      = false;
            app.algoData.uVars.exportData.deMul       = false;
            app.algoData.uVars.exportData.reshaped    = false;
            app.algoData.uVars.exportData.fft         = false;
            app.algoData.uVars.exportData.usCompCmplx = false;
       end
            
        function setAlgoValuesTable(app)
            data = app.extractStructDataToTable(app.algoData.curRes.sClk);
            app.UITable.Data = data;
            
            data = app.extractStructDataToTable(app.algoData.curRes.usSignal);
            app.UITable_2.Data = data;
            
            data = app.extractStructDataToTable(app.algoData.curRes.samples);
            app.UITable_3.Data = data;
            
            data = app.extractStructDataToTable(app.algoData.curRes.timing);
            app.UITable_4.Data = data;
            
%             data = app.extractStructDataToTable(app.algoData.curRes.digitizer);
%             app.UITable_5.Data = data;
            
            data = app.extractStructDataToTable(app.algoData.curRes.len);
            app.UITable_6.Data = data;
            
            data = app.extractStructDataToTable(app.algoData.curRes.freq);
            app.UITable_7.Data = data;
        end

        %---------------- Acousto Optics Functions ---------------%       
        % Variables
        function initAOVars(app)
            % These are static variables that shouldn't be changed during operation
            % These variables are initialized and never changed again.
            app.aoData.uVars = acoustoOptics.createUserVars();
            
            app.collectAOVars();
            
            app.aoData.uVars.figs.fonts.titleSize  = 14;
            app.aoData.uVars.figs.fonts.labelsSize = 14;
            app.aoData.uVars.figs.fonts.axisSize   = 14;
            
            % Handles
            for i=1:length(app.GUICtrl.figsCtrl.ao.figsNames)
                curFig = app.GUICtrl.figsCtrl.ao.figsNames{i};
                axName = sprintf("%sAx",curFig);
                app.aoData.uVars.figs.extH.(curFig).fig = app.AOIUIFigure;
                app.aoData.uVars.figs.extH.(curFig).ax  = app.(axName);
            end
        end
        
        function uVars = collectAOVars(app)
            % US Signal
            app.aoData.uVars.ao.cycPerPulse = app.CyclesPerPulseEditField.Value;
            app.aoData.uVars.ao.fSin        = app.SineFreqEditField.Value*1e6; 
            app.aoData.uVars.ao.fSqnc       = app.SquenceFreqEditField.Value*1e3;
            app.aoData.uVars.ao.frameTime   = app.FrameTimeEditField.Value;          
            app.aoData.uVars.ao.usPower = app.USPowerEditField.Value;

            % Sampling Clk
            app.aoData.uVars.ao.fs       = app.FrequencyEditField.Value*1e6;
            app.aoData.uVars.ao.sClkDcyc = app.DutyCycleEditField.Value;
            app.aoData.uVars.ao.fgClk    = app.SamplesClkFreqEditField.Value*1e6;
            
            % Digitizer
            app.aoData.uVars.ao.timeToSample  = app.TimetosampleEditField.Value;
            app.aoData.uVars.ao.channels      = app.NoOfChannelsEditField.Value;
            app.aoData.uVars.ao.measTimeLimit = app.MeasLimitEditField.Value;

            % Frequency
            app.aoData.uVars.ao.envDC = app.envDC.Value*1e3;
            app.aoData.uVars.ao.envUS = app.envUS.Value*1e3;
            
            % Geometry & Length
            app.aoData.uVars.ao.c               = app.SpeedofSoundEditField.Value;
            app.aoData.uVars.ao.distFromPhantom = app.TransDistEditField.Value*1e-2;

            % General Operation
            app.aoData.uVars.ao.useFrame        = app.UseTimeFrameCheckBox.Value;
            app.aoData.uVars.ao.useGPU          = app.UseGPUCheckBox.Value;
            app.aoData.uVars.ao.useHadamard     = app.HadamardCodeCheckBox.Value;
            app.aoData.uVars.ao.acCoupling      = app.ACCouplingCheckBox.Value;
            app.aoData.uVars.ao.useCalibration  = app.UseCalibrationCheckBox.Value;
            app.aoData.uVars.ao.analyzeSingleCh = app.SeparateChCheckBox.Value;
            app.aoData.uVars.ao.keepSplits      = app.KeepSplitsCheckBox.Value;
            app.aoData.uVars.ao.externalIOCtrl  = app.ExternalIOCtrlCheckBox.Value;
            app.aoData.uVars.ao.displayBuildUp  = app.DisplayBuildUpCheckBox.Value;
            app.aoData.uVars.ao.displayBUEvery  = app.DispEveryEditField.Value;
            app.aoData.uVars.ao.dispTimeTable = app.DispTimeTableCheckBox.Value;
            app.aoData.uVars.ao.timeToSampleCal = app.DurationEditField.Value;
            
            app.aoData.uVars.ao.cutArtfct    = app.CutArtifactsCheckBox.Value;
            app.aoData.uVars.ao.artfctIdxVec = str2num(app.IndexesEditField.Value);
            
            app.aoData.uVars.ao.calcMuEff  = app.CalcMuEffCheckBox.Value;
            app.aoData.uVars.ao.muEffModel = app.GeometryDropDown_2.Value;
            app.aoData.uVars.ao.muEffIdxs  = str2num(app.muEffIndexesEditField.Value);
            
            app.aoData.uVars.ao.extSNRDef   = app.ExternalSNRDefinitionCheckBox.Value;
            app.aoData.uVars.ao.extNoiseIdx = str2num(app.BkgIdxsEditField.Value);
            app.aoData.uVars.ao.extPeakIdx  = app.PeakIdxEditField.Value;

            app.aoData.uVars.ao.uploadToTelegram = app.UpdatedviaTelegramCheckBox.Value;
            app.aoData.uVars.ao.telegramChatID   = app.ChatIDEditField.Value;
            app.aoData.uVars.ao.tgBUEvery        = app.SendEveryEditField.Value;
            
            % Set Acousto Optics Object Control Vars
            app.aoData.uVars.ao.useVirtualData       = app.UseVirtualDataCheckBox.Value;
            app.aoData.uVars.ao.virtualDataNoiseSTD  = app.NoiesStdEditField.Value;
            
            % Live AOI
            app.aoData.uVars.ao.limitByN      = app.LimitLiveAOCheckBox.Value;
            app.aoData.uVars.ao.N             = app.NEditField.Value;

            % Export
            app.aoData.uVars.ao.exportData.meas        = app.MeasuredCheckBox.Value;       
            app.aoData.uVars.ao.exportData.signal      = app.SignalCheckBox.Value;
            app.aoData.uVars.ao.exportData.deMul       = app.DeMultiplexedCheckBox.Value;
            app.aoData.uVars.ao.exportData.reshaped    = app.ReshapedExportCheckBox.Value;
            app.aoData.uVars.ao.exportData.fft         = app.FFTCheckBox.Value;
            app.aoData.uVars.ao.exportData.usCompCmplx = app.ComplexCompUSCheckBox.Value;
            
            % Collect Graphics properties
            app.collectAOGraphicVars(); 
            
            % Collect file system properties
            app.collectAOFileSystemVars();

            uVars = app.aoData.uVars;
        end
        
        function fileSystem = collectAOFileSystemVars(app)
            app.aoData.uVars.fileSystem.saveMeas     = app.saveRawDataCheckBox.Value;
            app.aoData.uVars.fileSystem.saveSignal   = app.saveNetSignalCheckBox.Value;
            app.aoData.uVars.fileSystem.saveDeMul    = app.saveDeMultiplexedCheckBox.Value;
            app.aoData.uVars.fileSystem.saveReshaped = app.saveReshapedCheckBox.Value;
            app.aoData.uVars.fileSystem.saveFFT      = app.saveFFTCheckBox.Value;
            
            app.aoData.uVars.fileSystem.saveResults = app.saveResultsandVarsCheckBox.Value;
            app.aoData.uVars.fileSystem.saveFigs    = app.saveFigsCheckBox.Value;
            app.aoData.uVars.fileSystem.saveVars    = app.saveResultsandVarsCheckBox.Value;
            
            app.aoData.uVars.fileSystem.projName    = app.MeasNameEditField.Value;
            app.aoData.uVars.fileSystem.dirPath     = app.PathEditField.Value;
            
            app.aoData.uVars.fileSystem.extProject         = false;
            app.aoData.uVars.fileSystem.extProjPath        = [];
            app.aoData.uVars.fileSystem.extProjResultsPath = [];
            app.aoData.uVars.fileSystem.extProjFigsPath    = [];
            
            fileSystem = app.aoData.uVars.fileSystem;
        end

        function figs = collectAOGraphicVars(app)
            % Choose channel and position            
            app.aoData.uVars.figs.ch             = app.SignalChannelSpinner.Value;
            app.aoData.uVars.figs.singleChIdx    = app.SeparateChannelSpinner.Value;
            app.aoData.uVars.figs.depthIdx       = app.AODepthIdxSpinner.Value;
            app.aoData.uVars.figs.depthAxType    = app.AODepthAxTypeDropDown.Value;
            app.aoData.uVars.figs.frame          = app.FrameAOSpinner.Value;
            app.aoData.uVars.figs.FFTenv         = app.FreqEnvEditField.Value*1e3;
            app.aoData.uVars.figs.displayFullFFT = app.dispFullFFTCheckBox.Value;
            
            app.aoData.uVars.figs.reopenFigures  = app.ReOpenFiguresCheckBox.Value;

            % Choose internal or external
            if app.ExternalFiguresCheckBox.Value
                app.aoData.uVars.figs.intExt = 'int';
            else
                app.aoData.uVars.figs.intExt = 'ext';
            end
            
            % Turn figures on and off
            for i=1:length(app.GUICtrl.figsCtrl.ao.figsNames)
                curFig = app.GUICtrl.figsCtrl.ao.figsNames{i};
                if app.GUICtrl.init
                    app.aoData.uVars.figs.validStruct.(curFig) = false;
                else
                    app.aoData.uVars.figs.validStruct.(curFig) = app.GUICtrl.figsCtrl.ao.figsValStruct.(curFig);
                end
            end
            
            figs = app.aoData.uVars.figs;
        end
        
        function figs = collectAOGraphicOwnerVars(app)
            % Choose channel and position
            figs = app.ao.measVars.figs;
            
            figs.ch          = app.SignalChannelSpinner.Value;
            figs.depthIdx    = app.AODepthIdxSpinner.Value;
            figs.frame       = app.FrameAOSpinner.Value;
            figs.singleChIdx = app.SeparateChannelSpinner.Value;
            
            figs.reopenFigures  = app.ReOpenFiguresCheckBox.Value;
            figs.depthAxType    = app.AODepthAxTypeDropDown.Value;
            figs.FFTenv         = app.FreqEnvEditField.Value*1e3;
            figs.displayFullFFT = app.dispFullFFTCheckBox.Value;
        end

        function setAOVarsToGUI(app, vars)
            % US Signal
            app.CyclesPerPulseEditField.Value = vars.ao.cycPerPulse;
            app.SineFreqEditField.Value       = vars.ao.fSin/1e6; 
            app.SquenceFreqEditField.Value    = vars.ao.fSqnc/1e3;
            app.FrameTimeEditField.Value      = vars.ao.frameTime;     
            
            % Sampling Clk
            app.FrequencyEditField.Value      = vars.ao.fs /1e6;     
            app.DutyCycleEditField.Value      = vars.ao.sClkDcyc;
            app.SamplesClkFreqEditField.Value = vars.ao.fgClk /1e6;  
            
            % Digitizer
            app.TimetosampleEditField.Value = vars.ao.timeToSample;
            app.NoOfChannelsEditField.Value = vars.ao.channels;
            app.MeasLimitEditField.Value    = vars.ao.measTimeLimit;

            % Frequency
            app.envDC.Value = vars.ao.envDC/1e3;
            app.envUS.Value = vars.ao.envUS/1e3;
             
            % Geometry & Length
            app.SpeedofSoundEditField.Value = vars.ao.c;
            app.TransDistEditField.Value    = vars.ao.distFromPhantom/1e-2;

            % General Operation
            app.UseTimeFrameCheckBox.Value   = vars.ao.useFrame;
            app.UseGPUCheckBox.Value         = vars.ao.useGPU;
            app.HadamardCodeCheckBox.Value   = vars.ao.useHadamard;
            app.ACCouplingCheckBox.Value     = vars.ao.acCoupling;
            app.UseCalibrationCheckBox.Value = vars.ao.useCalibration;
            app.SeparateChCheckBox.Value     = vars.ao.analyzeSingleCh;
            app.KeepSplitsCheckBox.Value     = vars.ao.keepSplits;
            app.DisplayBuildUpCheckBox.Value = vars.ao.displayBuildUp;
            app.DispEveryEditField.Value     = vars.ao.displayBUEvery;
            app.DurationEditField.Value      = vars.ao.timeToSampleCal;
            
            app.CutArtifactsCheckBox.Value = vars.ao.cutArtfct;
            app.IndexesEditField.Value     = sprintf("%d:%d", vars.ao.artfctIdxVec(1), vars.ao.artfctIdxVec(end)); % TODO: logic for several sequences
            
            app.CalcMuEffCheckBox.Value     = vars.ao.calcMuEff;
            app.GeometryDropDown_2.Value    = vars.ao.muEffModel;
            app.muEffIndexesEditField.Value = sprintf("%d:%d", vars.ao.muEffIdxs(1), vars.ao.muEffIdxs(end));

            app.ExternalSNRDefinitionCheckBox.Value = vars.ao.extSNRDef;
            app.BkgIdxsEditField.Value              = sprintf("%d:%d", vars.ao.extNoiseIdx(1), vars.ao.extNoiseIdx(end)); % TODO: logic for several sequences
            app.PeakIdxEditField.Value              = vars.ao.extPeakIdx;

            app.USPowerEditField.Value = vars.ao.usPower;
        end
        
        % GUI Control
        function updateAOChSpinnerLimits(app)
            channels = app.aoData.curVars.measVars.algo.general.channels;
            if channels == 1
                app.SignalChannelSpinner.Value = 1;
                app.SignalChannelSpinner.Enable = false;
                app.SeparateChannelSpinner.Value = 1;
                app.SeparateChannelSpinner.Enable = false;
                return
            end
            app.SignalChannelSpinner.Enable = true;
            app.SeparateChannelSpinner.Enable = true;
            if app.SignalChannelSpinner.Value > channels
                app.SignalChannelSpinner.Value = 1;
            end
            if app.SeparateChannelSpinner.Value > channels
                app.SeparateChannelSpinner.Value = 1;
            end
            app.SignalChannelSpinner.Limits = [1, channels];
            app.SeparateChannelSpinner.Limits = [1, channels];
        end
        
        function updateAODepthIdxSpinnerLimits(app)
                zVec = app.aoData.curVars.measVars.algo.len.depthVec;
                app.AODepthIdxSpinner.Step  = 1;
                if  app.AODepthIdxSpinner.Value > length(zVec)
                    app.AODepthIdxSpinner.Value = 1;
                    app.updateAODepthIdxSpinnerLabel()
                end  
                app.AODepthIdxSpinner.Limits = [1, length(zVec)];
        end
        
        function updateAOFrameSpinnerLimits(app)
            maxFrame = app.aoData.curVars.measVars.algo.samples.framesPerSignal;
            if maxFrame == 1
                app.FrameAOSpinner.Value  = 1;
                app.FrameAOSpinner.Enable = false;
                return;
            end
            app.FrameAOSpinner.Enable = true;
            if app.FrameAOSpinner.Value > maxFrame
                app.FrameAOSpinner.Value  = 1;
                app.FrameAOSpinner.Step   = 1;
            end
            app.FrameAOSpinner.Limits = [1, maxFrame]; 
        end
        
        function updateAODepthIdxSpinnerLabel(app)
            app.PlotPosmmLabel.Text = sprintf("%.2f[mm]", app.aoData.curVars.measVars.algo.len.depthVec(app.AODepthIdxSpinner.Value)*1e3);
        end

        function updateAOSpinners(app)
            app.updateAOChSpinnerLimits();
            app.updateAODepthIdxSpinnerLimits();
            app.updateAOFrameSpinnerLimits();
        end
        
        function updateTimeTable(app, total)
            timeTable = app.ao.getTimeTable();
            dataAlgo  = app.extractStructDataToTable(timeTable.algo);
            dataDigi  = app.extractStructDataToTable(timeTable.digitizer);
            dataAO(1,1:2) = {'Overall',total}; 
            dataAO(2,1:2) = {'Overall Sampling',timeTable.acq}; 
            dataAO(3,1:2) = {'Overall Analysis',timeTable.reconstruct}; 
            dataAO(4,1:2) = {'Gather Raw Data', timeTable.moveData};
            dataAO(5,1:2) = {'Data to Graphics', timeTable.setResultsToGraphics};
            dataAO(6,1:2) = {'Plots', timeTable.plotAll};
            dataAO(7,1:2) = {'Save data', timeTable.moveData};
            
            app.UITable2.Data   = dataAlgo(:,1:2);
            app.UITable2_2.Data = dataDigi(:,1:2);
            app.UITable2_3.Data = dataAO;
        end

        function arrangeAOFigures(app)
%             for j = 1:length(app.GUICtrl.objNames)
%                 obj = app.GUICtrl.objNames{j};
            obj = ["ao", "AOS"];
            for j = 1:2
                for i=1:length(app.GUICtrl.figsCtrl.(obj(j)).figsNames)
    
                    figName = app.GUICtrl.figsCtrl.(obj(j)).figsNames{i};
    
                    tabName = sprintf("%sTab", figName);
                    ddName  = sprintf("%sDropDown", figName);
                    
                    tabGroupName = app.(ddName).Value;
                    app.GUICtrl.figsCtrl.(obj(j)).figsValStruct.(figName) = true;
    
                    switch tabGroupName
                        case 'Tab 1'
                            app.(tabName).Parent = app.acoustoOpticsPlotsTab;
                        case 'Tab 2'
                            app.(tabName).Parent = app.PhiTabGroup;
                        case 'Tab 3'
                            app.(tabName).Parent = app.ScanPlotTab;
                        case 'Tab 4'
                            app.(tabName).Parent = app.FFTTabGroup;
                        case 'Hide'
                            app.(tabName).Parent = app.InvisiblePlotTabs;
                            app.GUICtrl.figsCtrl.(obj(j)).figsValStruct.(figName) = false;
                    end
                end
            end
        end

        % Figures
        function updateAOFigs(app)
            if ~isempty(app.aoData.curRes)
                figsVars = app.collectAOGraphicOwnerVars();
                app.ao.setFigsVars(figsVars);
            end
        end
        
        %---------------- Scan AO Functions ---------------%
        % Variables
        function staticVars = uVarsAOSInit(app)
           app.AOSData.uVars = scanAO.createUserVars();
           
           app.AOSData.uVars.figs.extH.scanPlane.fig     = app.AOIUIFigure;
           app.AOSData.uVars.figs.extH.scanPlane.ax      = app.axScanPlane;
           app.AOSData.uVars.figs.extH.scanPlaneLog.fig  = app.AOIUIFigure;
           app.AOSData.uVars.figs.extH.scanPlaneLog.ax   = app.axScanPlaneLog;

           app.AOSData.uVars.figs.extH.navLine.fig          = app.AOIUIFigure;
           app.AOSData.uVars.figs.extH.navLine.ax           = app.axNavLine;
           app.AOSData.uVars.figs.extH.navLineLog.fig       = app.AOIUIFigure;
           app.AOSData.uVars.figs.extH.navLineLog.ax        = app.axNavLineLog;

           app.AOSData.uVars.figs.extH.navPlane.fig    = app.AOIUIFigure;
           app.AOSData.uVars.figs.extH.navPlane.ax     = app.axNavPlane;
           app.AOSData.uVars.figs.extH.navPlaneLog.fig = app.AOIUIFigure;
           app.AOSData.uVars.figs.extH.navPlaneLog.ax  = app.axNavPlaneLog;

           app.AOSData.uVars.figs.fonts.type = [];
           app.AOSData.uVars.figs.fonts.titleSize  = 14;
           app.AOSData.uVars.figs.fonts.labelsSize = 14;
           app.AOSData.uVars.figs.fonts.axisSize   = 14;

           app.AOSData.uVars.figs.validStruct.scanPlane    = true;
           app.AOSData.uVars.figs.validStruct.scanPlaneLog = true;
           app.AOSData.uVars.figs.validStruct.navLine      = true;
           app.AOSData.uVars.figs.validStruct.navLineLog   = true;
           app.AOSData.uVars.figs.validStruct.navPlane     = true;
           app.AOSData.uVars.figs.validStruct.navPlaneLog  = true;
            
           staticVars.validStruct = app.AOSData.uVars.figs.validStruct;
           staticVars.intExt      = app.AOSData.uVars.figs.intExt;
           staticVars.extH        = app.AOSData.uVars.figs.extH;
        end 
        
        function collectAOSVars(app)
            % Acousto Optics variables
            app.AOSData.uVars.ao = app.collectAOVars;
            
            % General scan variables
            app.AOSData.uVars.general.externalScan = false;
            app.AOSData.uVars.general.keepUSOn     = app.KeppUSONCheckBox.Value;
            app.AOSData.uVars.general.tgSend = app.UpdatedviaTelegramCheckBox.Value;
            app.AOSData.uVars.general.chatID = app.ChatIDEditField.Value;

            % Grid scan variables
            app.AOSData.uVars.grid.scan1Start  = app.AOScan1StartEditField.Value;
            app.AOSData.uVars.grid.scan1Stride = app.AOScan1StrideEditField.Value;
            app.AOSData.uVars.grid.scan1End    = app.AOScan1EndEditField.Value;

            app.AOSData.uVars.grid.scan2Start  = app.AOScan2StartEditField.Value;
            app.AOSData.uVars.grid.scan2Stride = app.AOScan2StrideEditField.Value;
            app.AOSData.uVars.grid.scan2End    = app.AOScan2EndEditField.Value;

            app.AOSData.uVars.grid.scan3Start  = app.AOScan3StartEditField.Value;
            app.AOSData.uVars.grid.scan3Stride = app.AOScan3StrideEditField.Value;
            app.AOSData.uVars.grid.scan3End    = app.AOScan3EndEditField.Value;
            
            app.AOSData.uVars.grid.scan1Label = app.AOScan1AxisDropDown.Value;
            app.AOSData.uVars.grid.scan2Label = app.AOScan2AxisDropDown.Value;
            app.AOSData.uVars.grid.scan3Label = app.AOSAxisScan3Label.Text;
            app.AOSData.uVars.grid.depthLabel = 'D';

            app.AOSData.uVars.grid.scanType   = app.ScanTypeDropDown.Value;

            % Stages control variables
            app.AOSData.uVars.stages.stagesOwnedByParent = true;
            app.AOSData.uVars.stages.moveNonScanStages = ~app.DontMoveNonScanninStages.Value;
            
            % Figures variables
            app.collectAOSGraphicsVars();
            
            % File system variables
            app.collectAOSFileSystemVars;
        end
        
        function figs = collectAOSGraphicsVars(app)
            app.AOSData.uVars.figs.reopenFigures = app.ReOpenFiguresCheckBox.Value;
            app.AOSData.uVars.figs.sepChIdx      = app.SeparateChannelSpinner.Value;
            app.AOSData.uVars.figs.normDispPlaneColors = app.NormalizePlaneColorsCheckBox.Value;

            % Axes Types
            app.AOSData.uVars.figs.scan1AxType = app.AOScan1AxTypeDropDown.Value;
            app.AOSData.uVars.figs.scan2AxType = app.AOScan2AxTypeDropDown.Value;
            app.AOSData.uVars.figs.scan3AxType = app.AOScan3AxTypeDropDown.Value;
            app.AOSData.uVars.figs.depthAxType = app.AODepthAxTypeDropDown.Value;
            
            % Navigators
            app.AOSData.uVars.figs.varsNavLine  = app.collectLineNavVars();
            app.AOSData.uVars.figs.varsNavPlane = app.collectPlaneNavVars();
            
            if app.ExternalFiguresCheckBox.Value
                app.AOSData.uVars.figs.intExt = 'int';
            else
                app.AOSData.uVars.figs.intExt = 'ext';
            end
            
            % Turn figures on and off
            for i=1:length(app.GUICtrl.figsCtrl.AOS.figsNames)
                curFig = app.GUICtrl.figsCtrl.AOS.figsNames{i};
                if app.GUICtrl.init
                    app.AOSData.uVars.figs.validStruct.(curFig) = false;
                else
                    app.AOSData.uVars.figs.validStruct.(curFig) = app.GUICtrl.figsCtrl.AOS.figsValStruct.(curFig);
                end
            end

            figs = app.AOSData.uVars.figs;
        end
        
        function fileSystem = collectAOSFileSystemVars(app)
            app.AOSData.uVars.fileSystem.saveResults  = app.saveResultsandVarsCheckBox.Value;
            app.AOSData.uVars.fileSystem.saveFigs     = app.saveFigsCheckBox.Value;
            app.AOSData.uVars.fileSystem.saveAO       = app.SaveAOCheckBox.Value;
            
            app.AOSData.uVars.fileSystem.projName    = app.MeasNameEditField.Value;
            app.AOSData.uVars.fileSystem.dirPath     = app.PathEditField.Value;
            
            app.AOSData.uVars.fileSystem.extProject         = false;
            app.AOSData.uVars.fileSystem.extProjPath        = [];
            app.AOSData.uVars.fileSystem.extProjResultsPath = [];
            app.AOSData.uVars.fileSystem.extProjFigsPath    = [];
            
            fileSystem = app.AOSData.uVars.fileSystem; 
        end
        
        %---------------- Navigators --------------------%
        % Variables
        function navVars = collectPlaneNavVars(app)
            navVars.labels = [app.PlaneNavAx1DropDown.Value,...
                              app.PlaneNavAx2DropDown.Value,...
                              app.PlaneNavAx3Label.Text,...
                              app.PlaneNavAx4Label.Text];
            navVars.idx  = [app.PlaneNavAx3IdxSpinner.Value,...
                            app.PlaneNavAx4IdxSpinner.Value];
            app.navVars.plane = navVars;
        end
        
        function navVars = collectLineNavVars(app)
            navVars.labels = [app.LineNavAx1DropDown.Value, ...
                              app.LineNavAx2Label.Text, ...
                              app.LineNavAx3Label.Text, ...
                              app.LineNavAx4Label.Text];
            navVars.idx    = [app.LineNavAx2Spinner.Value, ...
                              app.LineNavAx3Spinner.Value, ...
                              app.LineNavAx4Spinner.Value];

            app.navVars.line = navVars;
        end
        
        % GUI
        function axVec = chooseGridVecByLabel(app, label)
            switch label
                case app.AOSData.curVars.grid.scan1Label
                    axVec = app.AOSData.curVars.grid.scan1Vec;
                case app.AOSData.curVars.grid.scan2Label
                    axVec = app.AOSData.curVars.grid.scan2Vec;
                case app.AOSData.curVars.grid.scan3Label
                    axVec = app.AOSData.curVars.grid.scan3Vec;
                case app.AOSData.curVars.grid.depthLabel
                    axVec = app.AOSData.curVars.grid.depthVec*1e3;
            end
        end

        function updatePlaneNavIdxSpinnerLimits(app)
            app.PlaneNavAx3IdxSpinner.LowerLimitInclusive = 'on';
            app.PlaneNavAx3IdxSpinner.Step  = 1;
            
            spinVec = app.chooseGridVecByLabel(app.PlaneNavAx1DropDown.Value);
            maxLim  = length(spinVec);
            if app.PlaneNavAx3IdxSpinner.Value > maxLim
                app.PlaneNavAx3IdxSpinner.Value = 1;
            end
            if maxLim ==1
                app.PlaneNavAx3IdxSpinner.Enable = false;
            else
                app.PlaneNavAx3IdxSpinner.Enable = true;
                app.PlaneNavAx3IdxSpinner.Limits = [1, maxLim];
            end
            app.updatePlaneNavIdxLabel();
        end
        
        function updatePlaneNavIdxLabel(app)
            idx = app.PlaneNavAx3IdxSpinner.Value;
            spinVec = app.chooseGridVecByLabel(app.PlaneNavAx1DropDown.Value);
            app.PlaneNavAx3IdxLabel.Text = sprintf("%.2f[mm]", spinVec(idx));
        end

        function updateLineNavAx2Component(app)
            ax2Label = app.LineNavAx2Label.Text;
            spinVec = app.chooseGridVecByLabel(ax2Label);
            if ~app.LineNavAx2Spinner.Enable
                app.LineNavAx2Spinner.Value = 1;
            else
                app.LineNavAx2Spinner.LowerLimitInclusive = 'on';
                app.LineNavAx2Spinner.Step = 1;
                spinVec = app.chooseGridVecByLabel(ax2Label);
                maxLim = length(spinVec);
                if app.LineNavAx2Spinner.Value > maxLim
                    app.LineNavAx2Spinner.Value = maxLim;
                end
                app.LineNavAx2Spinner.Limits = [1, maxLim];
            end
            idx = app.LineNavAx2Spinner.Value;
            app.LineNavAx2IdxLabel.Text = sprintf("%.2f[mm]", spinVec(idx));
        end
        
        function updateLineNavAx3Component(app)
            ax3Label = app.LineNavAx3Label.Text;
            spinVec = app.chooseGridVecByLabel(ax3Label);
            if ~app.LineNavAx3Spinner.Enable
                app.LineNavAx3Spinner.Value = 1;
            else
                app.LineNavAx3Spinner.LowerLimitInclusive = 'on';
                app.LineNavAx3Spinner.Step = 1;
                maxLim = length(spinVec);
                if app.LineNavAx3Spinner.Value > maxLim
                    app.LineNavAx3Spinner.Value = maxLim;
                end
                app.LineNavAx3Spinner.Limits = [1, maxLim];
            end
            idx = app.LineNavAx3Spinner.Value;
            app.LineNavAx3IdxLabel.Text = sprintf("%.2f[mm]", spinVec(idx));
        end
        
        function updateLineNavAx4Component(app)
            ax4Label = app.LineNavAx4Label.Text;
            spinVec = app.chooseGridVecByLabel(ax4Label);
            if ~app.LineNavAx4Spinner.Enable
                app.LineNavAx4Spinner.Value = 1;
            else
                app.LineNavAx4Spinner.LowerLimitInclusive = 'on';
                app.LineNavAx4Spinner.Step = 1;
                maxLim = length(spinVec);
                if app.LineNavAx4Spinner.Value > maxLim
                    app.LineNavAx4Spinner.Value = maxLim;
                end
                app.LineNavAx4Spinner.Limits = [1, maxLim];
            end
            idx = app.LineNavAx4Spinner.Value;
            app.LineNavAx4IdxLabel.Text = sprintf("%.2f[mm]", spinVec(idx));
        end
        
        function updateAOSLineNavigatorComponents(app)
            scanDims = app.AOSData.curVars.grid.scanDims;
            ax1Label = app.AOSData.curVars.grid.scan1Label;
            ax2Label = app.AOSData.curVars.grid.scan2Label;
            ax3Label = app.AOSData.curVars.grid.scan3Label;

            switch scanDims
                case 1
                    app.LineNavAx1DropDown.Items = {ax1Label, 'D'};
                    app.LineNavAx2Spinner.Enable = false;
                    app.LineNavAx3Spinner.Enable = false;
                case 2
                    app.LineNavAx1DropDown.Items = {ax1Label, ax2Label, 'D'};
                    app.LineNavAx2Spinner.Enable = true;
                    app.LineNavAx3Spinner.Enable = false;
                case 3
                    app.LineNavAx1DropDown.Items = {ax1Label, ax2Label, ax3Label, 'D'};
                    app.LineNavAx2Spinner.Enable = true;
                    app.LineNavAx3Spinner.Enable = true;
            end

            app.LineNavAx1DropDown.Value = ax1Label;
            app.LineNavAx2Label.Text = ax2Label;
            app.LineNavAx3Label.Text = ax3Label;
            app.LineNavAx4Label.Text = 'D';
            
            app.updateLineNavAx2Component();
            app.updateLineNavAx3Component();
            app.updateLineNavAx4Component();
        end

        function updatePlaneNavAx3Component(app)
            ax3Label = app.PlaneNavAx3Label.Text;
            spinVec = app.chooseGridVecByLabel(ax3Label);
            if ~app.PlaneNavAx3Spinner.Enable
                app.PlaneNavAx3Spinner.Value = 1;
            else
                app.PlaneNavAx3Spinner.LowerLimitInclusive = 'on';
                app.PlaneNavAx3Spinner.Step = 1;
                spinVec = app.chooseGridVecByLabel(ax3Label);
                maxLim = length(spinVec);
                if app.PlaneNavAx3Spinner.Value > maxLim
                    app.PlaneNavAx3Spinner.Value = maxLim;
                end
                app.PlaneNavAx3Spinner.Limits = [1, maxLim];
            end
            idx = app.PlaneNavAx3Spinner.Value;
            app.PlaneNavAx3IdxLabel.Text = sprintf("%.2f[mm]", spinVec(idx));
        end
        
        function updatePlaneNavAx4Component(app)
            ax4Label = app.PlaneNavAx4Label.Text;
            spinVec = app.chooseGridVecByLabel(ax4Label);
            if ~app.PlaneNavAx4Spinner.Enable
                app.PlaneNavAx4Spinner.Value = 1;
            else
                app.PlaneNavAx4Spinner.LowerLimitInclusive = 'on';
                app.PlaneNavAx4Spinner.Step = 1;
                spinVec = app.chooseGridVecByLabel(ax4Label);
                maxLim = length(spinVec);
                if app.PlaneNavAx4Spinner.Value > maxLim
                    app.PlaneNavAx4Spinner.Value = maxLim;
                end
                app.PlaneNavAx4Spinner.Limits = [1, maxLim];
            end
            idx = app.PlaneNavAx4Spinner.Value;
            app.PlaneNavAx4IdxLabel.Text = sprintf("%.2f[mm]", spinVec(idx));
        end

        function updateAOSPlaneNavigatorComponents(app)
            scanDims = app.AOSData.curVars.grid.scanDims;
            ax1Label = app.AOSData.curVars.grid.scan1Label;
            ax2Label = app.AOSData.curVars.grid.scan2Label;
            ax3Label = app.AOSData.curVars.grid.scan3Label;
            
            curAx1DD = app.PlaneNavAx1DropDown.Value;
            curAx2DD = app.PlaneNavAx2DropDown.Value;
            curAx3Sp = app.PlaneNavAx3IdxSpinner.Value;
            curAx4Sp = app.PlaneNavAx3IdxSpinner.Value;

            switch scanDims
                case 1
                    dd1Itmes = {ax1Label, 'D'};
                    dd2Itmes= {ax1Label, 'D'};
                    app.PlaneNavAx3IdxSpinner.Enable = false;
                    app.PlaneNavAx4IdxSpinner.Enable = false;
                case 2
                    dd1Itmes = {ax1Label, ax2Label, 'D'};
                    dd2Itmes = {ax1Label, ax2Label, 'D'};
                    app.PlaneNavAx3IdxSpinner.Enable = true;
                    app.PlaneNavAx4IdxSpinner.Enable = false;
                case 3
                    dd1Itmes = {ax1Label, ax2Label, ax3Label, 'D'};
                    dd2Itmes = {ax1Label, ax2Label, ax3Label, 'D'};
                    app.PlaneNavAx3IdxSpinner.Enable = true;
                    app.PlaneNavAx4IdxSpinner.Enable = true;
            end
            app.PlaneNavAx1DropDown.Items = dd1Itmes;
            app.PlaneNavAx2DropDown.Items = dd2Itmes;

            if ~sum(strcmp(curAx1DD, dd1Itmes))
                app.PlaneNavAx1DropDown.Value = ax1Label;
                if strcmp(ax1Label,  curAx3Sp)
                    app.PlaneNavAx3Label.Text = curAx1DD;
                elseif strcmp(ax1Label,  curAx4Sp)
                    app.PlaneNavAx4Label.Text = curAx1DD;
                end
            end

            if ~sum(strcmp(curAx2DD, dd2Itmes))
                app.PlaneNavAx2DropDown.Value = 'D';
                if strcmp('D',  curAx3Sp)
                    app.PlaneNavAx3Label.Text = curAx2DD;
                elseif strcmp('D',  curAx4Sp)
                    app.PlaneNavAx4Label.Text = curAx2DD;
                end
            end
            
            app.updatePlaneNavAx3Component();
            app.updatePlaneNavAx4Component();
        end

        function updateAOScanNavComponents(app)
            app.updatePlaneNavIdxSpinnerLimits();
            app.updateAOSLineNavigatorComponents();
        end

        % Figures
        function updateLineNavigator(app)
            vars = app.collectLineNavVars();
            app.AOSGraphics.setNavLineVars(vars);
            app.AOSGraphics.dispLineNavAll();
        end
        
        function updatePlaneNavigator(app)
            vars = app.collectPlaneNavVars();
            app.AOSGraphics.setNavPlaneVars(vars);
            app.AOSGraphics.dispPlaneNavAll();
        end
        
        %---------------- Opto-Acoustics ---------------%
        function staticVars = uVarsOAInit(app)
           app.oaData.uVars = optoAcoustic.uVarsCreate();
           
           app.oaData.uVars.figs.extH.sinogram.fig     = app.AOIUIFigure;
           app.oaData.uVars.figs.extH.sinogram.ax      = app.axOASinogram;
           app.oaData.uVars.figs.extH.recon.fig        = app.AOIUIFigure;
           app.oaData.uVars.figs.extH.recon.ax         = app.axOABP;
           app.oaData.uVars.figs.extH.navSinogram.fig  = app.AOIUIFigure;
           app.oaData.uVars.figs.extH.navSinogram.ax   = app.axOANavSinogram;
           app.oaData.uVars.figs.extH.navRecon.fig     = app.AOIUIFigure;
           app.oaData.uVars.figs.extH.navRecon.ax      = app.axOANavBP;

           app.oaData.uVars.figs.fonts.type = [];
           app.oaData.uVars.figs.fonts.titleSize  = 14;
           app.oaData.uVars.figs.fonts.labelsSize = 14;
           app.oaData.uVars.figs.fonts.axisSize   = 14;

           app.oaData.uVars.figs.validStruct.sinogram    = true;
           app.oaData.uVars.figs.validStruct.recon        = true;
           app.oaData.uVars.figs.validStruct.navSinogram  = true;
           app.oaData.uVars.figs.validStruct.navRecon     = true;

           staticVars.validStruct = app.oaData.uVars.figs.validStruct;
           staticVars.intExt      = app.oaData.uVars.figs.intExt;
           staticVars.extH        = app.oaData.uVars.figs.extH;
        end

        function uVars = collectOAVars(app)
            %DAQ
            app.oaData.uVars.activeChannels  = 1:256; 
            app.oaData.uVars.numOfSamples    = app.SamplesEditField.Value;
            app.oaData.uVars.numOfAvg        = app.AveragesEditField.Value;
            app.oaData.uVars.numOfFrames     = app.FramesEditField.Value;
            
            %BP
            app.oaData.uVars.c  = app.SpeedofSoundEditField.Value;
            app.oaData.uVars.fs = app.SamplingRateEditField.Value;
            app.oaData.uVars.geometry = app.GeometryDropDown.Value;
            app.oaData.uVars.imageWidth = app.ImageWidthEditField.Value*1e-3; %[m]
            switch app.BPModeDropDown.Value
                case 'Direct'
                    app.oaData.uVars.BPmode = 1;
                case 'Derivative'
                    app.oaData.uVars.BPmode = 2;
                case 'Full'
                    app.oaData.uVars.BPmode = 3;
            end
            
            % GRID and Stages
            app.oaData.uVars.scanStart  = app.StartSinglePosEditField.Value;
            app.oaData.uVars.scanStride = app.StrideEditField.Value;
            app.oaData.uVars.scanEnd    = app.EndEditField.Value;
            
            app.oaData.uVars.firstAxis  = app.FirstAxisEditField.Value;
            app.oaData.uVars.secondAxis = app.SecondAxisEditField.Value;
            app.oaData.uVars.scanAxis   = app.ScanAxisEditField.Value;
            
            % Graphics
            app.collectOAGraphicsVars();
            
            uVars = app.oaData.uVars;
        end
        
        function figs = collectOAGraphicsVars(app)

            app.oaData.uVars.figs.useExtClims   = false;
            app.oaData.uVars.figs.reopenFigures = app.ReOpenFiguresCheckBox.Value;

            app.oaData.uVars.figs.firstAxType  = app.OAFirstScanAxTypeDropDown.Value;
            app.oaData.uVars.figs.secondAxType = app.OASecondScanAxTypeDropDown.Value;
            app.oaData.uVars.figs.scanAxType   = app.OAScanAxTypeDropDown.Value;
            app.oaData.uVars.figs.timeAxType   = app.OATimeAxTypeDropDown.Value;
            
            app.oaData.uVars.figs.scanAxCoor   = app.ScanAxisEditField.Value;

            app.oaData.uVars.figs.useExtClims = false;
            app.oaData.uVars.figs.reopenFigures = app.ReOpenFiguresCheckBox.Value;
            
            if app.ExternalFiguresCheckBox.Value
                app.oaData.uVars.figs.intExt = 'int';
            else
                app.oaData.uVars.figs.intExt = 'ext';
            end
            
            figs = app.oaData.uVars.figs;
        end
        
        function updateOANAvigatorSpinner(app)
            app.OANavIdxSpinner.Limits = [1, app.oaData.curVars.measVars.grid.scanIdxLen];
            app.OANavIdxSpinner.Value = 1;
            app.updateOANavigatorLabel();
        end
        
        function updateOANavigatorLabel(app)
           idx = app.OANavIdxSpinner.Value;
           app.OANavmmLabel.Text = sprintf("%.2f[mm]", app.oaGraphics.figs.scanAxVec(idx));
        end
        
        %---------------- PEUS ---------------%
        function staticVars = uVarsPEUSInit(app)
           app.peusData.uVars = pulseEchoUS.uVarsCreate();
           
           app.peusData.uVars.stagesOwnedByParent = true;
           app.peusData.uVars.figs.extH.rawData.fig     = app.AOIUIFigure;
           app.peusData.uVars.figs.extH.rawData.ax      = app.axPEUSRaw;
           app.peusData.uVars.figs.extH.recon.fig       = app.AOIUIFigure;
           app.peusData.uVars.figs.extH.recon.ax        = app.axPEUSCorrected;

           app.peusData.uVars.figs.fonts.type = [];
           app.peusData.uVars.figs.fonts.titleSize  = 14;
           app.peusData.uVars.figs.fonts.labelsSize = 14;
           app.peusData.uVars.figs.fonts.axisSize   = 14;

           app.peusData.uVars.figs.validStruct.rawData     = true;
           app.peusData.uVars.figs.validStruct.recon       = true;

           staticVars.validStruct = app.peusData.uVars.figs.validStruct;
           staticVars.intExt      = app.peusData.uVars.figs.intExt;
           staticVars.extH        = app.peusData.uVars.figs.extH;
        end
        
        function uVars = collectPEUSVars(app)
            %DAQ
            app.peusData.uVars.distFromCenter = app.PEUSDistancefromImageCenterEditField.Value*1e-2; 
            app.peusData.uVars.imageWidth     = app.PEUSImageWidthEditField.Value*1e-3;
            
            app.peusData.uVars.signalChannel = app.PEUSChannelNoEditField.Value;
            app.peusData.uVars.trigCh        = app.PEUSTrigChannelEditField.Value;
            app.peusData.uVars.avg           = app.PEUSNumofAvgEditField.Value;
            app.peusData.uVars.usRepRate     = app.PEUSRepetitionRateEditField.Value;
            app.peusData.uVars.pulseIntVal   = app.PEUSIntValEditField.Value;
            
            app.peusData.uVars.c              = app.SpeedofSoundEditField.Value;
            app.peusData.uVars.usFreq         = app.SineFreqEditField.Value*1e6;
            app.peusData.uVars.trigCh           = app.PEUSTrigChannelEditField.Value;
            app.peusData.uVars.avg              = app.PEUSNumofAvgEditField.Value;
            app.peusData.uVars.usRepRate        = app.PEUSRepetitionRateEditField.Value;
            app.peusData.uVars.pulseIntVal      = app.PEUSIntValEditField.Value; %[m]
 
            % GRID and Stages
            app.peusData.uVars.moveSecondStage = ~app.PEUSDontmovesecondscanaxisCheckBox.Value;
            
            app.peusData.uVars.scanStart  = app.PEUSStartEditField.Value;
            app.peusData.uVars.scanStride = app.PEUSStrideEditField.Value;
            app.peusData.uVars.scanEnd    = app.PEUSEndEditField.Value;
            
            app.peusData.uVars.scanAxis  = app.PEUSScanAxisEditField.Value;
            app.peusData.uVars.depthAxis = app.PEUSDepthAxisEditField.Value;
            app.peusData.uVars.thirdAxis = app.PEUSThirdsAxisEditField.Value;
            
            app.peusData.uVars.thirdPos = 0;
            
            % Graphics
            app.collectPEUSGraphicsVars();
            
            uVars = app.peusData.uVars;
        end
        
        function figs = collectPEUSGraphicsVars(app)
            if app.ExternalFiguresCheckBox.Value
                app.peusData.uVars.figs.intExt = 'int';
            else
                app.peusData.uVars.figs.intExt = 'ext';
            end
            
            app.peusData.uVars.figs.useExtClims   = false;
            app.peusData.uVars.figs.reopenFigures = app.ReOpenFiguresCheckBox.Value;
            
            app.peusData.uVars.figs.depthAxType   = app.PEUSFirstScanAxTypeDropDown.Value;
            app.peusData.uVars.figs.scanAxType    = app.PEUSSecondScanAxTypeDropDown.Value;

            figs = app.peusData.uVars.figs;
        end
       
        %---------------- Int - Sys ---------------%
        function staticVars = uVarsIntSysInit(app)
           app.intSysData.uVars = IntSys.uVarsCreate();
           
           app.intSysData.uVars.figs.extH.corrected.fig     = app.AOIUIFigure;
           app.intSysData.uVars.figs.extH.corrected.ax      = app.axCorrectedOA;

           app.intSysData.uVars.figs.fonts.type = [];
           app.intSysData.uVars.figs.fonts.titleSize  = 14;
           app.intSysData.uVars.figs.fonts.labelsSize = 14;
           app.intSysData.uVars.figs.fonts.axisSize   = 14;

           app.intSysData.uVars.figs.validStruct.corrected    = true;

           staticVars.validStruct = app.intSysData.uVars.figs.validStruct;
           staticVars.intExt      = app.intSysData.uVars.figs.intExt;
           staticVars.extH        = app.intSysData.uVars.figs.extH;
        end
        
        function uVars = collectIntSysVars(app)
            %DAQ
            app.intSysData.uVars.distFromCenter    = app.IntSysDistanceFromImageCenterEditField.Value*1e-3;
            app.intSysData.uVars.imageWidth        = app.IntSysImageWidthEditField.Value*1e-2; 
            app.intSysData.uVars.c                 = app.SpeedofSoundEditField.Value;
            
            app.intSysData.uVars.grid.aoScanStart  = app.AOScanStartEditField.Value; 
            app.intSysData.uVars.grid.aoScanStride = app.AOScanStrideEditField.Value;
            app.intSysData.uVars.grid.aoScanEnd    = app.AOScanEndEditField.Value;
            
            app.intSysData.uVars.grid.oaScanStart  = app.OAScanStartEditField.Value;
            app.intSysData.uVars.grid.oaScanStride = app.OAScanStrideEditField.Value;
            app.intSysData.uVars.grid.oaScanEnd    = app.OAScanEndEditField.Value;
            
            app.intSysData.uVars.grid.aoScanAxis   = app.AOScanAxisEditField.Value; % should be constantlu defined
            app.intSysData.uVars.grid.oaScanAxis   = app.OAScanAxisEditField.Value; % should be constantlu defined
            app.intSysData.uVars.grid.depthAxis    = app.AODepthAxisEditField.Value;
            
            app.intSysData.uVars.aoAOS = app.collectAOSVars();
            app.intSysData.uVars.oa    = app.collectOAVars();
            app.intSysData.uVars.peus  = app.collectPEUSVars();
            
            % Graphics
            app.collectIntSysGraphicsVars();
            
            uVars = app.intSysData.uVars;
        end
        
        function figs = collectIntSysGraphicsVars(app)
            if app.ExternalFiguresCheckBox.Value
                app.intSysData.uVars.figs.intExt = 'int';
            else
                app.intSysData.uVars.figs.intExt = 'ext';
            end
            
            app.intSysData.uVars.figs.useExtClims   = false;
            app.intSysData.uVars.figs.reopenFigures = app.ReOpenFiguresCheckBox.Value;
            
            app.peusData.uVars.figs.depthAxType   = app.PEUSFirstScanAxTypeDropDown.Value;
            app.peusData.uVars.figs.scanAxType    = app.PEUSSecondScanAxTypeDropDown.Value;

            figs = app.peusData.uVars.figs;
        end
        
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            addpath(genpath(pwd));
            f = waitbar(0,'Starting GUI: Resetting Peripherals');
            if evalin('base', "exist('ao')"); evalin('base', "clear('ao')"); end
            if evalin('base', "exist('oa')"); evalin('base', "clear('oa')"); end
            if evalin('base', "exist('aos')"); evalin('base', "clear('aos')"); end
%             if evalin('base', "exist('stagesAO')"); evalin('base', "clear('stagesAO')"); end
            if evalin('base', "exist('peus')"); evalin('base', "clear('peus')"); end
            if evalin('base', "exist('intSys')"); evalin('base', "clear('intSys')"); end
            if evalin('base', "exist('app')"); evalin('base', "clear('app')"); end

            instrreset;
            app.GUICtrl.init = true;
            app.GUICtrl.curMeas = "ao";
            app.GUICtrl.objNames = ["ao", "AOS", "oa", "peus", "intSys"];

            %-------- Acousto Optics ---------%
            waitbar(.1,f,'Initing Acousto Optics Object');
            fprintf("GUI: Initing Acousto Optics Object\n");
            app.GUICtrl.figsCtrl.ao.figsNames = AOGraphics.getGraphicsNames();

            app.initAOVars();
            app.ao = acoustoOptics();
            app.ao.init(app);
            app.aoGraphics = app.ao.getGraphicsHandle();

            app.aoData.importedVars = [];
            app.aoData.importedRes  = [];
            app.aoData.curVars      = [];
            app.aoData.curRes       = [];
       
            app.GUICtrl.restartAO = true;  % to init AO object in the beginning of the first run
            app.GUICtrl.liveAO    = false; % for toggling the button Run Live \ Stop Live
            app.GUICtrl.paused    = false;

            % --------- Algorithm ------- %
            waitbar(.2,f,'Initing Algorithm Object');
            fprintf("GUI: Initing Algorithm Object\n");
            app.initAlgoVars();
            app.algo = Algo();
            
            %-------- Stages ---------%
            app.stgGUI = stagesGUI();
            app.aoStages = app.stgGUI.getStgHdl();
%             waitbar(.3,f,'Connecting to Stages');
%             fprintf("GUI: Connecting to Stages\n");
%             prompt = {'Choose Stages to connect:'; 'Port'};
%             dlgtitle = 'Choose Your Weapon';
%             dims = [1 35];
%             definput = {'Zaber'; 'COM3'};
%             answer = inputdlg(prompt,dlgtitle,dims,definput);
%             
%             app.aoStages = stages(answer{1}, answer{2});
%             app.aoStages.connect();

            %--------- Scope -------%
            waitbar(.3,f,'Connecting to Scope');
            fprintf("GUI: Connecting to Scope\n");
            Scope_visa_address = 'USB0::0x1AB1::0x04CE::DS1ZA172115085::0::INSTR';
            app.scope = Rigol_DS1074z('ni', Scope_visa_address);
            
            %-------- OptoAcoustics -------%
            waitbar(.4,f,'Creatign Opto Acoustics Object');
            fprintf("GUI: Creatign Opto Acoustics Object\n");
            app.uVarsOAInit();
            app.oa = optoAcoustic(app.aoStages);
            app.oaGraphics = app.oa.graphics;
            
            app.oaData.importedVars = [];
            app.oaData.importedRes  = [];
            app.oaData.curVars      = [];
            app.oaData.curRes       = [];
            
            app.GUICtrl.figsCtrl.oa.figsNames = app.oaGraphics.figsNames;
            %-------- AO Scan --------%
            waitbar(.5,f,'Creatign AO Scan Object');
            fprintf("GUI: Creatign AO Scan Object\n");
            app.uVarsAOSInit();
            app.aos         = scanAO(app.ao, app.aoStages);
            app.AOSGraphics = app.aos.getGraphicsHandle();
            
            app.AOSData.importedVars = [];
            app.AOSData.importedRes  = [];
            app.AOSData.curVars     = [];
            app.AOSData.curRes       = [];

            app.GUICtrl.figsCtrl.AOS.figsNames = app.AOSGraphics.figsNames;
             
            % ------- PEUS --------%
            waitbar(.8,f,'Creatign PEUS Scan Object');
            fprintf("GUI: Creatign PEUS Scan Object\n");
            app.uVarsPEUSInit();
            app.peus = pulseEchoUS(app.scope, app.aoStages);
            app.peusGraphics = app.peus.graphics;
            
            app.peusData.importedVars = [];
            app.peusData.importedRes  = [];
            app.peusData.curVars      = [];
            app.peusData.curRes       = [];
            
            app.GUICtrl.figsCtrl.peus.figsNames = app.peusGraphics.figsNames;
            % ------- Int-Sys --------%
            waitbar(.9,f,'Creatign Int Sys Scan Object');
            fprintf("GUI: Creatign Int Sys Scan Object\n");
            app.uVarsIntSysInit();
            
            handles.aos    = app.aos;
            handles.stages = app.aoStages;
            handles.oa     = app.oa;
            handles.peUS   = app.peus;
            
            app.intSys = IntSys(handles);
%             app.intSysGraphics = app.intSys.graphics;
            
            app.intSysData.importedVars = [];
            app.intSysData.importedRes  = [];
            app.intSysData.curVars     = [];
            app.intSysData.curRes       = [];
            
            %-------- Managing GUI ------%
            % AOS Graphics:
            app.navLineTab.Parent      = app.InvisiblePlotTabs;
            app.navLineLogTab.Parent   = app.InvisiblePlotTabs;
            app.scanPlaneTab.Parent          = app.InvisiblePlotTabs;
            app.scanPlaneLogTab.Parent       = app.InvisiblePlotTabs;
            app.navPlaneTab.Parent     = app.InvisiblePlotTabs;
            app.navPlaneLogTab.Parent  = app.InvisiblePlotTabs;

            % Opto Acoustics:
            app.BPTab.Parent            = app.InvisiblePlotTabs;
            app.SinogramTab.Parent      = app.InvisiblePlotTabs;
            app.OANavBPTab.Parent       = app.InvisiblePlotTabs;
            app.OANavSinogramTab.Parent = app.InvisiblePlotTabs;
            
            % Pulse Echo US:
            app.PEUSRawTab.Parent            = app.InvisiblePlotTabs;
            app.PEUSCorrectedTab.Parent      = app.InvisiblePlotTabs;
            
            % Int System:
            app.CorrectedOATab.Parent        = app.InvisiblePlotTabs;
            
            app.arrangeAOFigures();
            % ---------- Assigning object to base workspace --------%
            waitbar(1, f, 'Assigining Objects to workspace');
            fprintf("GUI: Assigining Objects to workspace\n");
            assignin('base', 'ao',      app.ao);
            assignin('base', 'oa',      app.oa);
            assignin('base', 'aos',     app.aos);
%             assignin('base', 'stagesAO',  app.aoStages);
            assignin('base', 'peus',    app.peus);
            assignin('base', 'intSys',  app.intSys);
            assignin('base', 'app',     app);
            
            app.GUICtrl.init = false;
            close(f)
        end

        % Close request function: AOIUIFigure
        function AOIUIFigureCloseRequest(app, event)
            try
                delete(app.stgGUI)
                app.aoStages.delete();
                if app.oa.daq.vars.connected
                    app.oa.daq.disconnect();
                end
            catch
                
            end
            delete(app)
        end

        % Size changed function: AOIUIFigure
        function AOIUIFigureSizeChanged(app, event)
%             position = app.AOIUIFigure.Position;
            
        end

        % Button pushed function: AORunButton
        function AORunButtonPushed(app, event)
            app.GUICtrl.curMeas = "ao";
            if app.GUICtrl.liveAO 
                fprintf("GUI: Stopping Live AO, Please wait...\n");
                app.ao.stopMeas = true;
                app.AORunButton.Text = 'Run';
                app.GUICtrl.liveAO = false;
            else
                app.collectAOVars();
                app.ao.setVars(app.aoData.uVars);
                app.ao.configPeripherals();
                
                app.AORunButton.Text = 'Stop AO';
                app.GUICtrl.liveAO = true;

                total = tic;
                app.aoData.curRes = app.ao.runAcoustoOptics();
                total = toc(total);
                
                app.AORunButton.Text = 'Run';
                app.GUICtrl.liveAO = false;

                app.aoData.curVars = app.ao.getVars();
                
                app.updateAOSpinners();
                
                app.updateTimeTable(total);
            end
        end

        % Button pushed function: StopButton
        function StopButtonPushed(app, event)
            app.aos.stopScan = true;
        end

        % Button pushed function: PauseButton
        function PauseButtonPushed(app, event)
            if app.GUICtrl.liveAO 
                fprintf("GUI: Stopping Live AO, Please wait...\n");
                app.ao.stopMeas = true;
                app.ao.pauseMeas = true;
                app.PauseButton.Text = 'Resume';
                app.GUICtrl.liveAO = false;
                app.GUICtrl.paused = true; 
            elseif app.GUICtrl.paused
                app.PauseButton.Text = "Pause";
                app.GUICtrl.liveAO = true;
                uiresume();
            else
                fprintf("GUI: Nothing to pause. Invalid Operation.\n");
            end

        end

        % Button pushed function: ResetRunButton
        function ResetRunButtonPushed(app, event)
            app.GUICtrl.liveAO = false;
            app.AORunButton.Text = 'Run';
            app.AOLiveRunButton.Text = 'Run Live';
        end

        % Button pushed function: AOLiveRunButton
        function AOLiveRunButtonPushed(app, event)
            if app.GUICtrl.liveAO 
                fprintf("GUI: Stopping Live AO, Please wait...\n");
                app.ao.contLive = false;
                app.AOLiveRunButton.Text = 'Run Live';
                app.GUICtrl.liveAO = false;
            else
                if app.GUICtrl.restartAO
                    app.ao.init();
                    app.GUICtrl.restartAO = false;
                end
                app.collectAOVars();
                
                app.ao.setVars(app.aoData.uVars);
                app.ao.configPeripherals();
                
                % For spinner update:
                app.aoData.curVars = app.ao.getVars();
                app.updateAOSpinners();
                
                app.AOLiveRunButton.Text = 'Stop Live';
                
                app.GUICtrl.liveAO = true;
                app.aoData.curRes = app.ao.liveAcoustoOptics();
                app.AOLiveRunButton.Text = 'Run Live';
                app.GUICtrl.liveAO = false;
            end
        end

        % Button pushed function: ReCalcDataButton
        function ReCalcDataButtonPushed(app, event)
            app.collectAOVars();
            app.ao.reCalcData(app.aoData.uVars);
            
            app.aoData.curVars = app.ao.getVars();
            
            app.updateAOChSpinnerLimits();
            app.updateAODepthIdxSpinnerLimits();
            app.updateAOFrameSpinnerLimits();
            
        end

        % Button pushed function: TopUpButton
        function TopUpButtonPushed(app, event)
            if app.GUICtrl.liveAO 
                fprintf("GUI: Please wait for current measurement to stop.\n");
            else                
                app.AORunButton.Text = 'Stop AO';
                app.GUICtrl.liveAO = true;

                total = tic;
                app.aoData.curRes = app.ao.runTopUp;
                total = toc(total);
                
                app.AORunButton.Text = 'Run';
                app.GUICtrl.liveAO = false;                
                
                app.updateTimeTable(total);
            end
        end

        % Button pushed function: CalibrateButton
        function CalibrateButtonPushed(app, event)
            app.collectAOVars();
            
            total = tic;
            app.ao.calibrate(app.aoData.uVars)
            total = toc(total);

            app.aoData.curVars = app.ao.getVars();

            app.updateTimeTable(total)
        end

        % Button pushed function: analysisVarsPathButton
        function analysisVarsPathButtonPushed(app, event)
            [filename, path] = uigetfile('C:\Users\ahiad\OneDrive - Technion\Graduate\AcoustoOpticSystem\Measurements', '*.mat');
            app.VarsEditField.Value = [path, filename]; 
            app.AnalyzeOrigButton.Enable = false;
            app.AnalyizeNewButton.Enable = false;
        end

        % Button pushed function: analysisDataPathButton
        function analysisDataPathButtonPushed(app, event)
            path = uigetdir('D:\');
            app.PathEditField_2.Value = path;
        end

        % Button pushed function: ImportButton
        function ImportAOResultstButtonPushed(app, event)
            dir = app.PathEditField_2.Value;
            loadRawData = app.LoadRawDataCheckBox.Value;
            fprintf("GUI: Trying to load results and vars\n");
            data     = app.ao.loadData(dir, loadRawData);
            if ~isempty(data)
                app.setAOVarsToGUI(data.vars.uVarsNew);
                fprintf("GUI: Successfully loaded results and vars.\n");
            else
                fprintf("GUI: Invalid Path\n");
            end
        end

        % Value changed function: AODepthIdxSpinner
        function AODepthIdxSpinnerValueChanged(app, event)
            app.updateAODepthIdxSpinnerLabel();
            app.updateAOFigs();
        end

        % Button pushed function: AOScanImportLimitsfromfileButton
        function AOScanImportLimitsfromfileButtonPushed(app, event)
            [filename, path] = uigetfile('*.mat');
            app.importedLimits = load([path, filename]);
            applyImportedLimits(app);
        end

        % Menu selected function: PMTSaturationInspector
        function PMTSaturationInspectorMenuSelected(app, event)
            PMTSat;
        end

        % Menu selected function: ScanAssistantMenu
        function ScanAssistantMenuSelected(app, event)
             h = stagesCalib(app);
             waitfor(h);
             applyImportedLimits(app);
        end

        % Button pushed function: AOScanRunButton
        function AOScanRunButtonPushed(app, event)
            app.GUICtrl.curMeas = "AOS";

            app.collectAOSVars();
            
            app.aos.setUserVars(app.AOSData.uVars);
            app.AOSData.curVars = app.aos.getVars();
            app.aoData.curVars  = app.ao.getVars();
            
            app.updateAOScanNavComponents();
            app.updateAOSpinners();

            app.aos.configureScan();
            app.AOSData.curRes  = app.aos.scan();
            app.aoData.curRes   = app.ao.getResult;
        end

        % Button pushed function: AOScanImportResultsButton
        function ImportAOScanResultsButtonPushed(app, event)
            [filename, path] = uigetfile('C:\Users\ahiad\OneDrive - Technion\Graduate\AcoustoOpticSystem\Measurements', 'Select Scan Results *.mat file');
            if isempty(filename)
                fprintf("GUI: No loaded data\n");
                return;
            end
            h = waitbar(0,'Loading Results. Please wait...');
            data = load([path,filename]);
            [filename, path] = uigetfile('C:\Users\ahiad\OneDrive - Technion\Graduate\AcoustoOpticSystem\Measurements', 'Select Scan Variables *.mat file');
            if isempty(filename)
                fprintf("GUI: No loaded vars\n");
                close(h);
                return;
            end
            waitbar(.5,h,'Loading Variables. Please wait');
            vars = load([path,filename]);
            waitbar(1,h,'Done');
            close(h)

            if isfield(vars, 'uVars')
                app.setS3DVarsToGUI(vars.uVars);
                app.setAOVarsToGUI(vars.uVars.ao);
                app.s3DData.curVars = vars;
                app.s3DData.curData = data;
                vars.uVars.figs = app.collectS3DGraphicsVars();
                app.s3D.setLoaded3DData(data, vars);
                app.updateS3DGUIComponents();
            else
                fprintf("GUI: Failed to load variables. No uVars field found.\n");
            end
        end

        % Button pushed function: dirBrowseButton
        function dirBrowseButtonPushed(app, event)
            dir = app.PathEditField.Value;
            app.PathEditField.Value = uigetdir(dir);
        end

        % Selection change function: MeasMethod
        function MeasMethodSelectionChanged(app, event)
            selectedTab = app.MeasMethod.SelectedTab;
            switch(selectedTab.Title)               
                case 'AO Scan'
                    app.scanPlaneTab.Parent         = app.ScanPlotTab;
                    app.scanPlaneLogTab.Parent      = app.ScanPlotTab;
                    app.navLineTab.Parent     = app.ScanPlotTab;
                    app.navLineLogTab.Parent  = app.ScanPlotTab;
                    app.navPlaneLogTab.Parent = app.ScanPlotTab;
                    app.navPlaneTab.Parent    = app.ScanPlotTab;

                    app.BPTab.Parent            = app.InvisiblePlotTabs;
                    app.SinogramTab.Parent      = app.InvisiblePlotTabs;
                    app.OANavBPTab.Parent       = app.InvisiblePlotTabs;
                    app.OANavSinogramTab.Parent = app.InvisiblePlotTabs;
                    
                    app.PEUSRawTab.Parent       = app.InvisiblePlotTabs;
                    app.PEUSCorrectedTab.Parent = app.InvisiblePlotTabs;
                      
                    app.CorrectedOATab.Parent   = app.InvisiblePlotTabs;
                    
                    app.arrangeAOFigures();
                    
                    app.MeasNameEditField.Value = 'AO Scan';      
                case 'Single Measurement'
                    app.MeasNameEditField.Value = 'AcoustoOptics';
                case 'OptoAcoustics'
                    app.scanPlaneTab.Parent         = app.InvisiblePlotTabs;
                    app.scanPlaneLogTab.Parent      = app.InvisiblePlotTabs;
                    app.navLineTab.Parent     = app.InvisiblePlotTabs;
                    app.navLineLogTab.Parent  = app.InvisiblePlotTabs;
                    app.navPlaneLogTab.Parent = app.InvisiblePlotTabs;
                    app.navPlaneTab.Parent    = app.InvisiblePlotTabs;
                    
                    app.BPTab.Parent            = app.ScanPlotTab;
                    app.SinogramTab.Parent      = app.ScanPlotTab;
                    app.OANavBPTab.Parent       = app.ScanPlotTab;
                    app.OANavSinogramTab.Parent = app.ScanPlotTab;
                    
                    app.PEUSRawTab.Parent       = app.InvisiblePlotTabs;
                    app.PEUSCorrectedTab.Parent = app.InvisiblePlotTabs;
                     
                    app.CorrectedOATab.Parent   = app.InvisiblePlotTabs;
                     
                    app.MeasNameEditField.Value = 'OA';
                    
                case 'Pulse-Echo US'
                    app.scanPlaneTab.Parent         = app.InvisiblePlotTabs;
                    app.scanPlaneLogTab.Parent      = app.InvisiblePlotTabs;
                    app.navLineTab.Parent     = app.InvisiblePlotTabs;
                    app.navLineLogTab.Parent  = app.InvisiblePlotTabs;
                    app.navPlaneLogTab.Parent = app.InvisiblePlotTabs;
                    app.navPlaneTab.Parent    = app.InvisiblePlotTabs;
                    
                    app.BPTab.Parent            = app.InvisiblePlotTabs;
                    app.SinogramTab.Parent      = app.InvisiblePlotTabs;
                    app.OANavBPTab.Parent       = app.InvisiblePlotTabs;
                    app.OANavSinogramTab.Parent = app.InvisiblePlotTabs;
                    
                    app.PEUSRawTab.Parent       = app.ScanPlotTab;
                    app.PEUSCorrectedTab.Parent = app.ScanPlotTab;
                   
                    app.CorrectedOATab.Parent   = app.InvisiblePlotTabs;
                    
                    app.MeasNameEditField.Value = 'PEUS';
                case 'Int System'
                    app.scanPlaneTab.Parent         = app.ScanPlotTab;
                    app.scanPlaneLogTab.Parent      = app.ScanPlotTab;
                    app.navLineTab.Parent     = app.InvisiblePlotTabs;
                    app.navLineLogTab.Parent  = app.InvisiblePlotTabs;                    
                    app.navPlaneLogTab.Parent = app.InvisiblePlotTabs;
                    app.navPlaneTab.Parent    = app.InvisiblePlotTabs;
                    
                    app.BPTab.Parent            = app.ScanPlotTab;
                    app.SinogramTab.Parent      = app.ScanPlotTab;
                    app.OANavBPTab.Parent       = app.ScanPlotTab;
                    app.OANavSinogramTab.Parent = app.ScanPlotTab;
                                 
                    app.PEUSRawTab.Parent       = app.ScanPlotTab;
                    app.PEUSCorrectedTab.Parent = app.ScanPlotTab;
                     
                    app.CorrectedOATab.Parent   = app.ScanPlotTab;
 
                    app.MeasNameEditField.Value = 'IntSys';
            end
        end

        % Selection change function: GlobalParameters
        function GlobalParametersSelectionChanged(app, event)
            
        end

        % Value changed function: FrequencyEditField
        function FrequencyEditFieldValueChanged(app, event)
            app.algoData.curRes = app.algo.setVars(app.algoData.uVars);
% %             app.updatePlotZSpinner();
        end

        % Value changed function: SineFreqEditField
        function SineFreqEditFieldValueChanged(app, event)
            app.algoData.curRes = app.algo.setVars(app.algoData.uVars);
%             app.updatePlotZSpinner();
        end

        % Value changed function: CyclesPerPulseEditField
        function CyclesPerPulseEditFieldValueChanged(app, event)
            app.algoData.curRes = app.algo.setVars(app.algoData.uVars);
%             app.updatePlotZSpinner();
        end

        % Value changed function: SquenceFreqEditField
        function SquenceFreqEditFieldValueChanged(app, event)
            app.algoData.curRes = app.algo.setVars(app.algoData.uVars);
%             app.updatePlotZSpinner(); 
        end

        % Value changed function: SpeedofSoundEditField
        function SpeedofSoundEditFieldValueChanged(app, event)
            app.algoData.curRes = app.algo.setVars(app.algoData.uVars);
%             app.updatePlotZSpinner();
        end

        % Value changed function: FrameAOSpinner
        function FrameAOSpinnerValueChanged(app, event)
            app.updateAOFigs();
        end

        % Value changed function: SignalChannelSpinner
        function ChannelAOSpinnerValueChanged(app, event)
            app.updateAOFigs();
        end

        % Value changed function: FreqEnvEditField
        function FreqEnviromentAOEditFieldValueChanged(app, event)
            app.updateAOFigs();
        end

        % Button pushed function: CloseIOPortButton
        function CloseIOPortButtonPushed(app, event)
            app.ao.IO.close();
            fprintf("Closing IO\n")
        end

        % Button pushed function: OpenIOPortButton
        function OpenIOPortButtonPushed(app, event)
            app.ao.IO.open();
        end

        % Button pushed function: TurnAFGOffButton
        function TurnAFGOffButtonPushed(app, event)
            app.ao.fGen.disableAllOutputs();
        end

        % Value changed function: LimitLiveAOCheckBox
        function LimitLiveAOCheckBoxValueChanged(app, event)
            value = app.LimitLiveAOCheckBox.Value;
            if value
                app.NEditField.Enable = true;
            else
                app.NEditField.Enable = false;
            end
        end

        % Button pushed function: ImportVarsButton
        function ImportVarsToGUIButtonPushed(app, event)
            [filename, path] = uigetfile('C:\Users\ahiad\OneDrive - Technion\Graduate\AcoustoOpticSystem\Measurements','*.mat');
            vars = load([path,filename]);
            if isfield(vars, 'uVars')
                app.setAOVarsToGUI(vars.uVars); 
            else
                app.setAOVarsToGUI(vars);
            end
        end

        % Button pushed function: ExportVarsButton
        function ExportVarsFromGUIButtonPushed(app, event)
            app.collectAOVars();
            % Saved as field called uVars in order to comply with AO vars saving procedure.
            vars.uVars = app.aoData.uVars;
            vars.uVars.figs = [];
            [filename, path] = uiputfile('C:\Users\ahiad\OneDrive - Technion\Graduate\AcoustoOpticSystem\Measurements', '*.mat');
            save([path, filename], '-struct', 'vars', '-v7.3');
        end

        % Value changed function: AOMeasHereCheckBox
        function AOMeasHereCheckBoxValueChanged(app, event)
            value = app.AOMeasHereCheckBox.Value;
            if value
                app.AOXpositionEditField.Enable = false;
                app.AOYpositionEditField.Enable = false;
            else
                app.AOXpositionEditField.Enable = true;
                app.AOYpositionEditField.Enable = true;
            end
        end

        % Button pushed function: AOScanExportScanVarsButton
        function AOScanExportScanVarsButtonPushed(app, event)
            app.collectS3DVars();
            % Saved as field called uVars in order to comply with AO vars saving procedure.
            vars.uVars = app.s2dData.uVars;
            vars.uVars.figs = [];
            vars.uVars.ao.figs = [];
            [filename, path] = uiputfile('*.mat', 'Save Vars As', 'C:\Users\ahiad\OneDrive - Technion\Graduate\AcoustoOpticSystem\Measurements');
            save([path, filename], '-struct', 'vars', '-v7.3');
        end

        % Button pushed function: AOScanImportScanVarsButton
        function AOScanImportScanVarsButtonPushed(app, event)
            [filename, path] = uigetfile('C:\Users\ahiad\OneDrive - Technion\Graduate\AcoustoOpticSystem\Measurements','*.mat');
            vars = load([path,filename]);
            if isfield(vars, 'uVars')
                app.setAOSVarsToGUI(vars.uVars);
                app.setAOVarsToGUI(vars.uVars.ao);
            else
                fprintf("GUI: Failed to load variables. No uVars field found.\n");
            end
        end

        % Value changed function: AOScan1AxisDropDown
        function AOScan1AxisDropDownValueChanged(app, event)
            value   = event.Value;
            prevVal = event.PreviousValue;

            if strcmp(value, app.AOScan2AxisDropDown.Value)
                app.AOScan2AxisDropDown.Value = prevVal;
            else
                app.AOSAxisScan3Label.Text = prevVal;
            end
        
        end

        % Value changed function: AOScan2AxisDropDown
        function AOScan2AxisDropDownValueChanged(app, event)
            value   = event.Value;
            prevVal = event.PreviousValue;

            if strcmp(value, app.AOScan1AxisDropDown.Value)
                app.AOScan1AxisDropDown.Value = prevVal;
            else
                app.AOSAxisScan3Label.Text = sprintf("Axis %s", prevVal);
            end
        end

        % Value changed function: NormalizePlaneColorsCheckBox
        function NormalizePlaneColorsCheckBoxValueChanged(app, event)
            value = app.NormalizePlaneColorsCheckBox.Value;
            app.AOSGraphics.setDispPlaneColorNorm(value);
            app.AOSGraphics.dispAll();
        end

        % Button pushed function: PopButton
        function PopButtonPushed(app, event)
            switch app.FiguresToPopDropDown.Value
                case 'Acousto Optics'
                    if app.aoData.uVars.figs.validStruct.extClk
                        f = figure();
                        ax=axes(f);
                        copyUIAxes(app.extClkAx,ax);
                    end
                    if app.aoData.uVars.figs.validStruct.usSignal 
                        f = figure();
                        ax=axes(f);
                        copyobj(app.usSignalAx,ax);
                    end

                    if app.aoData.uVars.figs.validStruct.meas
                        f = figure();
                        ax=axes(f);
                        copyUIAxes(app.measAx,ax);
                    end
                    if app.aoData.uVars.figs.validStruct.signal
                        f = figure();
                        ax=axes(f);
                        copyUIAxes(app.signalAx,ax);
                    end
                    if app.aoData.uVars.figs.validStruct.deMul 
                        f = figure();
                        ax=axes(f);
                        copyUIAxes(app.deMulAx,ax);
                    end
                    if app.aoData.uVars.figs.validStruct.reshaped 
                        f = figure();
                        ax=axes(f);
                        copyUIAxes(app.reshapedAx,ax);
                    end
                    
                    if app.aoData.uVars.figs.validStruct.rawFFT 
                        f = figure();
                        ax=axes(f);
                        copyUIAxes(app.rawFFTAx,ax);
                    end
                    if app.aoData.uVars.figs.validStruct.fittingModel 
                        f = figure();
                        ax=axes(f);
                        copyUIAxes(app.fittingModelAx,ax);
                    end
                    if app.aoData.uVars.figs.validStruct.fittedPowerFFT 
                        f = figure();
                        ax=axes(f);
                        copyUIAxes(app.fittedPowerFFTAx,ax);
                    end
                    if app.aoData.uVars.figs.validStruct.finalFFT 
                        f = figure();
                        ax=axes(f);
                        copyUIAxes(app.finalFFTAx, ax);
                    end                    
                    if app.aoData.uVars.figs.validStruct.phi
                        f = figure();
                        ax=axes(f);
                        copyUIAxes(app.phiAx,ax);
                    end
                    
                    if app.aoData.uVars.figs.validStruct.rawPhi
                        f = figure();
                        ax=axes(f);
                        copyUIAxes(app.rawPhiAx,ax);
                    end

                    if app.aoData.uVars.figs.validStruct.phiLog
                        f = figure();
                        ax=axes(f);
                        copyUIAxes(app.phiLogAx,ax);
                    end
                case 'Scan 2D'
                    f = figure();
                    ax=axes(f);
                    copyUIAxes(app.axScanPlane, ax);
                    
                    f = figure();
                    ax=axes(f);
                    copyUIAxes(app.axScanPlaneLog, ax);

                    f = figure();
                    ax=axes(f);
                    copyUIAxes(app.axNavLine, ax);
                    
                    f = figure();
                    ax=axes(f);
                    copyUIAxes(app.axNavLineLog, ax);

                    f = figure();
                    ax=axes(f);
                    copyUIAxes(app.axNavPlane, ax);
                    
                    f = figure();
                    ax=axes(f);
                    copyUIAxes(app.axNavPlaneLog, ax);

                case 'OptoAcoustics'
                    
                case 'Pulse-Echo US'
                    
                case 'IntSys'
            end
        end

        % Value changed function: dispFullFFTCheckBox
        function dispFullFFTCheckBoxValueChanged(app, event)
            value = app.dispFullFFTCheckBox.Value;
            if value
                app.FreqEnvEditField.Enable = false;
            else
                app.FreqEnvEditField.Enable = true;
            end
        end

        % Button pushed function: OARunScanButton
        function OARunScanButtonPushed(app, event)
            app.collectOAVars();
            app.oa.setVars(app.oaData.uVars);
            app.oa.configPeripherals();
            app.oaData.curVars = app.oa.getVars();
            app.OANavIdxSpinner.Enable = true;
            app.updateOANAvigatorSpinner();
            app.oaData.curRes    = app.oa.scan();
        end

        % Button pushed function: OARunSingleButton
        function OARunSingleButtonPushed(app, event)
            app.collectOAVars();
            app.oa.setVars(app.oaData.uVars);
            app.oa.configPeripherals();
            app.oaData.curVars = app.oa.getVars();
            app.OANavIdxSpinner.Enable = false;
            app.oaData.curRes    = app.oa.runOA();
        end

        % Button pushed function: OARunLiveButton
        function OARunLiveButtonPushed(app, event)
            app.collectOAVars();
            app.oa.setVars(app.oaData.uVars);
            app.oa.configPeripherals();
            app.oaData.curVars = app.oa.getVars();
            app.OANavIdxSpinner.Enable = false;
            app.oaData.curRes    = app.oa.runLiveOA();
        end

        % Value changed function: OANavIdxSpinner
        function OANavIdxSpinnerValueChanged(app, event)
            idx =  app.OANavIdxSpinner.Value;
            app.updateOANavigatorLabel();
            app.oaGraphics.updateNavigator(idx);
        end

        % Button pushed function: PEUSRunScanButton
        function PEUSRunScanButtonPushed(app, event)
            app.collectPEUSVars();
            app.peus.setVars(app.peusData.uVars);
            app.peus.configureScan();
            app.peusData.curVars = app.peus.getVars();
            app.peusData.curRes  = app.peus.scan();
        end

        % Value changed function: MeasLimitEditField
        function MeasLimitEditFieldValueChanged2(app, event)
%             value = app.MeasLimitEditField.Value;
%             app.ao.setMeasLimit(value);
        end

        % Button pushed function: RunIntImagingButton
        function RunIntImagingButtonPushed(app, event)
            app.collectIntSysVars();
            app.intSys.setUserVars(uVars);
            app.intSys.configureScan();
            app.intSysData.curVars = app.intSys.getVars();
            app.intSysData.curRes = app.intSys.scan();
        end

        % Button pushed function: ResetDaqButton
        function ResetDAQResetDaqButtonPushed(app, event)
            app.oa.daq.resetDAQ();
        end

        % Value changed function: SeparateChannelSpinner
        function SeparateChannelSpinnerValueChanged(app, event)
            app.updateAOFigs();
        end

        % Button pushed function: MonitorButton
        function MonitorButtonPushed(app, event)
            
        end

        % Selection change function: acoustoOpticsPlotsTab
        function acoustoOpticsPlotsTabSelectionChanged(app, event)
            selectedTab = app.acoustoOpticsPlotsTab.SelectedTab;
            if strcmp(selectedTab.Title, 'AO Vars')
                app.collectAlgoVars();
                app.algoData.curRes = app.algo.setVars(app.algoData.uVars);
                app.setAlgoValuesTable();
            end
        end

        % Button pushed function: SaveCurrentMeasButton
        function SaveCurrentMeasButtonPushed(app, event)
            fsVars = app.collectAOFileSystemVars();
            app.ao.saveCurrentMeasManual(fsVars);
        end

        % Callback function: calibrationDropDown, croppedDropDown, 
        % ...and 14 other components
        function ExternalClkDropDownValueChanged(app, event)
            app.arrangeAOFigures();
        end

        % Value changed function: navLineDropDown, navLineLogDropDown, 
        % ...and 4 other components
        function LineNavLogDropDownValueChanged(app, event)
           app.arrangeAOFigures();
        end

        % Button pushed function: ResetDigitizerButton
        function ResetDigitizerButtonPushed(app, event)
           app.ao.resetDigitizer(); 
        end

        % Button pushed function: TurnAFGOnButton
        function TurnAFGOnButtonPushed(app, event)
            app.ao.fGen.enableAllOutputs();
        end

        % Button pushed function: OpenStagesGUIButton
        function OpenStagesGUIButtonPushed(app, event)
            app.stgGUI = stagesGUI(app.aoStages);
        end

        % Button pushed function: PopTab1Button
        function PopTab1ButtonPushed(app, event)
            f = figure();
            ax=axes(f);
            curTab = app.acoustoOpticsPlotsTab.SelectedTab;
            if strcmp (curTab.Title, 'Time Table') || strcmp (curTab.Title, 'Algo Vars')
                return
            end
            child = curTab.Children;
            for i=1:length(child)
                type = child(i).Type;
                if strcmp(type, 'axes')
                    curAx = child(i);
                end
            end
            copyUIAxes(curAx, ax);
        end

        % Button pushed function: PopTab3Button
        function PopTab3ButtonPushed(app, event)
            f = figure();
            ax=axes(f);
            child = app.ScanPlotTab.SelectedTab.Children;
            for i=1:length(child)
                type = child(i).Type;
                if strcmp(type, 'axes')
                    curAx = child(i);
                end
            end
            copyUIAxes(curAx, ax);
        end

        % Button pushed function: PopTab2Button
        function PopTab2ButtonPushed(app, event)
            f = figure();
            ax=axes(f);
            child = app.PhiTabGroup.SelectedTab.Children;
            for i=1:length(child)
                type = child(i).Type;
                if strcmp(type, 'axes')
                    curAx = child(i);
                end
            end
            copyUIAxes(curAx, ax);
        end

        % Button pushed function: PopTab4Button
        function PopTab4ButtonPushed(app, event)
            f = figure();
            ax=axes(f);
            child = app.FFTTabGroup.SelectedTab.Children;
            for i=1:length(child)
                type = child(i).Type;
                if strcmp(type, 'axes')
                    curAx = child(i);
                end
            end
            copyUIAxes(curAx, ax);
        end

        % Value changed function: LineNavAx1DropDown
        function LineNavAx1DropDownValueChanged(app, event)
            value = event.Value;
            prevVal = event.PreviousValue;

            if strcmp (value, app.LineNavAx2Label.Text)
                app.LineNavAx2Label.Text = prevVal;
                app.updateLineNavAx2Component();
            elseif strcmp(value, app.LineNavAx3Label.Text)
                app.LineNavAx3Label.Text = prevVal;
                app.updateLineNavAx3Component();
            elseif strcmp(value, app.LineNavAx4Label.Text)
                app.LineNavAx4Label.Text = prevVal;
                app.updateLineNavAx4Component();
            else
                disp("Error with Chosing Line Axes");
            end

            app.updateLineNavigator();
        end

        % Value changed function: LineNavAx2Spinner
        function LineNavAx2SpinnerValueChanged(app, event)
            app.updateLineNavAx2Component();
            app.updateLineNavigator();
        end

        % Value changed function: LineNavAx3Spinner
        function LineNavAx3SpinnerValueChanged(app, event)
            app.updateLineNavAx3Component();
            app.updateLineNavigator();
        end

        % Value changed function: LineNavAx4Spinner
        function LineNavAx4SpinnerValueChanged(app, event)
            app.updateLineNavAx4Component();
            app.updateLineNavigator();
        end

        % Value changed function: PlaneNavAx1DropDown
        function PlaneNavAx1DropDownValueChanged(app, event)
            value = event.Value;
            prevVal = event.PreviousValue;

            if strcmp (value, app.PlaneNavAx2DropDown.Value)
                app.PlaneNavAx2DropDown.Value = prevVal;
            elseif strcmp(value, app.PlaneNavAx3Label.Text)
                app.PlaneeNavAx3Label.Text = prevVal;
                app.updatePlaneNavAx3Component();
            elseif strcmp(value, app.PlaneNavAx4Label.Text)
                app.PlaneNavAx4Label.Text = prevVal;
                app.updatePlaneNavAx4Component();
            else
                disp("Error with Chosing Line Axes");
            end

            app.updatePlaneNavigator();
        end

        % Value changed function: PlaneNavAx2DropDown
        function PlaneNavAx2DropDownValueChanged(app, event)
            value = event.Value;
            prevVal = event.PreviousValue;

            if strcmp (value, app.PlaneNavAx1DropDown.Value)
                app.PlaneNavAx1DropDown.Value = prevVal;
            elseif strcmp(value, app.PlaneNavAx3Label.Text)
                app.PlaneeNavAx3Label.Text = prevVal;
                app.updatePlaneNavAx3Component();
            elseif strcmp(value, app.PlaneNavAx4Label.Text)
                app.PlaneNavAx4Label.Text = prevVal;
                app.updatePlaneNavAx4Component();
            else
                disp("Error with Chosing Line Axes");
            end

            app.updatePlaneNavigator();
        end

        % Value changed function: PlaneNavAx3IdxSpinner
        function PlaneNavAx3IdxSpinnerValueChanged(app, event)
            app.updatePlaneNavAx3Component();
            app.updatePlaneNavigator();
        end

        % Value changed function: PlaneNavAx4IdxSpinner
        function PlaneNavAx4IdxSpinnerValueChanged(app, event)
            app.updatePlaneNavAx4Component();
            app.updatePlaneNavigator();
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.AOIUIFigure = uifigure('Visible', 'off');
            app.AOIUIFigure.Name = 'AOI';
            app.AOIUIFigure.CloseRequestFcn = createCallbackFcn(app, @AOIUIFigureCloseRequest, true);
            app.AOIUIFigure.SizeChangedFcn = createCallbackFcn(app, @AOIUIFigureSizeChanged, true);
            app.FileMenu = uimenu(app.AOIUIFigure);
            app.FileMenu.Text = 'File';
            app.LoadCondifurationsMenu = uimenu(app.FileMenu);
            app.LoadCondifurationsMenu.Text = 'Load Condifurations';
            app.SaveConfigurationsMenu = uimenu(app.FileMenu);
            app.SaveConfigurationsMenu.Text = 'Save Configurations';
            app.ToolsMenu = uimenu(app.AOIUIFigure);
            app.ToolsMenu.Text = 'Tools';
            app.DebugMenu = uimenu(app.ToolsMenu);
            app.DebugMenu.Text = 'Debug';
            app.DeactivatePeripheralsMenu = uimenu(app.DebugMenu);
            app.DeactivatePeripheralsMenu.Text = 'Deactivate Peripherals ';
            app.ExtraFiguresMenu = uimenu(app.DebugMenu);
            app.ExtraFiguresMenu.Text = 'Extra Figures';
            app.PMTSaturationInspector = uimenu(app.ToolsMenu);
            app.PMTSaturationInspector.MenuSelectedFcn = createCallbackFcn(app, @PMTSaturationInspectorMenuSelected, true);
            app.PMTSaturationInspector.Text = 'PMT Saturation Inspector';
            app.ScanAssistantMenu = uimenu(app.ToolsMenu);
            app.ScanAssistantMenu.MenuSelectedFcn = createCallbackFcn(app, @ScanAssistantMenuSelected, true);
            app.ScanAssistantMenu.Text = 'Scan Assistant';
            app.FindMyinstrumentPortMenu = uimenu(app.ToolsMenu);
            app.FindMyinstrumentPortMenu.Text = 'Find My instrument Port';
            app.InstrumentsMenu = uimenu(app.AOIUIFigure);
            app.InstrumentsMenu.Text = 'Instruments';
            app.StagesMenu = uimenu(app.InstrumentsMenu);
            app.StagesMenu.Text = 'Stages';
            app.ScanningAssistantMenu = uimenu(app.StagesMenu);
            app.ScanningAssistantMenu.Text = 'Scanning Assistant';
            app.AFGMenu = uimenu(app.InstrumentsMenu);
            app.AFGMenu.Text = 'AFG';
            app.OscilloscopeMenu = uimenu(app.InstrumentsMenu);
            app.OscilloscopeMenu.Text = 'Oscilloscope';
            app.ResetInstrumentsCommunicationMenu = uimenu(app.InstrumentsMenu);
            app.ResetInstrumentsCommunicationMenu.Text = 'Reset Instruments Communication';
            app.HelpMenu = uimenu(app.AOIUIFigure);
            app.HelpMenu.Text = 'Help';
            app.AboutMenu = uimenu(app.HelpMenu);
            app.AboutMenu.Text = 'About';
            app.AcoustoOpticSystemLabel = uilabel(app.AOIUIFigure);
            app.AcoustoOpticSystemLabel.Text = 'AcoustoOptic System';
            app.SimulationSavePanel = uipanel(app.AOIUIFigure);
            app.SimulationSavePanel.Title = 'Simulation Name';
            app.MeasNameLabel = uilabel(app.SimulationSavePanel);
            app.MeasNameLabel.Text = 'Meas. Name:';
            app.MeasNameEditField = uieditfield(app.SimulationSavePanel, 'text');
            app.MeasNameEditField.Value = 'Scan';
            app.PathLabel = uilabel(app.SimulationSavePanel);
            app.PathLabel.Text = 'Path:';
            app.PathEditField = uieditfield(app.SimulationSavePanel, 'text');
            app.PathEditField.Value = 'D:\Results';
            app.saveResultsandVarsCheckBox = uicheckbox(app.SimulationSavePanel);
            app.saveResultsandVarsCheckBox.Text = 'Save Results and Vars';
            app.saveFigsCheckBox = uicheckbox(app.SimulationSavePanel);
            app.saveFigsCheckBox.Text = 'Save Figs';
            app.saveRawDataCheckBox = uicheckbox(app.SimulationSavePanel);
            app.saveRawDataCheckBox.Text = 'Raw Data';
            app.saveNetSignalCheckBox = uicheckbox(app.SimulationSavePanel);
            app.saveNetSignalCheckBox.Text = 'Net Signal';
            app.saveDeMultiplexedCheckBox = uicheckbox(app.SimulationSavePanel);
            app.saveDeMultiplexedCheckBox.Text = 'DeMultiplexed';
            app.saveReshapedCheckBox = uicheckbox(app.SimulationSavePanel);
            app.saveReshapedCheckBox.Text = 'Reshaped';
            app.saveFFTCheckBox = uicheckbox(app.SimulationSavePanel);
            app.saveFFTCheckBox.Text = 'FFT';
            app.SavedDataSpecificationsLabel = uilabel(app.SimulationSavePanel);
            app.SavedDataSpecificationsLabel.Text = 'Saved Data Specifications:';
            app.saveUSCmplxCheckBox = uicheckbox(app.SimulationSavePanel);
            app.saveUSCmplxCheckBox.Text = 'US Cmplx.';
            app.dirBrowseButton = uibutton(app.SimulationSavePanel, 'push');
            app.dirBrowseButton.ButtonPushedFcn = createCallbackFcn(app, @dirBrowseButtonPushed, true);
            app.dirBrowseButton.Text = '...';
            app.SaveCurrentMeasButton = uibutton(app.SimulationSavePanel, 'push');
            app.SaveCurrentMeasButton.ButtonPushedFcn = createCallbackFcn(app, @SaveCurrentMeasButtonPushed, true);
            app.SaveCurrentMeasButton.Text = 'Save Current Meas';
            app.SaveAOCheckBox = uicheckbox(app.SimulationSavePanel);
            app.SaveAOCheckBox.Text = 'Save AO';
            app.MeasMethod = uitabgroup(app.AOIUIFigure);
            app.MeasMethod.SelectionChangedFcn = createCallbackFcn(app, @MeasMethodSelectionChanged, true);
            app.SingleMeasurementTab = uitab(app.MeasMethod);
            app.SingleMeasurementTab.Title = 'Single Measurement';
            app.AORunButton = uibutton(app.SingleMeasurementTab, 'push');
            app.AORunButton.ButtonPushedFcn = createCallbackFcn(app, @AORunButtonPushed, true);
            app.AORunButton.Text = 'Run';
            app.AOPositionPanel = uipanel(app.SingleMeasurementTab);
            app.AOPositionPanel.Title = 'Position';
            app.mmLabel_17 = uilabel(app.AOPositionPanel);
            app.mmLabel_17.Text = '[mm]';
            app.mmLabel_18 = uilabel(app.AOPositionPanel);
            app.mmLabel_18.Text = '[mm]';
            app.XpositionEditField_2Label = uilabel(app.AOPositionPanel);
            app.XpositionEditField_2Label.Enable = 'off';
            app.XpositionEditField_2Label.Text = 'X position';
            app.AOXpositionEditField = uieditfield(app.AOPositionPanel, 'numeric');
            app.AOXpositionEditField.Enable = 'off';
            app.AOXpositionEditField.Value = 100;
            app.YpositionEditField_2Label = uilabel(app.AOPositionPanel);
            app.YpositionEditField_2Label.Enable = 'off';
            app.YpositionEditField_2Label.Text = 'Y position';
            app.AOYpositionEditField = uieditfield(app.AOPositionPanel, 'numeric');
            app.AOYpositionEditField.Enable = 'off';
            app.AOYpositionEditField.Value = 86.5;
            app.AOMeasHereCheckBox = uicheckbox(app.AOPositionPanel);
            app.AOMeasHereCheckBox.ValueChangedFcn = createCallbackFcn(app, @AOMeasHereCheckBoxValueChanged, true);
            app.AOMeasHereCheckBox.Text = 'Dont Move';
            app.AOMeasHereCheckBox.Value = true;
            app.LiveAcoustoOpticsPanel = uipanel(app.SingleMeasurementTab);
            app.LiveAcoustoOpticsPanel.Title = 'Live Acousto Optics';
            app.LimitLiveAOCheckBox = uicheckbox(app.LiveAcoustoOpticsPanel);
            app.LimitLiveAOCheckBox.ValueChangedFcn = createCallbackFcn(app, @LimitLiveAOCheckBoxValueChanged, true);
            app.LimitLiveAOCheckBox.Text = 'LimitLiveAO';
            app.LimitLiveAOCheckBox.Value = true;
            app.NEditFieldLabel = uilabel(app.LiveAcoustoOpticsPanel);
            app.NEditFieldLabel.Text = 'N';
            app.NEditField = uieditfield(app.LiveAcoustoOpticsPanel, 'numeric');
            app.NEditField.Limits = [1 Inf];
            app.NEditField.Value = 1;
            app.AOLiveRunButton = uibutton(app.LiveAcoustoOpticsPanel, 'push');
            app.AOLiveRunButton.ButtonPushedFcn = createCallbackFcn(app, @AOLiveRunButtonPushed, true);
            app.AOLiveRunButton.Text = 'Run Live';
            app.ImportResultsPanel = uipanel(app.SingleMeasurementTab);
            app.ImportResultsPanel.Title = 'Import Results';
            app.PathEditField_2Label = uilabel(app.ImportResultsPanel);
            app.PathEditField_2Label.Text = 'Path';
            app.PathEditField_2 = uieditfield(app.ImportResultsPanel, 'text');
            app.PathEditField_2.Editable = 'off';
            app.analysisDataPathButton = uibutton(app.ImportResultsPanel, 'push');
            app.analysisDataPathButton.ButtonPushedFcn = createCallbackFcn(app, @analysisDataPathButtonPushed, true);
            app.analysisDataPathButton.Text = '...';
            app.ImportButton = uibutton(app.ImportResultsPanel, 'push');
            app.ImportButton.ButtonPushedFcn = createCallbackFcn(app, @ImportAOResultstButtonPushed, true);
            app.ImportButton.Text = 'Import';
            app.LoadRawDataCheckBox = uicheckbox(app.ImportResultsPanel);
            app.LoadRawDataCheckBox.Text = 'Load Raw Data';
            app.TopUpButton = uibutton(app.SingleMeasurementTab, 'push');
            app.TopUpButton.ButtonPushedFcn = createCallbackFcn(app, @TopUpButtonPushed, true);
            app.TopUpButton.Text = 'Top-Up';
            app.ResetRunButton = uibutton(app.SingleMeasurementTab, 'push');
            app.ResetRunButton.ButtonPushedFcn = createCallbackFcn(app, @ResetRunButtonPushed, true);
            app.ResetRunButton.Text = 'Reset Run';
            app.PauseButton = uibutton(app.SingleMeasurementTab, 'push');
            app.PauseButton.ButtonPushedFcn = createCallbackFcn(app, @PauseButtonPushed, true);
            app.PauseButton.Text = 'Pause';
            app.AOScanTab = uitab(app.MeasMethod);
            app.AOScanTab.Title = 'AO Scan';
            app.AOScanPanel = uipanel(app.AOScanTab);
            app.AOScanPanel.Title = 'Scan Grid';
            app.AOScanAx1StartmmLabel = uilabel(app.AOScanPanel);
            app.AOScanAx1StartmmLabel.Text = '[mm]';
            app.AOScanAx1StridemmLabel = uilabel(app.AOScanPanel);
            app.AOScanAx1StridemmLabel.Text = '[mm]';
            app.AOScanAx1EndmmLabel = uilabel(app.AOScanPanel);
            app.AOScanAx1EndmmLabel.Text = '[mm]';
            app.AOScanAx2EndmmLabel = uilabel(app.AOScanPanel);
            app.AOScanAx2EndmmLabel.Text = '[mm]';
            app.AOScanAx2StridemmLabel = uilabel(app.AOScanPanel);
            app.AOScanAx2StridemmLabel.Text = '[mm]';
            app.StartEditFieldLabel = uilabel(app.AOScanPanel);
            app.StartEditFieldLabel.Text = 'Start';
            app.AOScan1StartEditField = uieditfield(app.AOScanPanel, 'numeric');
            app.AOScan1StartEditField.Value = 59.3256;
            app.EndEditFieldLabel = uilabel(app.AOScanPanel);
            app.EndEditFieldLabel.Text = 'End';
            app.AOScan1EndEditField = uieditfield(app.AOScanPanel, 'numeric');
            app.AOScan1EndEditField.Value = 68.1456;
            app.StartEditField_2Label = uilabel(app.AOScanPanel);
            app.StartEditField_2Label.Text = 'Start';
            app.AOScan2StartEditField = uieditfield(app.AOScanPanel, 'numeric');
            app.AOScan2StartEditField.Value = 62.38;
            app.EndEditField_2Label = uilabel(app.AOScanPanel);
            app.EndEditField_2Label.Text = 'End';
            app.AOScan2EndEditField = uieditfield(app.AOScanPanel, 'numeric');
            app.StrideEditFieldLabel = uilabel(app.AOScanPanel);
            app.StrideEditFieldLabel.Text = 'Stride';
            app.AOScan1StrideEditField = uieditfield(app.AOScanPanel, 'numeric');
            app.AOScan1StrideEditField.Value = 0.3372;
            app.StrideEditField_2Label = uilabel(app.AOScanPanel);
            app.StrideEditField_2Label.Text = 'Stride';
            app.AOScan2StrideEditField = uieditfield(app.AOScanPanel, 'numeric');
            app.AOScan1AxisLabel = uilabel(app.AOScanPanel);
            app.AOScan1AxisLabel.Text = 'Scan 1 Axis:';
            app.AOScan1AxisDropDown = uidropdown(app.AOScanPanel);
            app.AOScan1AxisDropDown.Items = {'Y', 'X', 'Z'};
            app.AOScan1AxisDropDown.ValueChangedFcn = createCallbackFcn(app, @AOScan1AxisDropDownValueChanged, true);
            app.AOScan1AxisDropDown.Value = 'X';
            app.AOScan2AxisLabel = uilabel(app.AOScanPanel);
            app.AOScan2AxisLabel.Text = 'Scan 2 Axis:';
            app.AOScan2AxisDropDown = uidropdown(app.AOScanPanel);
            app.AOScan2AxisDropDown.Items = {'Y', 'X', 'Z'};
            app.AOScan2AxisDropDown.ValueChangedFcn = createCallbackFcn(app, @AOScan2AxisDropDownValueChanged, true);
            app.AOScan2AxisDropDown.Value = 'Y';
            app.AOScan3AxisLabel = uilabel(app.AOScanPanel);
            app.AOScan3AxisLabel.Text = 'Scan 3 Axis:';
            app.AOScanAx2StartmmLabel = uilabel(app.AOScanPanel);
            app.AOScanAx2StartmmLabel.Text = '[mm]';
            app.DontMoveNonScanninStages = uicheckbox(app.AOScanPanel);
            app.DontMoveNonScanninStages.Text = 'Dont move non-scanning stages';
            app.ScanTypeDropDownLabel = uilabel(app.AOScanPanel);
            app.ScanTypeDropDownLabel.Text = 'Scan Type';
            app.ScanTypeDropDown = uidropdown(app.AOScanPanel);
            app.ScanTypeDropDown.Items = {'Uni', 'Bi'};
            app.ScanTypeDropDown.Value = 'Uni';
            app.KeppUSONCheckBox = uicheckbox(app.AOScanPanel);
            app.KeppUSONCheckBox.Text = 'Kepp US ON';
            app.AOScanAx3StridemmLabel = uilabel(app.AOScanPanel);
            app.AOScanAx3StridemmLabel.Text = '[mm]';
            app.StrideEditField_2Label_3 = uilabel(app.AOScanPanel);
            app.StrideEditField_2Label_3.Text = 'Stride';
            app.AOScan3StrideEditField = uieditfield(app.AOScanPanel, 'numeric');
            app.AOScanAx3StartmmLabel = uilabel(app.AOScanPanel);
            app.AOScanAx3StartmmLabel.Text = '[mm]';
            app.StartEditField_2Label_3 = uilabel(app.AOScanPanel);
            app.StartEditField_2Label_3.Text = 'Start';
            app.AOScan3StartEditField = uieditfield(app.AOScanPanel, 'numeric');
            app.AOScan3StartEditField.Value = 111.65;
            app.AOScanAx3EndmmLabel = uilabel(app.AOScanPanel);
            app.AOScanAx3EndmmLabel.Text = '[mm]';
            app.EndEditField_2Label_3 = uilabel(app.AOScanPanel);
            app.EndEditField_2Label_3.Text = 'End';
            app.AOScan3EndEditField = uieditfield(app.AOScanPanel, 'numeric');
            app.AOSAxisScan3Label = uilabel(app.AOScanPanel);
            app.AOSAxisScan3Label.Text = 'Z';
            app.AOScanRunButton = uibutton(app.AOScanTab, 'push');
            app.AOScanRunButton.ButtonPushedFcn = createCallbackFcn(app, @AOScanRunButtonPushed, true);
            app.AOScanRunButton.Text = 'Run';
            app.AOScanImportResultsButton = uibutton(app.AOScanTab, 'push');
            app.AOScanImportResultsButton.ButtonPushedFcn = createCallbackFcn(app, @ImportAOScanResultsButtonPushed, true);
            app.AOScanImportResultsButton.Text = 'Import Scan Data';
            app.AOScanExportScanVarsButton = uibutton(app.AOScanTab, 'push');
            app.AOScanExportScanVarsButton.ButtonPushedFcn = createCallbackFcn(app, @AOScanExportScanVarsButtonPushed, true);
            app.AOScanExportScanVarsButton.Text = 'Export Scan Vars';
            app.AOScanImportScanVarsButton = uibutton(app.AOScanTab, 'push');
            app.AOScanImportScanVarsButton.ButtonPushedFcn = createCallbackFcn(app, @AOScanImportScanVarsButtonPushed, true);
            app.AOScanImportScanVarsButton.Text = 'Import Scan Vars';
            app.AOScanImportLimitsfromfileButton = uibutton(app.AOScanTab, 'push');
            app.AOScanImportLimitsfromfileButton.ButtonPushedFcn = createCallbackFcn(app, @AOScanImportLimitsfromfileButtonPushed, true);
            app.AOScanImportLimitsfromfileButton.Text = 'Import Limits from file';
            app.StopButton = uibutton(app.AOScanTab, 'push');
            app.StopButton.ButtonPushedFcn = createCallbackFcn(app, @StopButtonPushed, true);
            app.StopButton.Text = 'Stop';
            app.OptoAcousticsTab = uitab(app.MeasMethod);
            app.OptoAcousticsTab.Title = 'OptoAcoustics';
            app.OARunScanButton = uibutton(app.OptoAcousticsTab, 'push');
            app.OARunScanButton.ButtonPushedFcn = createCallbackFcn(app, @OARunScanButtonPushed, true);
            app.OARunScanButton.Text = 'Run Scan';
            app.OARunSingleButton = uibutton(app.OptoAcousticsTab, 'push');
            app.OARunSingleButton.ButtonPushedFcn = createCallbackFcn(app, @OARunSingleButtonPushed, true);
            app.OARunSingleButton.Text = 'Run Single';
            app.StartSinglePosEditFieldLabel = uilabel(app.OptoAcousticsTab);
            app.StartSinglePosEditFieldLabel.Text = 'Start\Single Pos';
            app.StartSinglePosEditField = uieditfield(app.OptoAcousticsTab, 'numeric');
            app.StartSinglePosEditField.Value = 190;
            app.StrideEditFieldLabel_5 = uilabel(app.OptoAcousticsTab);
            app.StrideEditFieldLabel_5.Text = 'Stride';
            app.StrideEditField = uieditfield(app.OptoAcousticsTab, 'numeric');
            app.StrideEditField.Value = 1;
            app.EndEditFieldLabel_5 = uilabel(app.OptoAcousticsTab);
            app.EndEditFieldLabel_5.Text = 'End';
            app.EndEditField = uieditfield(app.OptoAcousticsTab, 'numeric');
            app.EndEditField.Value = 200;
            app.FirstAxisEditFieldLabel = uilabel(app.OptoAcousticsTab);
            app.FirstAxisEditFieldLabel.Text = 'First Axis';
            app.FirstAxisEditField = uieditfield(app.OptoAcousticsTab, 'text');
            app.FirstAxisEditField.Value = 'X';
            app.SecondAxisEditFieldLabel = uilabel(app.OptoAcousticsTab);
            app.SecondAxisEditFieldLabel.Text = 'Second Axis';
            app.SecondAxisEditField = uieditfield(app.OptoAcousticsTab, 'text');
            app.SecondAxisEditField.Value = 'Y';
            app.ScanAxisEditFieldLabel = uilabel(app.OptoAcousticsTab);
            app.ScanAxisEditFieldLabel.Text = 'Scan Axis';
            app.ScanAxisEditField = uieditfield(app.OptoAcousticsTab, 'text');
            app.ScanAxisEditField.Value = 'Z';
            app.OARunLiveButton = uibutton(app.OptoAcousticsTab, 'push');
            app.OARunLiveButton.ButtonPushedFcn = createCallbackFcn(app, @OARunLiveButtonPushed, true);
            app.OARunLiveButton.Text = 'Run Live';
            app.OAScanStartmmLabel = uilabel(app.OptoAcousticsTab);
            app.OAScanStartmmLabel.Text = '[mm]';
            app.OAScanStridemmLabel = uilabel(app.OptoAcousticsTab);
            app.OAScanStridemmLabel.Text = '[mm]';
            app.OAScanEndmmLabel = uilabel(app.OptoAcousticsTab);
            app.OAScanEndmmLabel.Text = '[mm]';
            app.PulseEchoUSTab = uitab(app.MeasMethod);
            app.PulseEchoUSTab.Title = 'Pulse-Echo US';
            app.DistancefromImageCenterLabel = uilabel(app.PulseEchoUSTab);
            app.DistancefromImageCenterLabel.Text = {'Distance from'; 'Image Center'};
            app.PEUSDistancefromImageCenterEditField = uieditfield(app.PulseEchoUSTab, 'numeric');
            app.PEUSDistancefromImageCenterEditField.Value = 9.6;
            app.ImageWidthEditField_2Label = uilabel(app.PulseEchoUSTab);
            app.ImageWidthEditField_2Label.Text = 'Image Width';
            app.PEUSImageWidthEditField = uieditfield(app.PulseEchoUSTab, 'numeric');
            app.PEUSImageWidthEditField.Value = 30;
            app.cmLabel_4 = uilabel(app.PulseEchoUSTab);
            app.cmLabel_4.Text = '[cm]';
            app.mmLabel_50 = uilabel(app.PulseEchoUSTab);
            app.mmLabel_50.Text = '[mm]';
            app.StartEditField_2Label_2 = uilabel(app.PulseEchoUSTab);
            app.StartEditField_2Label_2.Text = 'Start';
            app.PEUSStartEditField = uieditfield(app.PulseEchoUSTab, 'numeric');
            app.PEUSStartEditField.Value = 65;
            app.StrideEditField_2Label_2 = uilabel(app.PulseEchoUSTab);
            app.StrideEditField_2Label_2.Text = 'Stride';
            app.PEUSStrideEditField = uieditfield(app.PulseEchoUSTab, 'numeric');
            app.PEUSStrideEditField.Value = 1;
            app.EndEditField_2Label_2 = uilabel(app.PulseEchoUSTab);
            app.EndEditField_2Label_2.Text = 'End';
            app.PEUSEndEditField = uieditfield(app.PulseEchoUSTab, 'numeric');
            app.PEUSEndEditField.Value = 95;
            app.ScanAxisEditField_3Label = uilabel(app.PulseEchoUSTab);
            app.ScanAxisEditField_3Label.Text = 'Scan Axis';
            app.PEUSScanAxisEditField = uieditfield(app.PulseEchoUSTab, 'text');
            app.PEUSScanAxisEditField.Value = 'X';
            app.DepthAxisEditFieldLabel = uilabel(app.PulseEchoUSTab);
            app.DepthAxisEditFieldLabel.Text = 'Depth Axis';
            app.PEUSDepthAxisEditField = uieditfield(app.PulseEchoUSTab, 'text');
            app.PEUSDepthAxisEditField.Value = 'Y';
            app.ThirdsAxisEditFieldLabel = uilabel(app.PulseEchoUSTab);
            app.ThirdsAxisEditFieldLabel.Text = 'Thirds Axis';
            app.PEUSThirdsAxisEditField = uieditfield(app.PulseEchoUSTab, 'text');
            app.PEUSThirdsAxisEditField.Value = 'Z';
            app.mmLabel_43 = uilabel(app.PulseEchoUSTab);
            app.mmLabel_43.Text = '[mm]';
            app.mmLabel_44 = uilabel(app.PulseEchoUSTab);
            app.mmLabel_44.Text = '[mm]';
            app.mmLabel_45 = uilabel(app.PulseEchoUSTab);
            app.mmLabel_45.Text = '[mm]';
            app.PEUSRunScanButton = uibutton(app.PulseEchoUSTab, 'push');
            app.PEUSRunScanButton.ButtonPushedFcn = createCallbackFcn(app, @PEUSRunScanButtonPushed, true);
            app.PEUSRunScanButton.Text = 'Run Scan';
            app.PEUSDontmovesecondscanaxisCheckBox = uicheckbox(app.PulseEchoUSTab);
            app.PEUSDontmovesecondscanaxisCheckBox.Text = 'Dont move second scan axis';
            app.IntSystemTab = uitab(app.MeasMethod);
            app.IntSystemTab.Title = 'Int System';
            app.AOScanAxisEditFieldLabel = uilabel(app.IntSystemTab);
            app.AOScanAxisEditFieldLabel.Text = 'AO Scan Axis';
            app.AOScanAxisEditField = uieditfield(app.IntSystemTab, 'text');
            app.AOScanAxisEditField.Value = 'X';
            app.AODepthAxisEditFieldLabel = uilabel(app.IntSystemTab);
            app.AODepthAxisEditFieldLabel.Text = 'AO Depth Axis';
            app.AODepthAxisEditField = uieditfield(app.IntSystemTab, 'text');
            app.AODepthAxisEditField.Value = 'Y';
            app.OAScanAxisEditFieldLabel = uilabel(app.IntSystemTab);
            app.OAScanAxisEditFieldLabel.Text = 'OA Scan Axis';
            app.OAScanAxisEditField = uieditfield(app.IntSystemTab, 'text');
            app.OAScanAxisEditField.Value = 'Z';
            app.AOScanStartEditFieldLabel = uilabel(app.IntSystemTab);
            app.AOScanStartEditFieldLabel.Text = 'AO Scan Start';
            app.AOScanStartEditField = uieditfield(app.IntSystemTab, 'numeric');
            app.AOScanStrideEditFieldLabel = uilabel(app.IntSystemTab);
            app.AOScanStrideEditFieldLabel.Text = 'AO Scan Stride';
            app.AOScanStrideEditField = uieditfield(app.IntSystemTab, 'numeric');
            app.AOScanEndEditFieldLabel = uilabel(app.IntSystemTab);
            app.AOScanEndEditFieldLabel.Text = 'AO Scan End';
            app.AOScanEndEditField = uieditfield(app.IntSystemTab, 'numeric');
            app.OAScanStartEditFieldLabel = uilabel(app.IntSystemTab);
            app.OAScanStartEditFieldLabel.Text = 'OA Scan Start';
            app.OAScanStartEditField = uieditfield(app.IntSystemTab, 'numeric');
            app.OAScanStrideEditFieldLabel = uilabel(app.IntSystemTab);
            app.OAScanStrideEditFieldLabel.Text = 'OA Scan Stride';
            app.OAScanStrideEditField = uieditfield(app.IntSystemTab, 'numeric');
            app.OAScanEndEditFieldLabel = uilabel(app.IntSystemTab);
            app.OAScanEndEditFieldLabel.Text = 'OA Scan End';
            app.OAScanEndEditField = uieditfield(app.IntSystemTab, 'numeric');
            app.ImageWidthEditField_2Label_2 = uilabel(app.IntSystemTab);
            app.ImageWidthEditField_2Label_2.Text = 'Image Width';
            app.IntSysImageWidthEditField = uieditfield(app.IntSystemTab, 'numeric');
            app.DistanceFromImageCenterLabel = uilabel(app.IntSystemTab);
            app.DistanceFromImageCenterLabel.Text = {'Distance From'; 'Image Center'};
            app.IntSysDistanceFromImageCenterEditField = uieditfield(app.IntSystemTab, 'numeric');
            app.cmLabel_6 = uilabel(app.IntSystemTab);
            app.cmLabel_6.Text = '[cm]';
            app.cmLabel_7 = uilabel(app.IntSystemTab);
            app.cmLabel_7.Text = '[cm]';
            app.RunIntImagingButton = uibutton(app.IntSystemTab, 'push');
            app.RunIntImagingButton.ButtonPushedFcn = createCallbackFcn(app, @RunIntImagingButtonPushed, true);
            app.RunIntImagingButton.Text = 'Run Int Imaging';
            app.GlobalParameters = uitabgroup(app.AOIUIFigure);
            app.GlobalParameters.SelectionChangedFcn = createCallbackFcn(app, @GlobalParametersSelectionChanged, true);
            app.SignalExcitationTab = uitab(app.GlobalParameters);
            app.SignalExcitationTab.Title = 'Signal Excitation';
            app.SamplingPanel = uipanel(app.SignalExcitationTab);
            app.SamplingPanel.Title = 'Sampling';
            app.AOSamplingRateLabel = uilabel(app.SamplingPanel);
            app.AOSamplingRateLabel.Text = '[MHz]';
            app.FrequencyEditFieldLabel = uilabel(app.SamplingPanel);
            app.FrequencyEditFieldLabel.Text = 'Frequency';
            app.FrequencyEditField = uieditfield(app.SamplingPanel, 'numeric');
            app.FrequencyEditField.ValueChangedFcn = createCallbackFcn(app, @FrequencyEditFieldValueChanged, true);
            app.FrequencyEditField.Value = 20;
            app.AOTimeToSampleLabel = uilabel(app.SamplingPanel);
            app.AOTimeToSampleLabel.Text = '[sec]';
            app.TimetosampleEditFieldLabel = uilabel(app.SamplingPanel);
            app.TimetosampleEditFieldLabel.Text = 'Time to sample';
            app.TimetosampleEditField = uieditfield(app.SamplingPanel, 'numeric');
            app.TimetosampleEditField.Value = 1;
            app.AOFrameTimeLabel = uilabel(app.SamplingPanel);
            app.AOFrameTimeLabel.Text = '[sec]';
            app.FrameTimeEditFieldLabel = uilabel(app.SamplingPanel);
            app.FrameTimeEditFieldLabel.Text = 'Frame Time';
            app.FrameTimeEditField = uieditfield(app.SamplingPanel, 'numeric');
            app.FrameTimeEditField.Value = 0.05;
            app.AOMeasLimitLabel = uilabel(app.SamplingPanel);
            app.AOMeasLimitLabel.Text = '[sec]';
            app.MeasLimitEditFieldLabel = uilabel(app.SamplingPanel);
            app.MeasLimitEditFieldLabel.Text = 'Meas. Limit';
            app.MeasLimitEditField = uieditfield(app.SamplingPanel, 'numeric');
            app.MeasLimitEditField.ValueChangedFcn = createCallbackFcn(app, @MeasLimitEditFieldValueChanged2, true);
            app.MeasLimitEditField.Value = 1;
            app.AOChNumLabel = uilabel(app.SamplingPanel);
            app.AOChNumLabel.Text = '[#]';
            app.NoOfChannelsEditFieldLabel = uilabel(app.SamplingPanel);
            app.NoOfChannelsEditFieldLabel.Text = 'No. Of Channels';
            app.NoOfChannelsEditField = uieditfield(app.SamplingPanel, 'numeric');
            app.NoOfChannelsEditField.Value = 1;
            app.UltrasoundSignalPropertiesPanel = uipanel(app.SignalExcitationTab);
            app.UltrasoundSignalPropertiesPanel.Title = 'Ultrasound Signal Properties';
            app.MHzLabel = uilabel(app.UltrasoundSignalPropertiesPanel);
            app.MHzLabel.Text = '[MHz]';
            app.Label_13 = uilabel(app.UltrasoundSignalPropertiesPanel);
            app.Label_13.Text = '[#]';
            app.KHzLabel_3 = uilabel(app.UltrasoundSignalPropertiesPanel);
            app.KHzLabel_3.Text = '[KHz]';
            app.SineFreqEditFieldLabel = uilabel(app.UltrasoundSignalPropertiesPanel);
            app.SineFreqEditFieldLabel.Text = 'Sine Freq.';
            app.SineFreqEditField = uieditfield(app.UltrasoundSignalPropertiesPanel, 'numeric');
            app.SineFreqEditField.ValueChangedFcn = createCallbackFcn(app, @SineFreqEditFieldValueChanged, true);
            app.SineFreqEditField.Value = 1.25;
            app.SquenceFreqEditFieldLabel = uilabel(app.UltrasoundSignalPropertiesPanel);
            app.SquenceFreqEditFieldLabel.Text = 'Squence Freq.';
            app.SquenceFreqEditField = uieditfield(app.UltrasoundSignalPropertiesPanel, 'numeric');
            app.SquenceFreqEditField.ValueChangedFcn = createCallbackFcn(app, @SquenceFreqEditFieldValueChanged, true);
            app.SquenceFreqEditField.Value = 5;
            app.CyclesPerPulseEditFieldLabel = uilabel(app.UltrasoundSignalPropertiesPanel);
            app.CyclesPerPulseEditFieldLabel.Text = 'Cycles Per Pulse';
            app.CyclesPerPulseEditField = uieditfield(app.UltrasoundSignalPropertiesPanel, 'numeric');
            app.CyclesPerPulseEditField.ValueChangedFcn = createCallbackFcn(app, @CyclesPerPulseEditFieldValueChanged, true);
            app.CyclesPerPulseEditField.Value = 1;
            app.Label_19 = uilabel(app.UltrasoundSignalPropertiesPanel);
            app.Label_19.Text = '[%]';
            app.USPowerEditFieldLabel = uilabel(app.UltrasoundSignalPropertiesPanel);
            app.USPowerEditFieldLabel.Text = 'US Power';
            app.USPowerEditField = uieditfield(app.UltrasoundSignalPropertiesPanel, 'numeric');
            app.USPowerEditField.Limits = [0 500];
            app.USPowerEditField.Value = 100;
            app.GeometryPanel = uipanel(app.SignalExcitationTab);
            app.GeometryPanel.Title = 'Geometry';
            app.TransducerdistanceLabel = uilabel(app.GeometryPanel);
            app.TransducerdistanceLabel.Text = 'Trans. Dist.';
            app.TransDistEditField = uieditfield(app.GeometryPanel, 'numeric');
            app.msLabel = uilabel(app.GeometryPanel);
            app.msLabel.Text = '[m/s]';
            app.SpeedofSoundEditFieldLabel = uilabel(app.GeometryPanel);
            app.SpeedofSoundEditFieldLabel.Text = 'Speed of Sound';
            app.SpeedofSoundEditField = uieditfield(app.GeometryPanel, 'numeric');
            app.SpeedofSoundEditField.ValueChangedFcn = createCallbackFcn(app, @SpeedofSoundEditFieldValueChanged, true);
            app.SpeedofSoundEditField.Value = 1500;
            app.cmLabel_3 = uilabel(app.GeometryPanel);
            app.cmLabel_3.Text = '[cm]';
            app.GeneralOperationPanel = uipanel(app.SignalExcitationTab);
            app.GeneralOperationPanel.Title = 'General Operation';
            app.UseTimeFrameCheckBox = uicheckbox(app.GeneralOperationPanel);
            app.UseTimeFrameCheckBox.Text = 'Use Time Frame';
            app.UseTimeFrameCheckBox.Value = true;
            app.HadamardCodeCheckBox = uicheckbox(app.GeneralOperationPanel);
            app.HadamardCodeCheckBox.Text = 'Hadamard Code';
            app.HadamardCodeCheckBox.Value = true;
            app.SeparateChCheckBox = uicheckbox(app.GeneralOperationPanel);
            app.SeparateChCheckBox.Text = 'Separate Ch. ';
            app.UseGPUCheckBox = uicheckbox(app.GeneralOperationPanel);
            app.UseGPUCheckBox.Text = 'Use GPU';
            app.UseGPUCheckBox.Value = true;
            app.ACCouplingCheckBox = uicheckbox(app.GeneralOperationPanel);
            app.ACCouplingCheckBox.Text = 'AC Coupling';
            app.ACCouplingCheckBox.Value = true;
            app.DisplayBuildUpCheckBox = uicheckbox(app.GeneralOperationPanel);
            app.DisplayBuildUpCheckBox.Text = 'Display Build-Up';
            app.DisplayBuildUpCheckBox.Value = true;
            app.DispEveryEditFieldLabel = uilabel(app.GeneralOperationPanel);
            app.DispEveryEditFieldLabel.Text = 'Disp. Every';
            app.DispEveryEditField = uieditfield(app.GeneralOperationPanel, 'numeric');
            app.DispEveryEditField.Value = 16;
            app.UseCalibrationCheckBox = uicheckbox(app.GeneralOperationPanel);
            app.UseCalibrationCheckBox.Text = 'Use Calibration';
            app.KeepSplitsCheckBox = uicheckbox(app.GeneralOperationPanel);
            app.KeepSplitsCheckBox.Text = 'Keep Splits';
            app.ExternalIOCtrlCheckBox = uicheckbox(app.GeneralOperationPanel);
            app.ExternalIOCtrlCheckBox.Text = 'External IO Ctrl';
            app.FittingEnvironmentsPanel = uipanel(app.SignalExcitationTab);
            app.FittingEnvironmentsPanel.Title = 'Fitting Environments';
            app.DCEditFieldLabel = uilabel(app.FittingEnvironmentsPanel);
            app.DCEditFieldLabel.Text = 'DC';
            app.envDC = uieditfield(app.FittingEnvironmentsPanel, 'numeric');
            app.envDC.Value = 100;
            app.USEditFieldLabel = uilabel(app.FittingEnvironmentsPanel);
            app.USEditFieldLabel.Text = 'US';
            app.envUS = uieditfield(app.FittingEnvironmentsPanel, 'numeric');
            app.envUS.Value = 50;
            app.KHzLabel_6 = uilabel(app.FittingEnvironmentsPanel);
            app.KHzLabel_6.Text = '[KHz]';
            app.KHzLabel_7 = uilabel(app.FittingEnvironmentsPanel);
            app.KHzLabel_7.Text = '[KHz]';
            app.QuickPlotControlPanel = uipanel(app.SignalExcitationTab);
            app.QuickPlotControlPanel.Title = 'Quick Plot Control';
            app.SignalChannelSpinnerLabel = uilabel(app.QuickPlotControlPanel);
            app.SignalChannelSpinnerLabel.Text = 'Signal Channel';
            app.SignalChannelSpinner = uispinner(app.QuickPlotControlPanel);
            app.SignalChannelSpinner.ValueChangedFcn = createCallbackFcn(app, @ChannelAOSpinnerValueChanged, true);
            app.SignalChannelSpinner.Value = 1;
            app.FrameLabel = uilabel(app.QuickPlotControlPanel);
            app.FrameLabel.Text = 'Frame';
            app.FrameAOSpinner = uispinner(app.QuickPlotControlPanel);
            app.FrameAOSpinner.ValueChangedFcn = createCallbackFcn(app, @FrameAOSpinnerValueChanged, true);
            app.FrameAOSpinner.Value = 1;
            app.SeparateChannelSpinnerLabel = uilabel(app.QuickPlotControlPanel);
            app.SeparateChannelSpinnerLabel.Text = 'Separate Channel';
            app.SeparateChannelSpinner = uispinner(app.QuickPlotControlPanel);
            app.SeparateChannelSpinner.ValueChangedFcn = createCallbackFcn(app, @SeparateChannelSpinnerValueChanged, true);
            app.SeparateChannelSpinner.Value = 1;
            app.PlotPosmmLabel = uilabel(app.QuickPlotControlPanel);
            app.PlotPosmmLabel.Text = '00.00[mm]';
            app.DepthCoorLabel = uilabel(app.QuickPlotControlPanel);
            app.DepthCoorLabel.Text = 'Depth Coor';
            app.AODepthIdxSpinner = uispinner(app.QuickPlotControlPanel);
            app.AODepthIdxSpinner.ValueChangedFcn = createCallbackFcn(app, @AODepthIdxSpinnerValueChanged, true);
            app.AODepthIdxSpinner.Value = 137;
            app.DepthLenLabel = uilabel(app.QuickPlotControlPanel);
            app.DepthLenLabel.Text = 'Depth Len:';
            app.ArtifactsPanel = uipanel(app.SignalExcitationTab);
            app.ArtifactsPanel.Title = 'Artifacts';
            app.CutArtifactsCheckBox = uicheckbox(app.ArtifactsPanel);
            app.CutArtifactsCheckBox.Text = 'Cut Artifacts';
            app.CutArtifactsCheckBox.Value = true;
            app.IndexesEditFieldLabel = uilabel(app.ArtifactsPanel);
            app.IndexesEditFieldLabel.Text = 'Indexes';
            app.IndexesEditField = uieditfield(app.ArtifactsPanel, 'text');
            app.IndexesEditField.Value = '1:15';
            app.MuEffCalculationPanel = uipanel(app.SignalExcitationTab);
            app.MuEffCalculationPanel.Title = 'MuEff Calculation';
            app.IndexesEditField_2Label = uilabel(app.MuEffCalculationPanel);
            app.IndexesEditField_2Label.Text = 'Indexes:';
            app.muEffIndexesEditField = uieditfield(app.MuEffCalculationPanel, 'text');
            app.muEffIndexesEditField.Value = '120:134';
            app.GeometryDropDown_2Label = uilabel(app.MuEffCalculationPanel);
            app.GeometryDropDown_2Label.Text = 'Geometry:';
            app.GeometryDropDown_2 = uidropdown(app.MuEffCalculationPanel);
            app.GeometryDropDown_2.Items = {'Uniform', 'Two Fibers', 'Point'};
            app.GeometryDropDown_2.Value = 'Uniform';
            app.CalcMuEffCheckBox = uicheckbox(app.MuEffCalculationPanel);
            app.CalcMuEffCheckBox.Text = 'CalcMuEff';
            app.SNRNoisePanel = uipanel(app.SignalExcitationTab);
            app.SNRNoisePanel.Title = 'SNR & Noise';
            app.PeakIdxEditFieldLabel = uilabel(app.SNRNoisePanel);
            app.PeakIdxEditFieldLabel.Text = 'Peak Idx:';
            app.PeakIdxEditField = uieditfield(app.SNRNoisePanel, 'numeric');
            app.PeakIdxEditField.Value = 137;
            app.BkgIdxsEditFieldLabel = uilabel(app.SNRNoisePanel);
            app.BkgIdxsEditFieldLabel.Text = 'Bkg Idxs:';
            app.BkgIdxsEditField = uieditfield(app.SNRNoisePanel, 'text');
            app.BkgIdxsEditField.Value = '30:60';
            app.ExternalSNRDefinitionCheckBox = uicheckbox(app.SNRNoisePanel);
            app.ExternalSNRDefinitionCheckBox.Text = 'External SNR Definition';
            app.ExternalSNRDefinitionCheckBox.Value = true;
            app.VirtualDataPanel = uipanel(app.SignalExcitationTab);
            app.VirtualDataPanel.Title = 'Virtual Data';
            app.UseVirtualDataCheckBox = uicheckbox(app.VirtualDataPanel);
            app.UseVirtualDataCheckBox.Text = 'Use Virtual Data';
            app.NoiesStdEditFieldLabel = uilabel(app.VirtualDataPanel);
            app.NoiesStdEditFieldLabel.Text = 'Noies Std.';
            app.NoiesStdEditField = uieditfield(app.VirtualDataPanel, 'numeric');
            app.MuEffEditFieldLabel = uilabel(app.VirtualDataPanel);
            app.MuEffEditFieldLabel.Enable = 'off';
            app.MuEffEditFieldLabel.Text = 'MuEff';
            app.MuEffEditField = uieditfield(app.VirtualDataPanel, 'numeric');
            app.MuEffEditField.Enable = 'off';
            app.ReCalcDataButton = uibutton(app.SignalExcitationTab, 'push');
            app.ReCalcDataButton.ButtonPushedFcn = createCallbackFcn(app, @ReCalcDataButtonPushed, true);
            app.ReCalcDataButton.Text = 'Re-Calc Data';
            app.ImportVarsPanel = uipanel(app.SignalExcitationTab);
            app.ImportVarsPanel.Title = 'Import Vars';
            app.VarsEditFieldLabel = uilabel(app.ImportVarsPanel);
            app.VarsEditFieldLabel.Text = 'Vars';
            app.VarsEditField = uieditfield(app.ImportVarsPanel, 'text');
            app.VarsEditField.Editable = 'off';
            app.analysisVarsPathButton = uibutton(app.ImportVarsPanel, 'push');
            app.analysisVarsPathButton.ButtonPushedFcn = createCallbackFcn(app, @analysisVarsPathButtonPushed, true);
            app.analysisVarsPathButton.Text = '...';
            app.ImportVarsButton = uibutton(app.ImportVarsPanel, 'push');
            app.ImportVarsButton.ButtonPushedFcn = createCallbackFcn(app, @ImportVarsToGUIButtonPushed, true);
            app.ImportVarsButton.Text = 'Import Vars';
            app.ExportVarsButton = uibutton(app.ImportVarsPanel, 'push');
            app.ExportVarsButton.ButtonPushedFcn = createCallbackFcn(app, @ExportVarsFromGUIButtonPushed, true);
            app.ExportVarsButton.Text = 'Export Vars';
            app.PeripheralsTab = uitab(app.GlobalParameters);
            app.PeripheralsTab.Title = 'Peripherals';
            app.FunctionGeneratorPanel = uipanel(app.PeripheralsTab);
            app.FunctionGeneratorPanel.Title = 'Function Generator';
            app.SamplesClkFreqEditFieldLabel = uilabel(app.FunctionGeneratorPanel);
            app.SamplesClkFreqEditFieldLabel.Text = 'Samples Clk Freq.';
            app.SamplesClkFreqEditField = uieditfield(app.FunctionGeneratorPanel, 'numeric');
            app.SamplesClkFreqEditField.Value = 100;
            app.MSsLabel = uilabel(app.FunctionGeneratorPanel);
            app.MSsLabel.Text = '[MS/s]';
            app.Label_17 = uilabel(app.FunctionGeneratorPanel);
            app.Label_17.Text = '[%]';
            app.DutyCycleEditFieldLabel = uilabel(app.FunctionGeneratorPanel);
            app.DutyCycleEditFieldLabel.Text = 'Duty Cycle';
            app.DutyCycleEditField = uieditfield(app.FunctionGeneratorPanel, 'numeric');
            app.DutyCycleEditField.Value = 50;
            app.TurnAFGOffButton = uibutton(app.FunctionGeneratorPanel, 'push');
            app.TurnAFGOffButton.ButtonPushedFcn = createCallbackFcn(app, @TurnAFGOffButtonPushed, true);
            app.TurnAFGOffButton.Text = 'Turn AFG Off ';
            app.TurnAFGOnButton = uibutton(app.FunctionGeneratorPanel, 'push');
            app.TurnAFGOnButton.ButtonPushedFcn = createCallbackFcn(app, @TurnAFGOnButtonPushed, true);
            app.TurnAFGOnButton.Text = 'Turn AFG On ';
            app.DAQPanel = uipanel(app.PeripheralsTab);
            app.DAQPanel.Title = 'DAQ';
            app.SamplingRateEditFieldLabel = uilabel(app.DAQPanel);
            app.SamplingRateEditFieldLabel.Text = 'Sampling Rate';
            app.SamplingRateEditField = uieditfield(app.DAQPanel, 'numeric');
            app.SamplingRateEditField.Value = 40000000;
            app.Label_14 = uilabel(app.DAQPanel);
            app.Label_14.Text = '[#]';
            app.Label_15 = uilabel(app.DAQPanel);
            app.Label_15.Text = '[#]';
            app.Label_16 = uilabel(app.DAQPanel);
            app.Label_16.Text = '[#]';
            app.sLabel_10 = uilabel(app.DAQPanel);
            app.sLabel_10.Text = '[s]';
            app.HzLabel_2 = uilabel(app.DAQPanel);
            app.HzLabel_2.Text = '[Hz]';
            app.SamplesEditFieldLabel = uilabel(app.DAQPanel);
            app.SamplesEditFieldLabel.Text = 'Samples';
            app.SamplesEditField = uieditfield(app.DAQPanel, 'numeric');
            app.SamplesEditField.Value = 2030;
            app.AveragesEditFieldLabel = uilabel(app.DAQPanel);
            app.AveragesEditFieldLabel.Text = 'Averages';
            app.AveragesEditField = uieditfield(app.DAQPanel, 'numeric');
            app.AveragesEditField.Value = 40;
            app.FramesEditFieldLabel = uilabel(app.DAQPanel);
            app.FramesEditFieldLabel.Text = 'Frames';
            app.FramesEditField = uieditfield(app.DAQPanel, 'numeric');
            app.FramesEditField.Value = 1;
            app.DelayEditFieldLabel = uilabel(app.DAQPanel);
            app.DelayEditFieldLabel.Text = 'Delay';
            app.DelayEditField = uieditfield(app.DAQPanel, 'numeric');
            app.ResetDaqButton = uibutton(app.DAQPanel, 'push');
            app.ResetDaqButton.ButtonPushedFcn = createCallbackFcn(app, @ResetDAQResetDaqButtonPushed, true);
            app.ResetDaqButton.Text = 'Reset Daq';
            app.OsciloscopePanel = uipanel(app.PeripheralsTab);
            app.OsciloscopePanel.Title = 'Osciloscope';
            app.ChannelNoEditFieldLabel = uilabel(app.OsciloscopePanel);
            app.ChannelNoEditFieldLabel.Text = 'Channel No.';
            app.PEUSChannelNoEditField = uieditfield(app.OsciloscopePanel, 'numeric');
            app.PEUSChannelNoEditField.Value = 1;
            app.TrigChannelEditFieldLabel = uilabel(app.OsciloscopePanel);
            app.TrigChannelEditFieldLabel.Text = 'Trig Channel';
            app.PEUSTrigChannelEditField = uieditfield(app.OsciloscopePanel, 'numeric');
            app.PEUSTrigChannelEditField.Value = 4;
            app.NumofAvgEditFieldLabel = uilabel(app.OsciloscopePanel);
            app.NumofAvgEditFieldLabel.Text = 'Num of Avg.';
            app.PEUSNumofAvgEditField = uieditfield(app.OsciloscopePanel, 'numeric');
            app.PEUSNumofAvgEditField.Value = 64;
            app.RepetitionRateEditFieldLabel = uilabel(app.OsciloscopePanel);
            app.RepetitionRateEditFieldLabel.Text = 'Repetition Rate';
            app.PEUSRepetitionRateEditField = uieditfield(app.OsciloscopePanel, 'numeric');
            app.PEUSRepetitionRateEditField.Value = 100;
            app.IntValEditFieldLabel = uilabel(app.OsciloscopePanel);
            app.IntValEditFieldLabel.Text = 'Int Val.';
            app.PEUSIntValEditField = uieditfield(app.OsciloscopePanel, 'numeric');
            app.PEUSIntValEditField.Value = 100;
            app.HzLabel = uilabel(app.OsciloscopePanel);
            app.HzLabel.Text = '[Hz]';
            app.BackProjectionPanel = uipanel(app.PeripheralsTab);
            app.BackProjectionPanel.Title = 'BackProjection';
            app.OABPmmLabel = uilabel(app.BackProjectionPanel);
            app.OABPmmLabel.Text = '[mm]';
            app.GeometryDropDownLabel = uilabel(app.BackProjectionPanel);
            app.GeometryDropDownLabel.Text = 'Geometry';
            app.GeometryDropDown = uidropdown(app.BackProjectionPanel);
            app.GeometryDropDown.Items = {'Circular'};
            app.GeometryDropDown.Value = 'Circular';
            app.ImageWidthEditFieldLabel = uilabel(app.BackProjectionPanel);
            app.ImageWidthEditFieldLabel.Text = 'Image Width';
            app.ImageWidthEditField = uieditfield(app.BackProjectionPanel, 'numeric');
            app.ImageWidthEditField.Value = 30;
            app.BPModeDropDownLabel = uilabel(app.BackProjectionPanel);
            app.BPModeDropDownLabel.Text = 'BP Mode';
            app.BPModeDropDown = uidropdown(app.BackProjectionPanel);
            app.BPModeDropDown.Items = {'Direct', 'Derivative', 'Full'};
            app.BPModeDropDown.Value = 'Direct';
            app.DigitizerPanel = uipanel(app.PeripheralsTab);
            app.DigitizerPanel.Title = 'Digitizer';
            app.CroppedSamplsEditFieldLabel = uilabel(app.DigitizerPanel);
            app.CroppedSamplsEditFieldLabel.Text = 'Cropped Sampls ';
            app.CroppedSamplsEditField = uieditfield(app.DigitizerPanel, 'numeric');
            app.MonitorButton = uibutton(app.DigitizerPanel, 'push');
            app.MonitorButton.ButtonPushedFcn = createCallbackFcn(app, @MonitorButtonPushed, true);
            app.MonitorButton.Text = 'Monitor';
            app.UseusersetcropsamplesCheckBox = uicheckbox(app.DigitizerPanel);
            app.UseusersetcropsamplesCheckBox.Text = 'Use user set crop samples';
            app.ResetDigitizerButton = uibutton(app.DigitizerPanel, 'push');
            app.ResetDigitizerButton.ButtonPushedFcn = createCallbackFcn(app, @ResetDigitizerButtonPushed, true);
            app.ResetDigitizerButton.Text = 'Reset Digitizer';
            app.CalibrationPanel = uipanel(app.PeripheralsTab);
            app.CalibrationPanel.Title = 'Calibration';
            app.DurationEditFieldLabel = uilabel(app.CalibrationPanel);
            app.DurationEditFieldLabel.Text = 'Duration';
            app.DurationEditField = uieditfield(app.CalibrationPanel, 'numeric');
            app.secLabel_4 = uilabel(app.CalibrationPanel);
            app.secLabel_4.Text = '[sec]';
            app.CalibrateButton = uibutton(app.CalibrationPanel, 'push');
            app.CalibrateButton.ButtonPushedFcn = createCallbackFcn(app, @CalibrateButtonPushed, true);
            app.CalibrateButton.Text = 'Calibrate!';
            app.StagesPanel = uipanel(app.PeripheralsTab);
            app.StagesPanel.Title = 'Stages';
            app.OpenStagesGUIButton = uibutton(app.StagesPanel, 'push');
            app.OpenStagesGUIButton.ButtonPushedFcn = createCallbackFcn(app, @OpenStagesGUIButtonPushed, true);
            app.OpenStagesGUIButton.Text = 'Open Stages GUI';
            app.IOControlPanel = uipanel(app.PeripheralsTab);
            app.IOControlPanel.Title = 'IO Control';
            app.CloseIOPortButton = uibutton(app.IOControlPanel, 'push');
            app.CloseIOPortButton.ButtonPushedFcn = createCallbackFcn(app, @CloseIOPortButtonPushed, true);
            app.CloseIOPortButton.Text = 'Close IO Port';
            app.OpenIOPortButton = uibutton(app.IOControlPanel, 'push');
            app.OpenIOPortButton.ButtonPushedFcn = createCallbackFcn(app, @OpenIOPortButtonPushed, true);
            app.OpenIOPortButton.Text = 'Open IO Port';
            app.PlotsDataTab = uitab(app.GlobalParameters);
            app.PlotsDataTab.Title = 'Plots & Data';
            app.TabGroup3 = uitabgroup(app.PlotsDataTab);
            app.AOTab = uitab(app.TabGroup3);
            app.AOTab.Title = 'AO';
            app.TimeTablePanel = uipanel(app.AOTab);
            app.TimeTablePanel.Title = 'Time Table';
            app.DispTimeTableCheckBox = uicheckbox(app.TimeTablePanel);
            app.DispTimeTableCheckBox.Text = 'Disp. Time Table';
            app.DispTimeTableCheckBox.Value = true;
            app.ExportDataPanel = uipanel(app.AOTab);
            app.ExportDataPanel.Title = 'Export Data';
            app.MeasuredCheckBox = uicheckbox(app.ExportDataPanel);
            app.MeasuredCheckBox.Text = 'Measured';
            app.SignalCheckBox = uicheckbox(app.ExportDataPanel);
            app.SignalCheckBox.Text = 'Signal';
            app.DeMultiplexedCheckBox = uicheckbox(app.ExportDataPanel);
            app.DeMultiplexedCheckBox.Text = 'De-Multiplexed';
            app.ReshapedExportCheckBox = uicheckbox(app.ExportDataPanel);
            app.ReshapedExportCheckBox.Text = 'Reshaped';
            app.FFTCheckBox = uicheckbox(app.ExportDataPanel);
            app.FFTCheckBox.Text = 'FFT';
            app.ComplexCompUSCheckBox = uicheckbox(app.ExportDataPanel);
            app.ComplexCompUSCheckBox.Text = 'Complex Comp.(US)';
            app.AcoustoOpticsPanel_4 = uipanel(app.AOTab);
            app.AcoustoOpticsPanel_4.Title = 'Acousto Optics';
            app.ExcitationSignalsLabel_4 = uilabel(app.AcoustoOpticsPanel_4);
            app.ExcitationSignalsLabel_4.Text = 'Excitation Signals';
            app.TimeDomainSignalsLabel_4 = uilabel(app.AcoustoOpticsPanel_4);
            app.TimeDomainSignalsLabel_4.Text = 'Time Domain Signals';
            app.FrequencyDomainLabel_4 = uilabel(app.AcoustoOpticsPanel_4);
            app.FrequencyDomainLabel_4.Text = 'Frequency Domain';
            app.SpatialReconLabel_4 = uilabel(app.AcoustoOpticsPanel_4);
            app.SpatialReconLabel_4.Text = 'Spatial Recon';
            app.ExternalClkDropDownLabel = uilabel(app.AcoustoOpticsPanel_4);
            app.ExternalClkDropDownLabel.Text = 'External Clk';
            app.USSignalDropDownLabel = uilabel(app.AcoustoOpticsPanel_4);
            app.USSignalDropDownLabel.Text = 'US Signal';
            app.CroppedDropDownLabel = uilabel(app.AcoustoOpticsPanel_4);
            app.CroppedDropDownLabel.Text = 'Cropped';
            app.MeasuredDropDownLabel = uilabel(app.AcoustoOpticsPanel_4);
            app.MeasuredDropDownLabel.Text = 'Measured';
            app.SignalDropDownLabel = uilabel(app.AcoustoOpticsPanel_4);
            app.SignalDropDownLabel.Text = 'Signal';
            app.DeMulLabel = uilabel(app.AcoustoOpticsPanel_4);
            app.DeMulLabel.Text = 'De-Mul.';
            app.ReshapedDropDownLabel = uilabel(app.AcoustoOpticsPanel_4);
            app.ReshapedDropDownLabel.Text = 'Reshaped';
            app.PhiDropDownLabel = uilabel(app.AcoustoOpticsPanel_4);
            app.PhiDropDownLabel.Text = 'Phi';
            app.PhiRawDropDownLabel = uilabel(app.AcoustoOpticsPanel_4);
            app.PhiRawDropDownLabel.Text = 'Phi (Raw)';
            app.PhiLogDropDownLabel = uilabel(app.AcoustoOpticsPanel_4);
            app.PhiLogDropDownLabel.Text = 'Phi Log';
            app.phiLogDropDown = uidropdown(app.AcoustoOpticsPanel_4);
            app.phiLogDropDown.Items = {'Hide', 'Tab 1', 'Tab 2', 'Tab 3', 'Tab 4'};
            app.phiLogDropDown.ValueChangedFcn = createCallbackFcn(app, @ExternalClkDropDownValueChanged, true);
            app.phiLogDropDown.Value = 'Tab 2';
            app.rawPhiDropDown = uidropdown(app.AcoustoOpticsPanel_4);
            app.rawPhiDropDown.Items = {'Hide', 'Tab 1', 'Tab 2', 'Tab 3', 'Tab 4'};
            app.rawPhiDropDown.ValueChangedFcn = createCallbackFcn(app, @ExternalClkDropDownValueChanged, true);
            app.rawPhiDropDown.Value = 'Hide';
            app.phiDropDown = uidropdown(app.AcoustoOpticsPanel_4);
            app.phiDropDown.Items = {'Hide', 'Tab 1', 'Tab 2', 'Tab 3', 'Tab 4'};
            app.phiDropDown.ValueChangedFcn = createCallbackFcn(app, @ExternalClkDropDownValueChanged, true);
            app.phiDropDown.Value = 'Tab 2';
            app.finalFFTDropDown = uidropdown(app.AcoustoOpticsPanel_4);
            app.finalFFTDropDown.Items = {'Hide', 'Tab 1', 'Tab 2', 'Tab 3', 'Tab 4'};
            app.finalFFTDropDown.ValueChangedFcn = createCallbackFcn(app, @ExternalClkDropDownValueChanged, true);
            app.finalFFTDropDown.Value = 'Tab 4';
            app.RawFFTDropDownLabel = uilabel(app.AcoustoOpticsPanel_4);
            app.RawFFTDropDownLabel.Text = 'Raw FFT';
            app.FittingModelDropDownLabel = uilabel(app.AcoustoOpticsPanel_4);
            app.FittingModelDropDownLabel.Text = 'Fitting Model';
            app.FittedPFFTLabel = uilabel(app.AcoustoOpticsPanel_4);
            app.FittedPFFTLabel.Text = 'Fitted P-FFT';
            app.fittedPowerFFTDropDown = uidropdown(app.AcoustoOpticsPanel_4);
            app.fittedPowerFFTDropDown.Items = {'Hide', 'Tab 1', 'Tab 2', 'Tab 3', 'Tab 4'};
            app.fittedPowerFFTDropDown.ValueChangedFcn = createCallbackFcn(app, @ExternalClkDropDownValueChanged, true);
            app.fittedPowerFFTDropDown.Value = 'Hide';
            app.FinalFFTDropDownLabel = uilabel(app.AcoustoOpticsPanel_4);
            app.FinalFFTDropDownLabel.Text = 'Final FFT';
            app.fittingModelDropDown = uidropdown(app.AcoustoOpticsPanel_4);
            app.fittingModelDropDown.Items = {'Hide', 'Tab 1', 'Tab 2', 'Tab 3', 'Tab 4'};
            app.fittingModelDropDown.ValueChangedFcn = createCallbackFcn(app, @ExternalClkDropDownValueChanged, true);
            app.fittingModelDropDown.Value = 'Hide';
            app.rawFFTDropDown = uidropdown(app.AcoustoOpticsPanel_4);
            app.rawFFTDropDown.Items = {'Hide', 'Tab 1', 'Tab 2', 'Tab 3', 'Tab 4'};
            app.rawFFTDropDown.ValueChangedFcn = createCallbackFcn(app, @ExternalClkDropDownValueChanged, true);
            app.rawFFTDropDown.Value = 'Tab 4';
            app.reshapedDropDown = uidropdown(app.AcoustoOpticsPanel_4);
            app.reshapedDropDown.Items = {'Hide', 'Tab 1', 'Tab 2', 'Tab 3', 'Tab 4'};
            app.reshapedDropDown.ValueChangedFcn = createCallbackFcn(app, @ExternalClkDropDownValueChanged, true);
            app.reshapedDropDown.Value = 'Hide';
            app.deMulDropDown = uidropdown(app.AcoustoOpticsPanel_4);
            app.deMulDropDown.Items = {'Hide', 'Tab 1', 'Tab 2', 'Tab 3', 'Tab 4'};
            app.deMulDropDown.ValueChangedFcn = createCallbackFcn(app, @ExternalClkDropDownValueChanged, true);
            app.deMulDropDown.Value = 'Hide';
            app.signalDropDown = uidropdown(app.AcoustoOpticsPanel_4);
            app.signalDropDown.Items = {'Hide', 'Tab 1', 'Tab 2', 'Tab 3', 'Tab 4'};
            app.signalDropDown.ValueChangedFcn = createCallbackFcn(app, @ExternalClkDropDownValueChanged, true);
            app.signalDropDown.Value = 'Hide';
            app.measDropDown = uidropdown(app.AcoustoOpticsPanel_4);
            app.measDropDown.Items = {'Hide', 'Tab 1', 'Tab 2', 'Tab 3', 'Tab 4'};
            app.measDropDown.ValueChangedFcn = createCallbackFcn(app, @ExternalClkDropDownValueChanged, true);
            app.measDropDown.Value = 'Hide';
            app.croppedDropDown = uidropdown(app.AcoustoOpticsPanel_4);
            app.croppedDropDown.Items = {'Hide', 'Tab 1', 'Tab 2', 'Tab 3', 'Tab 4'};
            app.croppedDropDown.ValueChangedFcn = createCallbackFcn(app, @ExternalClkDropDownValueChanged, true);
            app.croppedDropDown.Value = 'Hide';
            app.usSignalDropDown = uidropdown(app.AcoustoOpticsPanel_4);
            app.usSignalDropDown.Items = {'Hide', 'Tab 1', 'Tab 2', 'Tab 3', 'Tab 4'};
            app.usSignalDropDown.DropDownOpeningFcn = createCallbackFcn(app, @ExternalClkDropDownValueChanged, true);
            app.usSignalDropDown.ValueChangedFcn = createCallbackFcn(app, @ExternalClkDropDownValueChanged, true);
            app.usSignalDropDown.Value = 'Hide';
            app.extClkDropDown = uidropdown(app.AcoustoOpticsPanel_4);
            app.extClkDropDown.Items = {'Hide', 'Tab 1', 'Tab 2', 'Tab 3', 'Tab 4'};
            app.extClkDropDown.ValueChangedFcn = createCallbackFcn(app, @ExternalClkDropDownValueChanged, true);
            app.extClkDropDown.Value = 'Hide';
            app.CalibrationLabel = uilabel(app.AcoustoOpticsPanel_4);
            app.CalibrationLabel.Text = 'Calibration';
            app.calibrationDropDown = uidropdown(app.AcoustoOpticsPanel_4);
            app.calibrationDropDown.Items = {'Hide', 'Tab 1', 'Tab 2', 'Tab 3', 'Tab 4'};
            app.calibrationDropDown.ValueChangedFcn = createCallbackFcn(app, @ExternalClkDropDownValueChanged, true);
            app.calibrationDropDown.Value = 'Hide';
            app.dispFullFFTCheckBox = uicheckbox(app.AcoustoOpticsPanel_4);
            app.dispFullFFTCheckBox.ValueChangedFcn = createCallbackFcn(app, @dispFullFFTCheckBoxValueChanged, true);
            app.dispFullFFTCheckBox.Text = 'disp Full FFT';
            app.dispFullFFTCheckBox.Value = true;
            app.FreqEnvEditFieldLabel = uilabel(app.AcoustoOpticsPanel_4);
            app.FreqEnvEditFieldLabel.Enable = 'off';
            app.FreqEnvEditFieldLabel.Text = 'Freq. Env.';
            app.FreqEnvEditField = uieditfield(app.AcoustoOpticsPanel_4, 'numeric');
            app.FreqEnvEditField.ValueChangedFcn = createCallbackFcn(app, @FreqEnviromentAOEditFieldValueChanged, true);
            app.FreqEnvEditField.Enable = 'off';
            app.FreqEnvEditField.Value = 250;
            app.KHzLabel_8 = uilabel(app.AcoustoOpticsPanel_4);
            app.KHzLabel_8.Enable = 'off';
            app.KHzLabel_8.Text = 'KHz';
            app.NotificationsPanel = uipanel(app.AOTab);
            app.NotificationsPanel.Title = 'Notifications';
            app.UpdatedviaTelegramCheckBox = uicheckbox(app.NotificationsPanel);
            app.UpdatedviaTelegramCheckBox.Text = 'Updated via Telegram';
            app.ChatIDEditFieldLabel = uilabel(app.NotificationsPanel);
            app.ChatIDEditFieldLabel.Text = 'Chat ID';
            app.ChatIDEditField = uieditfield(app.NotificationsPanel, 'text');
            app.ChatIDEditField.Value = '-512325870';
            app.SendEveryEditFieldLabel = uilabel(app.NotificationsPanel);
            app.SendEveryEditFieldLabel.Text = 'Send Every';
            app.SendEveryEditField = uieditfield(app.NotificationsPanel, 'numeric');
            app.SendEveryEditField.Limits = [1 Inf];
            app.SendEveryEditField.Value = 4;
            app.AxisPropertiesPanel = uipanel(app.AOTab);
            app.AxisPropertiesPanel.Title = 'Axis Properties';
            app.DepthDropDownLabel = uilabel(app.AxisPropertiesPanel);
            app.DepthDropDownLabel.Text = 'Depth';
            app.AODepthAxTypeDropDown = uidropdown(app.AxisPropertiesPanel);
            app.AODepthAxTypeDropDown.Items = {'Normal', 'Center', 'Zero', 'Index'};
            app.AODepthAxTypeDropDown.Value = 'Index';
            app.AOSTab = uitab(app.TabGroup3);
            app.AOSTab.Title = 'AOS';
            app.NavigatorPropertiesPanel = uipanel(app.AOSTab);
            app.NavigatorPropertiesPanel.Title = 'Navigator Properties';
            app.NormalizePlaneColorsCheckBox = uicheckbox(app.NavigatorPropertiesPanel);
            app.NormalizePlaneColorsCheckBox.ValueChangedFcn = createCallbackFcn(app, @NormalizePlaneColorsCheckBoxValueChanged, true);
            app.NormalizePlaneColorsCheckBox.Text = 'Normalize Plane Colors';
            app.PlaneNavAx4IdxLabel = uilabel(app.NavigatorPropertiesPanel);
            app.PlaneNavAx4IdxLabel.Text = '00.00[mm]';
            app.PlaneNavAx3IdxLabel = uilabel(app.NavigatorPropertiesPanel);
            app.PlaneNavAx3IdxLabel.Text = '00.00[mm]';
            app.PlaneNavAx4IdxSpinner = uispinner(app.NavigatorPropertiesPanel);
            app.PlaneNavAx4IdxSpinner.Limits = [1 Inf];
            app.PlaneNavAx4IdxSpinner.ValueChangedFcn = createCallbackFcn(app, @PlaneNavAx4IdxSpinnerValueChanged, true);
            app.PlaneNavAx4IdxSpinner.Value = 1;
            app.PlaneNavAx3IdxSpinner = uispinner(app.NavigatorPropertiesPanel);
            app.PlaneNavAx3IdxSpinner.Limits = [1 Inf];
            app.PlaneNavAx3IdxSpinner.ValueChangedFcn = createCallbackFcn(app, @PlaneNavAx3IdxSpinnerValueChanged, true);
            app.PlaneNavAx3IdxSpinner.Value = 1;
            app.PlaneNavAx4Label = uilabel(app.NavigatorPropertiesPanel);
            app.PlaneNavAx4Label.Text = 'Z';
            app.PlaneNavAx3Label = uilabel(app.NavigatorPropertiesPanel);
            app.PlaneNavAx3Label.Text = 'Y';
            app.PlaneNavAx2DropDown = uidropdown(app.NavigatorPropertiesPanel);
            app.PlaneNavAx2DropDown.Items = {'X', 'D'};
            app.PlaneNavAx2DropDown.ValueChangedFcn = createCallbackFcn(app, @PlaneNavAx2DropDownValueChanged, true);
            app.PlaneNavAx2DropDown.Value = 'D';
            app.PlaneNavAx1DropDown = uidropdown(app.NavigatorPropertiesPanel);
            app.PlaneNavAx1DropDown.Items = {'X', 'D'};
            app.PlaneNavAx1DropDown.ValueChangedFcn = createCallbackFcn(app, @PlaneNavAx1DropDownValueChanged, true);
            app.PlaneNavAx1DropDown.Value = 'X';
            app.PlaneNavigatorLabel = uilabel(app.NavigatorPropertiesPanel);
            app.PlaneNavigatorLabel.Text = 'Plane Navigator:';
            app.LineNavAx4IdxLabel = uilabel(app.NavigatorPropertiesPanel);
            app.LineNavAx4IdxLabel.Text = '00.00[mm]';
            app.LineNavAx3IdxLabel = uilabel(app.NavigatorPropertiesPanel);
            app.LineNavAx3IdxLabel.Text = '00.00[mm]';
            app.LineNavAx2IdxLabel = uilabel(app.NavigatorPropertiesPanel);
            app.LineNavAx2IdxLabel.Text = '00.00[mm]';
            app.LineNavAx4Spinner = uispinner(app.NavigatorPropertiesPanel);
            app.LineNavAx4Spinner.Limits = [1 Inf];
            app.LineNavAx4Spinner.ValueChangedFcn = createCallbackFcn(app, @LineNavAx4SpinnerValueChanged, true);
            app.LineNavAx4Spinner.Value = 1;
            app.LineNavAx3Spinner = uispinner(app.NavigatorPropertiesPanel);
            app.LineNavAx3Spinner.Limits = [1 Inf];
            app.LineNavAx3Spinner.ValueChangedFcn = createCallbackFcn(app, @LineNavAx3SpinnerValueChanged, true);
            app.LineNavAx3Spinner.Value = 1;
            app.LineNavAx2Spinner = uispinner(app.NavigatorPropertiesPanel);
            app.LineNavAx2Spinner.Limits = [1 Inf];
            app.LineNavAx2Spinner.ValueChangedFcn = createCallbackFcn(app, @LineNavAx2SpinnerValueChanged, true);
            app.LineNavAx2Spinner.Value = 1;
            app.LineNavAx4Label = uilabel(app.NavigatorPropertiesPanel);
            app.LineNavAx4Label.Text = 'D';
            app.LineNavAx3Label = uilabel(app.NavigatorPropertiesPanel);
            app.LineNavAx3Label.Text = 'Z';
            app.LineNavAx2Label = uilabel(app.NavigatorPropertiesPanel);
            app.LineNavAx2Label.Text = 'Y';
            app.LineNavAx1DropDown = uidropdown(app.NavigatorPropertiesPanel);
            app.LineNavAx1DropDown.Items = {'X', 'Y', 'Z', 'D'};
            app.LineNavAx1DropDown.ValueChangedFcn = createCallbackFcn(app, @LineNavAx1DropDownValueChanged, true);
            app.LineNavAx1DropDown.Value = 'X';
            app.LineNavigatorLabel = uilabel(app.NavigatorPropertiesPanel);
            app.LineNavigatorLabel.Text = 'Line Navigator:';
            app.AxespropertiesPanel = uipanel(app.AOSTab);
            app.AxespropertiesPanel.Title = 'Axes properties';
            app.Scan1Label = uilabel(app.AxespropertiesPanel);
            app.Scan1Label.Text = 'Scan 1 ';
            app.AOScan1AxTypeDropDown = uidropdown(app.AxespropertiesPanel);
            app.AOScan1AxTypeDropDown.Items = {'Normal', 'Center', 'Zero', 'Index'};
            app.AOScan1AxTypeDropDown.Value = 'Normal';
            app.Scan2Label = uilabel(app.AxespropertiesPanel);
            app.Scan2Label.Text = 'Scan 2';
            app.AOScan2AxTypeDropDown = uidropdown(app.AxespropertiesPanel);
            app.AOScan2AxTypeDropDown.Items = {'Normal', 'Center', 'Zero', 'Index'};
            app.AOScan2AxTypeDropDown.Value = 'Normal';
            app.Scan2Label_2 = uilabel(app.AxespropertiesPanel);
            app.Scan2Label_2.Text = 'Scan 3';
            app.AOScan3AxTypeDropDown = uidropdown(app.AxespropertiesPanel);
            app.AOScan3AxTypeDropDown.Items = {'Normal', 'Center', 'Zero', 'Index'};
            app.AOScan3AxTypeDropDown.Value = 'Normal';
            app.FiguresArrangementPanel = uipanel(app.AOSTab);
            app.FiguresArrangementPanel.Title = 'Figures Arrangement';
            app.MainPlaneDropDownLabel = uilabel(app.FiguresArrangementPanel);
            app.MainPlaneDropDownLabel.Text = 'Main Plane';
            app.scanPlaneDropDown = uidropdown(app.FiguresArrangementPanel);
            app.scanPlaneDropDown.Items = {'Hide', 'Tab 1', 'Tab 2', 'Tab 3', 'Tab 4'};
            app.scanPlaneDropDown.ValueChangedFcn = createCallbackFcn(app, @LineNavLogDropDownValueChanged, true);
            app.scanPlaneDropDown.Value = 'Tab 1';
            app.MainPlaneLogDropDownLabel = uilabel(app.FiguresArrangementPanel);
            app.MainPlaneLogDropDownLabel.Text = 'Main Plane Log';
            app.scanPlaneLogDropDown = uidropdown(app.FiguresArrangementPanel);
            app.scanPlaneLogDropDown.Items = {'Hide', 'Tab 1', 'Tab 2', 'Tab 3', 'Tab 4'};
            app.scanPlaneLogDropDown.ValueChangedFcn = createCallbackFcn(app, @LineNavLogDropDownValueChanged, true);
            app.scanPlaneLogDropDown.Value = 'Tab 1';
            app.PlaneNavDropDownLabel = uilabel(app.FiguresArrangementPanel);
            app.PlaneNavDropDownLabel.Text = 'Plane Nav';
            app.navPlaneDropDown = uidropdown(app.FiguresArrangementPanel);
            app.navPlaneDropDown.Items = {'Hide', 'Tab 1', 'Tab 2', 'Tab 3', 'Tab 4'};
            app.navPlaneDropDown.ValueChangedFcn = createCallbackFcn(app, @LineNavLogDropDownValueChanged, true);
            app.navPlaneDropDown.Value = 'Tab 3';
            app.PlaneNavLogDropDownLabel = uilabel(app.FiguresArrangementPanel);
            app.PlaneNavLogDropDownLabel.Text = 'Plane Nav Log';
            app.navPlaneLogDropDown = uidropdown(app.FiguresArrangementPanel);
            app.navPlaneLogDropDown.Items = {'Hide', 'Tab 1', 'Tab 2', 'Tab 3', 'Tab 4'};
            app.navPlaneLogDropDown.ValueChangedFcn = createCallbackFcn(app, @LineNavLogDropDownValueChanged, true);
            app.navPlaneLogDropDown.Value = 'Tab 3';
            app.LineNavDropDownLabel = uilabel(app.FiguresArrangementPanel);
            app.LineNavDropDownLabel.Text = 'Line Nav';
            app.navLineDropDown = uidropdown(app.FiguresArrangementPanel);
            app.navLineDropDown.Items = {'Hide', 'Tab 1', 'Tab 2', 'Tab 3', 'Tab 4'};
            app.navLineDropDown.ValueChangedFcn = createCallbackFcn(app, @LineNavLogDropDownValueChanged, true);
            app.navLineDropDown.Value = 'Tab 3';
            app.LineNavLogDropDownLabel = uilabel(app.FiguresArrangementPanel);
            app.LineNavLogDropDownLabel.Text = 'Line Nav Log';
            app.navLineLogDropDown = uidropdown(app.FiguresArrangementPanel);
            app.navLineLogDropDown.Items = {'Hide', 'Tab 1', 'Tab 2', 'Tab 3', 'Tab 4'};
            app.navLineLogDropDown.ValueChangedFcn = createCallbackFcn(app, @LineNavLogDropDownValueChanged, true);
            app.navLineLogDropDown.Value = 'Hide';
            app.OthersTab = uitab(app.TabGroup3);
            app.OthersTab.Title = 'Others';

            app.OAAxespropertiesPanel = uipanel(app.OthersTab);
            app.OAAxespropertiesPanel.Title = 'OA Axes properties';
            app.FirstReconAxLabel = uilabel(app.OAAxespropertiesPanel);
            app.FirstReconAxLabel.Text = 'First Recon Ax';
            app.OAFirstScanAxTypeDropDown = uidropdown(app.OAAxespropertiesPanel);
            app.OAFirstScanAxTypeDropDown.Items = {'Normal', 'Center', 'Zero', 'Index'};
            app.OAFirstScanAxTypeDropDown.Value = 'Normal';
            app.SecondReconAxLabel = uilabel(app.OAAxespropertiesPanel);
            app.SecondReconAxLabel.Text = 'Second Recon Ax';
            app.OASecondScanAxTypeDropDown = uidropdown(app.OAAxespropertiesPanel);
            app.OASecondScanAxTypeDropDown.Items = {'Normal', 'Centered', 'Zero', 'Index'};
            app.OASecondScanAxTypeDropDown.Value = 'Normal';
            app.ScanAxLabel = uilabel(app.OAAxespropertiesPanel);
            app.ScanAxLabel.Text = 'Scan Ax';
            app.OAScanAxTypeDropDown = uidropdown(app.OAAxespropertiesPanel);
            app.OAScanAxTypeDropDown.Items = {'Normal', 'Centered', 'Zero', 'Index'};
            app.OAScanAxTypeDropDown.Value = 'Normal';
            app.TimeAxLabel = uilabel(app.OAAxespropertiesPanel);
            app.TimeAxLabel.Text = 'Time Ax';
            app.OATimeAxTypeDropDown = uidropdown(app.OAAxespropertiesPanel);
            app.OATimeAxTypeDropDown.Items = {'Normal', 'Sample'};
            app.OATimeAxTypeDropDown.Value = 'Normal';
            app.OAScanNavigatorPanel = uipanel(app.OthersTab);
            app.OAScanNavigatorPanel.Title = 'OA Scan Navigator';
            app.NavPlaneLabel_2 = uilabel(app.OAScanNavigatorPanel);
            app.NavPlaneLabel_2.Text = 'Nav Plane';
            app.OANavPlaneDropDown = uidropdown(app.OAScanNavigatorPanel);
            app.OANavPlaneDropDown.Items = {'X', 'Y', 'Z'};
            app.OANavPlaneDropDown.Enable = 'off';
            app.OANavPlaneDropDown.Value = 'Z';
            app.NavIdxSpinnerLabel_5 = uilabel(app.OAScanNavigatorPanel);
            app.NavIdxSpinnerLabel_5.Text = 'Nav Idx';
            app.OANavIdxSpinner = uispinner(app.OAScanNavigatorPanel);
            app.OANavIdxSpinner.Limits = [0 Inf];
            app.OANavIdxSpinner.ValueChangedFcn = createCallbackFcn(app, @OANavIdxSpinnerValueChanged, true);
            app.OANavIdxSpinner.Enable = 'off';
            app.OANavIdxSpinner.Value = 1;
            app.OANavmmLabel = uilabel(app.OAScanNavigatorPanel);
            app.OANavmmLabel.Text = '00.00[mm]';
            app.OANormalizeColorstoDisplayedPlaneCheckBox = uicheckbox(app.OAScanNavigatorPanel);
            app.OANormalizeColorstoDisplayedPlaneCheckBox.Text = 'Normalize Colors to Displayed Plane';
            app.PEUSAxespropertiesPanel = uipanel(app.OthersTab);
            app.PEUSAxespropertiesPanel.Title = 'PEUS Axes properties';
            app.ScanReconAxLabel = uilabel(app.PEUSAxespropertiesPanel);
            app.ScanReconAxLabel.Text = 'Scan Recon Ax';
            app.PEUSFirstScanAxTypeDropDown = uidropdown(app.PEUSAxespropertiesPanel);
            app.PEUSFirstScanAxTypeDropDown.Items = {'Normal', 'Center', 'Zero', 'Index'};
            app.PEUSFirstScanAxTypeDropDown.Value = 'Normal';
            app.DepthReconAxLabel = uilabel(app.PEUSAxespropertiesPanel);
            app.DepthReconAxLabel.Text = 'Depth Recon Ax';
            app.PEUSSecondScanAxTypeDropDown = uidropdown(app.PEUSAxespropertiesPanel);
            app.PEUSSecondScanAxTypeDropDown.Items = {'Normal', 'Centered', 'Zero', 'Index'};
            app.PEUSSecondScanAxTypeDropDown.Value = 'Normal';
            app.PopoutFiguresPanel = uipanel(app.OthersTab);
            app.PopoutFiguresPanel.Title = 'Pop out Figures';
            app.PopButton = uibutton(app.PopoutFiguresPanel, 'push');
            app.PopButton.ButtonPushedFcn = createCallbackFcn(app, @PopButtonPushed, true);
            app.PopButton.Text = 'Pop!';
            app.FiguresToPopDropDown = uidropdown(app.PopoutFiguresPanel);
            app.FiguresToPopDropDown.Items = {'Acousto Optics', 'AO Scan', 'OA', 'PEUS', 'Int-Sys'};
            app.FiguresToPopDropDown.Value = 'Acousto Optics';
            app.ExternalFiguresCheckBox = uicheckbox(app.PopoutFiguresPanel);
            app.ExternalFiguresCheckBox.Text = 'External Figures';
            app.ReOpenFiguresCheckBox = uicheckbox(app.PopoutFiguresPanel);
            app.ReOpenFiguresCheckBox.Text = 'Re-Open Figures';
            app.PopTab1Button = uibutton(app.PopoutFiguresPanel, 'push');
            app.PopTab1Button.ButtonPushedFcn = createCallbackFcn(app, @PopTab1ButtonPushed, true);
            app.PopTab1Button.Text = 'Pop Tab 1';
            app.PopTab2Button = uibutton(app.PopoutFiguresPanel, 'push');
            app.PopTab2Button.ButtonPushedFcn = createCallbackFcn(app, @PopTab2ButtonPushed, true);
            app.PopTab2Button.Text = 'Pop Tab 2';
            app.PopTab3Button = uibutton(app.PopoutFiguresPanel, 'push');
            app.PopTab3Button.ButtonPushedFcn = createCallbackFcn(app, @PopTab3ButtonPushed, true);
            app.PopTab3Button.Text = 'Pop Tab 3';
            app.PopTab4Button = uibutton(app.PopoutFiguresPanel, 'push');
            app.PopTab4Button.ButtonPushedFcn = createCallbackFcn(app, @PopTab4ButtonPushed, true);
            app.PopTab4Button.Text = 'Pop Tab 4';
            app.RefreshFiguresPanel = uipanel(app.OthersTab);
            app.RefreshFiguresPanel.Title = 'Refresh Figures';
            app.DropDown = uidropdown(app.RefreshFiguresPanel);
            app.DropDown.Items = {'AO', 'AOS', 'DV'};
            app.DropDown.Value = 'AO';
            app.RefreshButton = uibutton(app.RefreshFiguresPanel, 'push');
            app.RefreshButton.Enable = 'off';
            app.RefreshButton.Text = 'Refresh';
            app.InvisiblePlotTabs = uitabgroup(app.AOIUIFigure);
            app.InvisiblePlotTabs.Visible = 'off';
            app.Tab = uitab(app.InvisiblePlotTabs);
            app.Tab.Title = 'Tab';
            app.navAxHolderTab = uitab(app.InvisiblePlotTabs);
            app.navAxHolderTab.Title = 'Tab2';
            app.ScanPlotTab = uitabgroup(app.AOIUIFigure);
            app.scanPlaneTab = uitab(app.ScanPlotTab);
            app.scanPlaneTab.Title = 'Main Plane';
            app.axScanPlane = uiaxes(app.scanPlaneTab);
            title(app.axScanPlane, 'Title')
            xlabel(app.axScanPlane, 'X')
            ylabel(app.axScanPlane, 'Y')
            app.scanPlaneLogTab = uitab(app.ScanPlotTab);
            app.scanPlaneLogTab.Title = 'MainPlane (Log)';
            app.axScanPlaneLog = uiaxes(app.scanPlaneLogTab);
            title(app.axScanPlaneLog, 'Title')
            xlabel(app.axScanPlaneLog, 'X')
            ylabel(app.axScanPlaneLog, 'Y')
            app.navLineTab = uitab(app.ScanPlotTab);
            app.navLineTab.Title = 'Line Navigator';
            app.axNavLine = uiaxes(app.navLineTab);
            title(app.axNavLine, 'Title')
            xlabel(app.axNavLine, 'X')
            ylabel(app.axNavLine, 'Y')
            app.navLineLogTab = uitab(app.ScanPlotTab);
            app.navLineLogTab.Title = 'Line Navigator (Log)';
            app.axNavLineLog = uiaxes(app.navLineLogTab);
            title(app.axNavLineLog, 'Title')
            xlabel(app.axNavLineLog, 'X')
            ylabel(app.axNavLineLog, 'Y')
            app.navPlaneTab = uitab(app.ScanPlotTab);
            app.navPlaneTab.Title = 'Plane Navigator';
            app.axNavPlane = uiaxes(app.navPlaneTab);
            title(app.axNavPlane, 'Title')
            xlabel(app.axNavPlane, 'X')
            ylabel(app.axNavPlane, 'Y')
            app.navPlaneLogTab = uitab(app.ScanPlotTab);
            app.navPlaneLogTab.Title = 'Plane Navigator (Log)';
            app.axNavPlaneLog = uiaxes(app.navPlaneLogTab);
            title(app.axNavPlaneLog, 'Title')
            xlabel(app.axNavPlaneLog, 'X')
            ylabel(app.axNavPlaneLog, 'Y')
            app.BPTab = uitab(app.ScanPlotTab);
            app.BPTab.Title = 'BP';
            app.axOABP = uiaxes(app.BPTab);
            title(app.axOABP, 'Title')
            xlabel(app.axOABP, 'X')
            ylabel(app.axOABP, 'Y')
            app.SinogramTab = uitab(app.ScanPlotTab);
            app.SinogramTab.Title = 'Sinogram';
            app.axOASinogram = uiaxes(app.SinogramTab);
            title(app.axOASinogram, 'Title')
            xlabel(app.axOASinogram, 'X')
            ylabel(app.axOASinogram, 'Y')
            app.OANavSinogramTab = uitab(app.ScanPlotTab);
            app.OANavSinogramTab.Title = 'OA Nav. Sinogram';
            app.axOANavSinogram = uiaxes(app.OANavSinogramTab);
            title(app.axOANavSinogram, 'Title')
            xlabel(app.axOANavSinogram, 'X')
            ylabel(app.axOANavSinogram, 'Y')
            app.OANavBPTab = uitab(app.ScanPlotTab);
            app.OANavBPTab.Title = 'OA Nav. BP';
            app.axOANavBP = uiaxes(app.OANavBPTab);
            title(app.axOANavBP, 'Title')
            xlabel(app.axOANavBP, 'X')
            ylabel(app.axOANavBP, 'Y')
            app.PEUSRawTab = uitab(app.ScanPlotTab);
            app.PEUSRawTab.Title = 'PEUS - Raw';
            app.axPEUSRaw = uiaxes(app.PEUSRawTab);
            title(app.axPEUSRaw, 'Title')
            xlabel(app.axPEUSRaw, 'X')
            ylabel(app.axPEUSRaw, 'Y')
            app.PEUSCorrectedTab = uitab(app.ScanPlotTab);
            app.PEUSCorrectedTab.Title = 'PEUS - Corrected';
            app.axPEUSCorrected = uiaxes(app.PEUSCorrectedTab);
            title(app.axPEUSCorrected, 'Title')
            xlabel(app.axPEUSCorrected, 'X')
            ylabel(app.axPEUSCorrected, 'Y')
            app.CorrectedOATab = uitab(app.ScanPlotTab);
            app.CorrectedOATab.Title = 'Corrected OA';
            app.axCorrectedOA = uiaxes(app.CorrectedOATab);
            title(app.axCorrectedOA, 'Title')
            xlabel(app.axCorrectedOA, 'X')
            ylabel(app.axCorrectedOA, 'Y')
            app.FFTTabGroup = uitabgroup(app.AOIUIFigure);
            app.rawFFTTab = uitab(app.FFTTabGroup);
            app.rawFFTTab.Title = 'Raw FFT';
            app.rawFFTAx = uiaxes(app.rawFFTTab);
            title(app.rawFFTAx, 'Title')
            xlabel(app.rawFFTAx, 'X')
            ylabel(app.rawFFTAx, 'Y')
            app.calibrationTab = uitab(app.FFTTabGroup);
            app.calibrationTab.Title = 'Calibration Spectrum';
            app.calibrationAx = uiaxes(app.calibrationTab);
            title(app.calibrationAx, 'Title')
            xlabel(app.calibrationAx, 'X')
            ylabel(app.calibrationAx, 'Y')
            app.fittingModelTab = uitab(app.FFTTabGroup);
            app.fittingModelTab.Title = 'Fitting Model';
            app.fittingModelAx = uiaxes(app.fittingModelTab);
            title(app.fittingModelAx, 'Title')
            xlabel(app.fittingModelAx, 'X')
            ylabel(app.fittingModelAx, 'Y')
            app.fittedPowerFFTTab = uitab(app.FFTTabGroup);
            app.fittedPowerFFTTab.Title = 'Fitted Power FFT';
            app.fittedPowerFFTAx = uiaxes(app.fittedPowerFFTTab);
            title(app.fittedPowerFFTAx, 'Title')
            xlabel(app.fittedPowerFFTAx, 'X')
            ylabel(app.fittedPowerFFTAx, 'Y')
            app.finalFFTTab = uitab(app.FFTTabGroup);
            app.finalFFTTab.Title = 'Final Spectrum';
            app.finalFFTAx = uiaxes(app.finalFFTTab);
            title(app.finalFFTAx, 'Title')
            xlabel(app.finalFFTAx, 'X')
            ylabel(app.finalFFTAx, 'Y')
            app.PhiTabGroup = uitabgroup(app.AOIUIFigure);
            app.phiTab = uitab(app.PhiTabGroup);
            app.phiTab.Title = 'Phi';
            app.phiAx = uiaxes(app.phiTab);
            title(app.phiAx, 'Title')
            xlabel(app.phiAx, 'X')
            ylabel(app.phiAx, 'Y')
            app.rawPhiTab = uitab(app.PhiTabGroup);
            app.rawPhiTab.Title = 'Phi (Raw)';
            app.rawPhiAx = uiaxes(app.rawPhiTab);
            title(app.rawPhiAx, 'Title')
            xlabel(app.rawPhiAx, 'X')
            ylabel(app.rawPhiAx, 'Y')
            app.phiLogTab = uitab(app.PhiTabGroup);
            app.phiLogTab.Title = 'Phi (Log)';
            app.phiLogAx = uiaxes(app.phiLogTab);
            title(app.phiLogAx, 'Title')
            xlabel(app.phiLogAx, 'X')
            ylabel(app.phiLogAx, 'Y')
            app.acoustoOpticsPlotsTab = uitabgroup(app.AOIUIFigure);
            app.acoustoOpticsPlotsTab.SelectionChangedFcn = createCallbackFcn(app, @acoustoOpticsPlotsTabSelectionChanged, true);
            app.TimeTable = uitab(app.acoustoOpticsPlotsTab);
            app.TimeTable.Title = 'Time Table';
            app.UITable2 = uitable(app.TimeTable);
            app.UITable2.ColumnName = {'Stage'; 'Time[s]'};
            app.UITable2.RowName = {};
            app.UITable2_2 = uitable(app.TimeTable);
            app.UITable2_2.ColumnName = {'Stage'; 'Time[s]'};
            app.UITable2_2.RowName = {};
            app.UITable2_3 = uitable(app.TimeTable);
            app.UITable2_3.ColumnName = {'Stage'; 'Time[s]'};
            app.UITable2_3.RowName = {};
            app.AOVarsTab = uitab(app.acoustoOpticsPlotsTab);
            app.AOVarsTab.Title = 'AO Vars';
            app.TabGroup2 = uitabgroup(app.AOVarsTab);
            app.SystemClkTab = uitab(app.TabGroup2);
            app.SystemClkTab.Title = 'System Clk';
            app.UITable = uitable(app.SystemClkTab);
            app.UITable.ColumnName = {'Parameter'; 'Value'; 'Units'};
            app.UITable.RowName = {};
            app.USSignalTab = uitab(app.TabGroup2);
            app.USSignalTab.Title = 'US Signal';
            app.UITable_2 = uitable(app.USSignalTab);
            app.UITable_2.ColumnName = {'Parameter'; 'Value'; 'Units'};
            app.UITable_2.RowName = {};
            app.SamplesTab = uitab(app.TabGroup2);
            app.SamplesTab.Title = 'Samples';
            app.UITable_3 = uitable(app.SamplesTab);
            app.UITable_3.ColumnName = {'Parameter'; 'Value'; 'Units'};
            app.UITable_3.RowName = {};
            app.TimingTab = uitab(app.TabGroup2);
            app.TimingTab.Title = 'Timing';
            app.UITable_4 = uitable(app.TimingTab);
            app.UITable_4.ColumnName = {'Parameter'; 'Value'; 'Units'};
            app.UITable_4.RowName = {};
            app.DigitizerTab = uitab(app.TabGroup2);
            app.DigitizerTab.Title = 'Digitizer';
            app.UITable_5 = uitable(app.DigitizerTab);
            app.UITable_5.ColumnName = {'Parameter'; 'Value'; 'Units'};
            app.UITable_5.RowName = {};
            app.LengthTab = uitab(app.TabGroup2);
            app.LengthTab.Title = 'Length';
            app.UITable_6 = uitable(app.LengthTab);
            app.UITable_6.ColumnName = {'Parameter'; 'Value'; 'Units'};
            app.UITable_6.RowName = {};
            app.FreqTab = uitab(app.TabGroup2);
            app.FreqTab.Title = 'Freq';
            app.UITable_7 = uitable(app.FreqTab);
            app.UITable_7.ColumnName = {'Parameter'; 'Value'; 'Units'};
            app.UITable_7.RowName = {};
            app.extClkTab = uitab(app.acoustoOpticsPlotsTab);
            app.extClkTab.Title = 'Ext. Clk';
            app.extClkAx = uiaxes(app.extClkTab);
            title(app.extClkAx, 'Title')
            xlabel(app.extClkAx, 'X')
            ylabel(app.extClkAx, 'Y')
            app.usSignalTab = uitab(app.acoustoOpticsPlotsTab);
            app.usSignalTab.Title = 'US';
            app.usSignalAx = uiaxes(app.usSignalTab);
            title(app.usSignalAx, 'Title')
            xlabel(app.usSignalAx, 'X')
            ylabel(app.usSignalAx, 'Y')
            app.croppedTab = uitab(app.acoustoOpticsPlotsTab);
            app.croppedTab.Title = 'Cropped';
            app.croppedAx = uiaxes(app.croppedTab);
            title(app.croppedAx, 'Title')
            xlabel(app.croppedAx, 'X')
            ylabel(app.croppedAx, 'Y')
            app.measTab = uitab(app.acoustoOpticsPlotsTab);
            app.measTab.Title = 'Meas. Sig.';
            app.measAx = uiaxes(app.measTab);
            title(app.measAx, 'Title')
            xlabel(app.measAx, 'X')
            ylabel(app.measAx, 'Y')
            app.signalTab = uitab(app.acoustoOpticsPlotsTab);
            app.signalTab.Title = 'Net';
            app.signalAx = uiaxes(app.signalTab);
            title(app.signalAx, 'Title')
            xlabel(app.signalAx, 'X')
            ylabel(app.signalAx, 'Y')
            app.deMulTab = uitab(app.acoustoOpticsPlotsTab);
            app.deMulTab.Title = 'DeMul';
            app.deMulAx = uiaxes(app.deMulTab);
            title(app.deMulAx, 'Title')
            xlabel(app.deMulAx, 'X')
            ylabel(app.deMulAx, 'Y')
            app.reshapedTab = uitab(app.acoustoOpticsPlotsTab);
            app.reshapedTab.Title = 'Reshaped';
            app.reshapedAx = uiaxes(app.reshapedTab);
            title(app.reshapedAx, 'Title')
            xlabel(app.reshapedAx, 'X')
            ylabel(app.reshapedAx, 'Y')
            % Show the figure after all components are created
            app.AOIUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = AOGUI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.AOIUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.AOIUIFigure)
        end
    end
end